classdef PropagateROI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIpropagateROI       matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        PropagateROIButton   matlab.ui.control.Button
        Label                matlab.ui.control.Label
        FirstROISlider       matlab.ui.control.Slider
        LastROISlider        matlab.ui.control.Slider
        ROIPanel             matlab.ui.container.Panel
        Label_2              matlab.ui.control.Label
        ROISlider            matlab.ui.control.Slider
        AcceptallROIsButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        dcm_image_series;
        dcm_number_total;
        HounsFieldImageSeries;
        propagation;
        CallingApp;
        lower_level;
        upper_level;
        first_ax;
        last_ax;
        ROI_ax;
        ROI_propagation_mask;
        ROI_centroid                    % Structure (.centeroid) centeroid for all the ROIs
    end
    
    methods (Access = public)
        
        function propagation_ROI = propagate_ROI(app)
            
            %             fig = figure;
%             fig.WindowState = 'maximized';
%             set(fig,'CloseRequestFcn','')                % Prevent pop-up from closing
%             set(fig, 'MenuBar', 'none');
%             set(fig, 'ToolBar', 'none');
%             h1 = subplot(2,2,1);
            
%             previousButtom_first = uicontrol('Parent',fig,"Style","pushbutton","String","Previous Image","Units","normalized","Position",[0.15 0.5 0.1 0.05],"Visible","on", 'Callback', {@previousButtomBallBack,img});
%             nextButtom_first = uicontrol('Parent',fig,"Style","pushbutton","String","Next Image","Units","normalized","Position",[0.31 0.5 0.1 0.05],"Visible","on", 'Callback', {@nextButtomBallBack,img});
%             
%             previousButtom_last = uicontrol('Parent',fig,"Style","pushbutton","String","Previous Image","Units","normalized","Position",[0.65 0.5 0.1 0.05],"Visible","on");
%             nextButtom_last = uicontrol('Parent',fig,"Style","pushbutton","String","Next Image","Units","normalized","Position",[0.81 0.5 0.1 0.05],"Visible","on");
            
%             imshow(app.HounsFieldImageSeries(app.FirstROISlider.Value).images, 'DisplayRange', [app.lower_level app.upper_level],'Parent', app.first_ax);
%             fig.WindowState = 'maximized';
%             set(h1, 'Position', get(0, 'Screensize'));  % Fullscreen image
%             set(fig,'CloseRequestFcn','')                % Prevent pop-up from closing
%             set(fig, 'MenuBar', 'none');
%             set(fig, 'ToolBar', 'none');
            
            message = sprintf('Step 1: Draw a polygon specifiying ROI.\nClose the polygon by clicking the first point to finish.');
            text( 5, 30, message, 'color', 'r', 'FontSize', 12, "Parent",app.first_ax);
            
%             uiwait(msgbox(message));
%             hFH1 = imfreehand(h1);
            hFH1 = drawpolygon('Parent', app.first_ax);
%             position_1 = getPosition(hFH1);
            position_1 = hFH1.Position;
            centroid_1 = mean(position_1);


%             delete(fig);
            
            [theta_1 rho_1] = cart2pol(position_1(:,1) - centroid_1(1), position_1(:,2) - centroid_1(2));
            
%             theta_q = -pi:pi/4:pi;
            theta_q = min(theta_1):(max(theta_1) - min(theta_1))/8:max(theta_1);
            
            new_rho = interp1(theta_1,rho_1, theta_q);
            
            [x1 y1] = pol2cart(theta_q,new_rho);
            
            y_final_1 = y1 + centroid_1(2);
            x_final_1 = x1 + centroid_1(1);
            
            y_centroid_check_1 = mean(y_final_1);
            x_centroid_check_1 = mean(x_final_1);


%             h2 = subplot(2,2,2);
%             fig = figure;
%             h1 = subplot(1,2,1);
%             imshow(app.HounsFieldImageSeries(app.dcm_number_total).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.last_ax);
%             set(h2, 'Position', get(0, 'Screensize'));  % Fullscreen image
%             set(fig,'CloseRequestFcn','')                % Prevent pop-up from closing
%             set(fig, 'MenuBar', 'none');
%             set(fig, 'ToolBar', 'none');
            
            message = sprintf('Step 2: Draw a polygon specifiying ROI.\nClose the polygon by clicking the first point to finish.');
            text( 5, 30, message, 'color', 'r', 'FontSize', 12, 'Parent',app.last_ax);
            
%             uiwait(msgbox(message));
%             hFH2 = imfreehand(h2);
            hFH2 = drawpolygon("Parent",app.last_ax);
%             position_2 = getPosition(hFH2);
            position_2 = hFH2.Position;
            centroid_2 = mean(position_2);
            
%             delete(fig);


            [theta_2 rho_2] = cart2pol(position_2(:,1) - centroid_2(1), position_2(:,2) - centroid_2(2));
            
            theta_q = min(theta_2):(max(theta_2) - min(theta_2))/8:max(theta_2);
            
            new_rho = interp1(theta_2,rho_2, theta_q);
            
            [x2 y2] = pol2cart(theta_q,new_rho);
            
            y_final_2 = y2 + centroid_2(2);
            x_final_2 = x2 + centroid_2(1);
            
            y_centroid_check_2 = mean(y_final_2);
            x_centroid_check_2 = mean(x_final_2);


%             fig = figure;
%             h3 = subplot(2,2,[3,4]);
%             h3 = subplot(2,2,[3,4]);
            imshow(app.HounsFieldImageSeries(app.FirstROISlider.Value).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.ROI_ax);
%             set(fig, 'Position', get(0, 'Screensize'));  % Fullscreen image
%             set(fig,'CloseRequestFcn','')                % Prevent pop-up from closing
%             set(fig, 'MenuBar', 'none');
%             set(fig, 'ToolBar', 'none');
            dcm_idx = 1;
            for basic_dcm_idx = app.FirstROISlider.Value:app.LastROISlider.Value
                    
                ROI_coord(:,1) = (x_final_2 - x_final_1) .* ((dcm_idx - 1)/(app.dcm_number_total - 1)) + x_final_1;
                ROI_coord(:,2) = (y_final_2 - y_final_1) .* ((dcm_idx - 1)/(app.dcm_number_total - 1)) + y_final_1;
                imshow(app.HounsFieldImageSeries(basic_dcm_idx).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.ROI_ax);
                
%                 propagation_ROI(dcm_idx).ROI = impoly(app.ROI_ax, ROI_coord);
                polygon_tmp = drawpolygon("Parent",app.ROI_ax,"Position",ROI_coord);
                propagation_ROI(dcm_idx).ROI = ROI_coord;
                app.ROI_centroid(dcm_idx).centeroid = round(mean(ROI_coord));
                drawnow;
                dcm_idx = dcm_idx + 1;
            end
            
%             delete(fig);
%             delete(gcf);
            
            app.propagation = propagation_ROI;
            
            set(app.ROISlider, 'Limits', [app.FirstROISlider.Value app.LastROISlider.Value]);
            set(app.ROISlider, 'MinorTicks', [app.FirstROISlider.Value app.LastROISlider.Value]);


        end
        
        function clickCallback(app, src, evt, value)


            app.propagation(value).ROI = evt.CurrentPosition;

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, dcm_image_series, dcm_number_total, HounsFieldImageSeries, lower_level, upper_level)
            app.CallingApp = mainapp;
            app.dcm_image_series = dcm_image_series;
            app.dcm_number_total = dcm_number_total;
            app.HounsFieldImageSeries = HounsFieldImageSeries;
            app.lower_level = lower_level;
            app.upper_level = upper_level;
            
            app.first_ax = axes('Parent',app.Panel, 'Position',[0.05 0.25 0.35 0.7]);
            app.last_ax = axes('Parent',app.Panel, 'Position',[0.6 0.25 0.35 0.7]);
            
            app.ROI_ax = axes('Parent',app.ROIPanel, "Position", [0.15 0.15 0.7 0.7]);
            
            imshow(app.HounsFieldImageSeries(1).images, 'DisplayRange', [app.lower_level app.upper_level],'Parent', app.first_ax);
            imshow(app.HounsFieldImageSeries(dcm_number_total).images, 'DisplayRange', [app.lower_level app.upper_level],'Parent', app.last_ax);
            imshow(app.HounsFieldImageSeries(1).images, 'DisplayRange', [app.lower_level app.upper_level],'Parent', app.ROI_ax);
            
            set(app.FirstROISlider, 'Limits', [1 app.dcm_number_total]);
            set(app.FirstROISlider, 'MinorTicks', [1:1:app.dcm_number_total]);
            
            set(app.LastROISlider, 'Limits', [1 app.dcm_number_total]);
            set(app.LastROISlider, 'MinorTicks', [1:1:app.dcm_number_total]);
            
            app.LastROISlider.Value = app.dcm_number_total;
            
            
        end

        % Button pushed function: PropagateROIButton
        function PropagateROIButtonPushed(app, event)
            app.FirstROISlider.Enable = false;
            app.LastROISlider.Enable = false;
            app.propagation = propagate_ROI(app);
            app.PropagateROIButton.Enable = false;
        end

        % Value changed function: FirstROISlider
        function FirstROISliderValueChanged(app, event)
            value = app.FirstROISlider.Value;
            
            event.Source.Value = round( value );
            
            value = round( value );
            
            if value>= app.LastROISlider.Value
                value = app.LastROISlider.Value - 1;
            end
            
            event.Source.Value = round( value );
            
            imshow(app.HounsFieldImageSeries(value).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.first_ax)
            
            title(app.first_ax, strcat('DICOM Image (#', num2str(value) ,')') )
        end

        % Value changed function: LastROISlider
        function LastROISliderValueChanged(app, event)
            value = app.LastROISlider.Value;
            
            event.Source.Value = round( value );
            
            value = round( value );
            
            if value<= app.FirstROISlider.Value
                value = app.FirstROISlider.Value + 1;
            end
            
            event.Source.Value = round( value );
            
            imshow(app.HounsFieldImageSeries(value).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.last_ax)
            
            title(app.last_ax, strcat('DICOM Image (#', num2str(value) ,')') )
            
        end

        % Value changed function: ROISlider
        function ROISliderValueChanged(app, event)
            value = app.ROISlider.Value;
            
            event.Source.Value = round( value );
            
            value = round( value );
            
            event.Source.Value = round( value );
            
            imshow(app.HounsFieldImageSeries(value).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.ROI_ax)
            
            title(app.last_ax, strcat('DICOM Image (#', num2str(value) ,')') )
            
            polygon_position = app.propagation(value - app.ROISlider.Limits(1) + 1).ROI;
            
            new_ROI_position = drawpolygon('Parent',app.ROI_ax,'Position',polygon_position);
            drawnow;
            
            addlistener(new_ROI_position,'ROIMoved',@(src,evt)clickCallback(app, src, evt, value));

            
        end

        % Button pushed function: AcceptallROIsButton
        function AcceptallROIsButtonPushed(app, event)
        
            dcm_idx = 1;
            for basic_dcm_idx = app.FirstROISlider.Value:app.LastROISlider.Value
                
                imshow(app.HounsFieldImageSeries(basic_dcm_idx).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.ROI_ax)
                
                polygon_position = app.propagation(dcm_idx).ROI;
                ROI_polygonal = drawpolygon('Parent',app.ROI_ax,'Position',polygon_position);
                app.ROI_propagation_mask(dcm_idx).masks = ROI_polygonal.createMask();
                
                
                dcm_idx = dcm_idx + 1;
            end
            
            getROIpropagation_mask(app.CallingApp, app.FirstROISlider.Value, app.LastROISlider.Value, app.ROI_propagation_mask, app.ROI_centroid);
            
%             uiresume;
            delete(app)
        end

        % Close request function: UIpropagateROI
        function UIpropagateROICloseRequest(app, event)
            
            app.CallingApp.PropagateROIButton.Enable = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIpropagateROI = uifigure('Visible', 'off');
            app.UIpropagateROI.Name = 'UI Figure';
            app.UIpropagateROI.CloseRequestFcn = createCallbackFcn(app, @UIpropagateROICloseRequest, true);
            app.Panel = uipanel(app.UIpropagateROI);
            app.PropagateROIButton = uibutton(app.Panel, 'push');
            app.PropagateROIButton.ButtonPushedFcn = createCallbackFcn(app, @PropagateROIButtonPushed, true);
            app.PropagateROIButton.Text = 'Propagate ROI';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.FirstROISlider = uislider(app.Panel);
            app.FirstROISlider.ValueChangedFcn = createCallbackFcn(app, @FirstROISliderValueChanged, true);
            app.FirstROISlider.MinorTicks = [];
            app.FirstROISlider.Value = 1;
            app.LastROISlider = uislider(app.Panel);
            app.LastROISlider.ValueChangedFcn = createCallbackFcn(app, @LastROISliderValueChanged, true);
            app.LastROISlider.MinorTicks = [];
            app.LastROISlider.Value = 2;
            app.ROIPanel = uipanel(app.UIpropagateROI);
            app.Label_2 = uilabel(app.ROIPanel);
            app.Label_2.Text = '';
            app.ROISlider = uislider(app.ROIPanel);
            app.ROISlider.ValueChangedFcn = createCallbackFcn(app, @ROISliderValueChanged, true);
            app.ROISlider.MinorTicks = [];
            app.AcceptallROIsButton = uibutton(app.UIpropagateROI, 'push');
            app.AcceptallROIsButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptallROIsButtonPushed, true);
            app.AcceptallROIsButton.Text = 'Accept all ROIs';
            % Show the figure after all components are created
            app.UIpropagateROI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PropagateROI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIpropagateROI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIpropagateROI)
        end
    end
end