classdef centerline_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ImportDICOMMetadataButtonGroup  matlab.ui.container.ButtonGroup
        SelectdirectoryButton           matlab.ui.control.Button
        ChoosethedirectorytoDICOMmetadataLabel  matlab.ui.control.Label
        UIdicomCollectionTable          matlab.ui.control.Table
        LoadSelectedSeriesButton        matlab.ui.control.Button
        AllViewsPanel                   matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        ImageNavigatorPanel             matlab.ui.container.Panel
        AxialSliderLabel                matlab.ui.control.Label
        AxialSlider                     matlab.ui.control.Slider
        SagittalSliderLabel             matlab.ui.control.Label
        SagittalSlider                  matlab.ui.control.Slider
        CoronalSliderLabel              matlab.ui.control.Label
        CoronalSlider                   matlab.ui.control.Slider
        AxialLabel                      matlab.ui.control.Label
        SagittalLabel                   matlab.ui.control.Label
        CoronalLabel                    matlab.ui.control.Label
        WLWWPanel                       matlab.ui.container.Panel
        SetWLWWButton                   matlab.ui.control.Button
        WWEditFieldLabel                matlab.ui.control.Label
        WWEditField                     matlab.ui.control.NumericEditField
        WLEditFieldLabel                matlab.ui.control.Label
        WLEditField                     matlab.ui.control.NumericEditField
        ExtractLuminalCrosssectionImagesPanel  matlab.ui.container.Panel
        AddPointsButton                 matlab.ui.control.Button
        CreateButton                    matlab.ui.control.Button
        ViewButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        CreateaseriesforpointforcenterlinecalculationLabel  matlab.ui.control.Label
        CreatethecenterlineLabel        matlab.ui.control.Label
        Viewthecrosssectionalandcurved3DMPRviewsLabel  matlab.ui.control.Label
        SavecrosssectionalimagesindcmseriesLabel  matlab.ui.control.Label
        UITablePoints                   matlab.ui.control.Table
        SavePointsButton                matlab.ui.control.Button
        LoadSVpathButton                matlab.ui.control.Button
        QuantifyButton                  matlab.ui.control.Button
        LampLabel                       matlab.ui.control.Label
        ShowCLButton                    matlab.ui.control.StateButton
        TranslateCLCheckBox             matlab.ui.control.CheckBox
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.EditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.EditField
        ZEditFieldLabel                 matlab.ui.control.Label
        ZEditField                      matlab.ui.control.EditField
        BifurcationPointLabel           matlab.ui.control.Label
        ExportSlicesButton              matlab.ui.control.Button
        LevelsetButton                  matlab.ui.control.Button
        VVLevelsetButton                matlab.ui.control.Button
        VVFLevelsetButton               matlab.ui.control.Button
        LoadrawpathButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        
        QuantifyWindow                          % Name of quantification app window
        dcm_series_Filenames                    % Filename and directory for dcm series
        sorted_filenames                        % Filenames for sorted dicom series
        dcm_series_volume                       % Stores the dicomVolume 
        dcm_series_volume_HU                    % Stores the dicomVolume in Hounsfield Unit
        dcm_series_volume_HU_scaled             % Stores the scaled dicomVolume in Hounsfield Unit
        volume_dim                              % Number of slices in [x y z] directions
        dcm_series_info
        
        dcm_series_volume_HU_enhcd              % Enhanced calcium plaque images
        dcm_series_volume_HU_enhcd_scaled       % Scaled and enhanced calcium plaque images
        
        reduced_dataset                         % Dicom volume containing the region of interset
                                                % calculated based on
                                                % Levelset method
        
        delta_x                                 % Pixel spacing in x-direction
        delta_y                                 % Pixel spacing in y-direction
        delta_z                                 % Pixel spacing in z-direction
        
        %patientPosition                         % The x, y, and z coordinates of the upper left hand corner 
                                                % (center of the first voxel transmitted) of the image, in mm
                                                
        %patientOrientation                      % The direction cosines of the first row and the first column 
                                                % with respect to the patient
        
        X                                       % X component of totoal volume mesh grid
        Y                                       % Y component of totoal volume mesh grid
        Z                                       % Z component of totoal volume mesh grid
        
        UIAxialAxis                             % Handle to axial view
        UISagittalAxis                          % Handle to sagittal view
        UICoronalAxis                           % Handle to coronal view
        UI3DPlanesAxis                          % Handle to 3d view
        
        upper_level                             % Upper level of contrast for images based on WW and WL
        lower_level                             % Lower level of contrast for images based on WW and WL
        
        crosshair_enable                        % Flag for showing crosshair
        cursor_position                         % Position of cursor on the screen
        h_crosshair_exist                       % flag for horizontal crosshair
        line_h
        
        h_axial
        
        slices_limits                           % Stores the image limits for all three views
                                                % struct: xLim_axial_view, yLim_axial_view
                                                %         yLim_sagittal_view, zLim_sagittal_view
                                                %         xLim_coronal_view, zLim_coronal_view

        plane_location                          % Stores the location of each plane
                                                % struct: .axial, .sagittal, .coronal
                                                
        CL_points                               % Centerline points: struct: .point_pointID
        
        centerline                              % Centerline points: array
        centerline_interp                       % Uniformly-spaced centerline points: array
        bif_index                               % Bifurcation point index
        tangent_vector                          % Tangent vector to each centerline point: array
        tangents_updated                        % Tangent vector to each point on centerline_interp: array
        rotation_vectors_updated                % Rotation vector for oblique slices
        slice_2D                                % Stores the 2D slices. struct: (.idx_#centerline_point)
        slice_3D                                % Stores the 3D slices. struct: (.idx_#centerline_point)
        slice_2D_zoomed                         % Stores the zoomed 2D slices. struct: (.idx_#centerline_point)
        
        slice_2D_reduced                        % Stores the 2D slices from reduced dataset. struct: (.idx_#centerline_point)
        slice_2D_reduced_zoomed                 % Stores the zoomed 2D slices from reduced dataset. struct: (.idx_#centerline_point)
        slice_2D_reduced_enhsd                  % Enhanced 2D segment obtained by superposition of reduced dataset onto the original dataset
        
        points_pos                              % local position of points. struct: (.axial, .sagittal, .coronal).ID
        
        point_ID                                % CL point IDs
        
        points_data_table                       % Stores the point coords to show them in the table
        
        point_handle                            % Struct: .loc_%d_%d_%d_PID_%d_axis -> axial_plane, sagittal_plane, coronal_plane, app.point_ID, axial
        
        flag_imported_centerline                % Flag for imported centerline. "True" if centerline was imported; "False" if dicom slices were imported
        
    end
    
    
    methods (Access = public)
        
        % Sorting the DICOM collection by the InstanceNumber      
        function sorted_filenames = sort_metadata(app, dcm_filenames)
            
            infos = cellfun(@dicominfo, dcm_filenames);
            
            [~, inds] = sort([infos.InstanceNumber]);
            sorted_filenames = dcm_filenames(inds);
            
        end
        
        % Calculate the the upper level and lower level of visualized
        % images based on WW and WL
        function [lower_level, upper_level] = set_WW_WL(app, WW, WL)
            upper_level = WL + (WW/2);
            lower_level = WL - (WW/2);
        end


        function update_Planes_3D_view(app, axial_slice_position, sagittal_slice_position, coronal_slice_position)
        
%             Planes_3D = slice(app.UI3DPlanesAxis, app.dcm_series_volume_HU_scaled, app.volume_dim(1)/2, app.volume_dim(2)/2, app.volume_dim(3)/2);
            Planes_3D = slice(app.UI3DPlanesAxis, app.dcm_series_volume_HU_scaled, sagittal_slice_position, coronal_slice_position, axial_slice_position);
            set(Planes_3D, 'edgecolor', 'none')
            set(app.UI3DPlanesAxis,'Colormap', gray)
            set(app.UI3DPlanesAxis, 'CLim', [app.lower_level app.upper_level]);
            app.UI3DPlanesAxis.Color = 'none';
            axis(app.UI3DPlanesAxis, 'off');
            text( 1, 3, '3D', 'color', 'm', 'FontSize', 10, 'Parent', app.UI3DPlanesAxis);
        
        end
        
        function update_axial_view(app, axial_slice_position)
            
            [axial_X axial_Y axial_Z] = meshgrid( (1:0.5:app.volume_dim(1)), (1:0.5:app.volume_dim(2)), axial_slice_position );
            axial_view_data = interp3(app.X, app.Y, app.Z, app.dcm_series_volume_HU_scaled, axial_X, axial_Y, axial_Z);
            app.h_axial = imshow(double(squeeze(axial_view_data)) , 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UIAxialAxis);
            
            plane_clicked = 1;
            app.h_crosshair_exist = 0;
            
            app.h_axial.ButtonDownFcn = @(src,event)activate_crosshair(app,src,event, plane_clicked);
%             h_axial.PickableParts = "none";
            app.h_axial.Tag = 'AxialAxis';
            
            text( 1, 3, 'Axial', 'color', 'r', 'FontSize', 10, 'Parent', app.UIAxialAxis);
            
        end
        
        function update_sagittal_view(app, sagittal_slice_position)
                
            [sagittal_X sagittal_Y sagittal_Z] = meshgrid( sagittal_slice_position, (1:0.5:app.volume_dim(2)), (1:0.5:app.volume_dim(3)) );
            sagittal_view_data = interp3(app.X, app.Y, app.Z, app.dcm_series_volume_HU_scaled, sagittal_X, sagittal_Y, sagittal_Z);
            
%             x_lim = app.slices_limits.yLim_saggital_view
%             y_lim = app.slices_limits.zLim_saggital_view
            
            h_sagittal = imshow(imrotate(double(squeeze(sagittal_view_data)), 90),...
                'DisplayRange', [app.lower_level app.upper_level],...
                'Parent', app.UISagittalAxis);
            
%             h_sagittal = imshow(double(squeeze(sagittal_view_data)),...
%                 'DisplayRange', [app.lower_level app.upper_level],...
%                 'Parent', app.UISagittalAxis, 'XData', [x_lim(1) x_lim(2)],...
%                 'YData', [y_lim(1) y_lim(2)]);
            
            plane_clicked = 2;
            h_sagittal.ButtonDownFcn = @(src,event)activate_crosshair(app,src,event, plane_clicked);
            h_sagittal.PickableParts = "all";


            text( 1, 3, 'Sagittal', 'color', 'g', 'FontSize', 10, 'Parent', app.UISagittalAxis);
            
        end
        
        function update_coronal_view(app, coronal_slice_position)
            
            [coronal_X coronal_Y coronal_Z] = meshgrid( (1:0.5:app.volume_dim(1)), coronal_slice_position, (1:0.5:app.volume_dim(3)));
            coronal_view_data = interp3(app.X, app.Y, app.Z, app.dcm_series_volume_HU_scaled, coronal_X, coronal_Y, coronal_Z);
            h_coronal = imshow(imrotate(double(squeeze(coronal_view_data)), 90), 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UICoronalAxis);
            
            plane_clicked = 3;
            h_coronal.ButtonDownFcn = @(src,event)activate_crosshair(app,src,event,plane_clicked);
            h_coronal.PickableParts = "all";
            
            
            text( 1, 3, 'Coronal', 'color', 'b', 'FontSize', 10, 'Parent', app.UICoronalAxis);
            
        end
        
        function activate_crosshair(app,src, event, plane_clicked)
            
%             cursor_position = get (app.UIAxialAxis, 'CurrentPoint');
            
%             if ~isempty(event.Source.CurrentObject) && isequal(event.Source.CurrentObject.Tag,'AxialAxis')

                if app.crosshair_enable == true
                    
                    % If the click was on the axial image
                    if plane_clicked == 1
                        
                        if app.h_crosshair_exist == 1
                            delete(app.line_h)
                            drawnow;
                        end
                    
                        app.cursor_position = app.UIAxialAxis.CurrentPoint;
                        x_pos = app.cursor_position(1,1)
                        y_pos = app.cursor_position(1,2)
                        
                        % Axial image size limits
                        x_lim_axial = app.slices_limits.xLim_axial_view
                        y_lim_axial = app.slices_limits.yLim_axial_view
                        
                        % Sagittal image size limits
                        x_lim_sagittal = app.slices_limits.yLim_sagittal_view
                        y_lim_sagittal = app.slices_limits.zLim_sagittal_view
                        
                        % Coronal image size limits
                        x_lim_coronal = app.slices_limits.xLim_coronal_view
                        y_lim_coronal = app.slices_limits.zLim_coronal_view
                        
                        
                        update_sagittal_view(app, x_pos/2);
                        update_coronal_view(app, y_pos/2);
                        update_Planes_3D_view(app, app.plane_location.axial, x_pos/2, y_pos/2);
                        
                        
                        update_sliders_value(app, app.plane_location.axial, x_pos/2, y_pos/2);
                        
                        % Crosshair on Axial View
                        app.line_h(1) = drawline('Parent', app.UIAxialAxis, 'Position',...
                            [x_lim_axial(1) y_pos; x_lim_axial(2) y_pos], "Color", 'b', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
                        app.line_h(2) = drawline('Parent', app.UIAxialAxis, 'Position',...
                            [x_pos y_lim_axial(1); x_pos y_lim_axial(2)], "Color", 'g', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
                        % Crosshair on Sagittal View
                        app.line_h(3) = drawline('Parent', app.UISagittalAxis, 'Position',...
                            [x_lim_sagittal(1) (y_lim_sagittal(2) - app.plane_location.axial*2); x_lim_sagittal(2) (y_lim_sagittal(2) - app.plane_location.axial*2)],...
                            "Color", 'r', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
                        app.line_h(4) = drawline('Parent', app.UISagittalAxis, 'Position',...
                            [y_pos y_lim_sagittal(1); y_pos y_lim_sagittal(2)],...
                            "Color", 'b', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
                        % Crosshair on Coronal View
                        app.line_h(5) = drawline('Parent', app.UICoronalAxis, 'Position',...
                            [x_lim_coronal(1) (y_lim_sagittal(2) - app.plane_location.axial*2); x_lim_coronal(2) (y_lim_sagittal(2) - app.plane_location.axial*2)],...
                            "Color", 'r', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
                        app.line_h(6) = drawline('Parent', app.UICoronalAxis, 'Position',...
                            [x_pos y_lim_coronal(1); x_pos y_lim_coronal(2)],...
                            "Color", 'g', "InteractionsAllowed", "none", 'LineWidth', 1 );
                            
                        app.h_crosshair_exist = 1;
                        
                        
                    end
                    
                    
                    % If the click was on the sagittal image
                    if plane_clicked == 2
                        
                        if app.h_crosshair_exist == 1
                            delete(app.line_h)
                            drawnow;
                        end
                    
                        app.cursor_position = app.UISagittalAxis.CurrentPoint;
                        x_pos = app.cursor_position(1,1)
                        y_pos = app.cursor_position(1,2)
                        
                        % Axial image size limits
                        x_lim_axial = app.slices_limits.xLim_axial_view
                        y_lim_axial = app.slices_limits.yLim_axial_view
                        
                        % Sagittal image size limits
                        x_lim_sagittal = app.slices_limits.yLim_sagittal_view
                        y_lim_sagittal = app.slices_limits.zLim_sagittal_view
                        
                        % Coronal image size limits
                        x_lim_coronal = app.slices_limits.xLim_coronal_view
                        y_lim_coronal = app.slices_limits.zLim_coronal_view
                        
                        
                        update_axial_view(app, (y_lim_sagittal(2) - y_pos)/2);
                        update_coronal_view(app, x_pos/2);
                        update_Planes_3D_view(app, (y_lim_sagittal(2) - y_pos)/2, app.plane_location.sagittal ,x_pos/2);
                        
                        
                        update_sliders_value(app, (y_lim_sagittal(2) - y_pos)/2, app.plane_location.sagittal, x_pos/2);
                        
                        % Crosshair on Sagittal View
                        app.line_h(1) = drawline('Parent', app.UISagittalAxis, 'Position',...
                            [x_lim_sagittal(1) y_pos; x_lim_sagittal(2) y_pos], "Color", 'r', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
                        app.line_h(2) = drawline('Parent', app.UISagittalAxis, 'Position',...
                            [x_pos y_lim_sagittal(1); x_pos y_lim_sagittal(2)], "Color", 'b', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
                        % Crosshair on axial View
                        app.line_h(3) = drawline('Parent', app.UIAxialAxis, 'Position',...
                            [x_lim_axial(1) x_pos; x_lim_axial(2) x_pos],...
                            "Color", 'b', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
                        app.line_h(4) = drawline('Parent', app.UIAxialAxis, 'Position',...
                            [(app.plane_location.sagittal*2) y_lim_axial(1); (app.plane_location.sagittal*2) y_lim_axial(2)],...
                            "Color", 'g', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
                        % Crosshair on Coronal View
                        app.line_h(5) = drawline('Parent', app.UICoronalAxis, 'Position',...
                            [x_lim_coronal(1) y_pos; x_lim_coronal(2) y_pos],...
                            "Color", 'r', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
                        app.line_h(6) = drawline('Parent', app.UICoronalAxis, 'Position',...
                            [(app.plane_location.sagittal*2) y_lim_coronal(1); (app.plane_location.sagittal*2) y_lim_coronal(2)],...
                            "Color", 'g', "InteractionsAllowed", "none", 'LineWidth', 1 );
                            
                        app.h_crosshair_exist = 1;
                        
                        
                    end
                    
                    
                    % If the click was on the coronal image
                    if plane_clicked == 3
                        
                        if app.h_crosshair_exist == 1
                            delete(app.line_h)
                            drawnow;
                        end
                    
                        app.cursor_position = app.UICoronalAxis.CurrentPoint;
                        x_pos = app.cursor_position(1,1)
                        y_pos = app.cursor_position(1,2)
                        
                        % Axial image size limits
                        x_lim_axial = app.slices_limits.xLim_axial_view
                        y_lim_axial = app.slices_limits.yLim_axial_view
                        
                        % Sagittal image size limits
                        x_lim_sagittal = app.slices_limits.yLim_sagittal_view
                        y_lim_sagittal = app.slices_limits.zLim_sagittal_view
                        
                        % Coronal image size limits
                        x_lim_coronal = app.slices_limits.xLim_coronal_view
                        y_lim_coronal = app.slices_limits.zLim_coronal_view
                        
                        
                        update_axial_view(app, (y_lim_sagittal(2) - y_pos)/2);
                        update_sagittal_view(app, x_pos/2);
                        update_Planes_3D_view(app, (y_lim_sagittal(2) - y_pos)/2, x_pos/2, app.plane_location.coronal);
                        
                        
                        update_sliders_value(app, (y_lim_sagittal(2) - y_pos)/2, x_pos/2, app.plane_location.coronal);
                        
                        % Crosshair on Coronal View
                        app.line_h(1) = drawline('Parent', app.UICoronalAxis, 'Position',...
                            [x_lim_coronal(1) y_pos; x_lim_coronal(2) y_pos], "Color", 'r', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
                        app.line_h(2) = drawline('Parent', app.UICoronalAxis, 'Position',...
                            [x_pos y_lim_coronal(1); x_pos y_lim_coronal(2)], "Color", 'g', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
                        % Crosshair on Axial View
                        app.line_h(3) = drawline('Parent', app.UIAxialAxis, 'Position',...
                            [x_lim_axial(1) (app.plane_location.coronal)*2; x_lim_axial(2) (app.plane_location.coronal)*2],...
                            "Color", 'b', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
                        app.line_h(4) = drawline('Parent', app.UIAxialAxis, 'Position',...
                            [x_pos y_lim_axial(1); x_pos y_lim_axial(2)],...
                            "Color", 'g', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
                        % Crosshair on Sagittal View
                        app.line_h(5) = drawline('Parent', app.UISagittalAxis, 'Position',...
                            [x_lim_sagittal(1) y_pos; x_lim_sagittal(2) y_pos],...
                            "Color", 'r', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
%                         app.line_h(6) = drawline('Parent', app.UISagittalAxis, 'Position',...
%                             [(x_lim_sagittal(2) - app.plane_location.coronal) y_lim_coronal(1); (x_lim_coronal(2) - app.plane_location.coronal) y_lim_sagittal(2)],...
%                             "Color", 'b', "InteractionsAllowed", "none", 'LineWidth', 1 );
                        
                        app.line_h(6) = drawline('Parent', app.UISagittalAxis, 'Position',...
                            [(app.plane_location.coronal)*2 y_lim_coronal(1); (app.plane_location.coronal)*2 y_lim_sagittal(2)],...
                            "Color", 'b', "InteractionsAllowed", "none", 'LineWidth', 1 );
                            
                        app.h_crosshair_exist = 1;
                        
                        
                    end


                end


%             drawline('Parent',app.UIAxialAxis, "Position", [min(sagittal_X,[],'all') cursor_position(1,2);max(sagittal_X,[], 'all') cursor_position(1,2)])
            
        end
        
        
        function update_image_limits(app)
            
            app.slices_limits.xLim_axial_view = app.UIAxialAxis.XLim;
            app.slices_limits.yLim_axial_view = app.UIAxialAxis.YLim;
            
            app.slices_limits.yLim_sagittal_view = app.UISagittalAxis.XLim;
            app.slices_limits.zLim_sagittal_view = app.UISagittalAxis.YLim;
            
            app.slices_limits.xLim_coronal_view = app.UICoronalAxis.XLim;
            app.slices_limits.zLim_coronal_view = app.UICoronalAxis.YLim;
        
        
        end
        
        function update_plane_location(app)
        
            app.plane_location.axial = app.AxialSlider.Value;
            app.plane_location.sagittal = app.SagittalSlider.Value;
            app.plane_location.coronal = app.CoronalSlider.Value;
            
        end
        
        function update_sliders_value(app, axial_location, sagittal_location, coronal_location)
            
            app.AxialSlider.Value = axial_location;
            app.SagittalSlider.Value = sagittal_location;
            app.CoronalSlider.Value = coronal_location;
            
            app.AxialLabel.Text = num2str(axial_location,'% 10.1f');
            app.SagittalLabel.Text = num2str(sagittal_location,'% 10.1f');
            app.CoronalLabel.Text = num2str(coronal_location,'% 10.1f');
                        
            update_plane_location(app)
            
            
        end
        
        function add_point(app, axial_plane, sagittal_plane, coronal_plane)
            
%             app.point_ID = 0;
            app.point_ID = app.point_ID + 1;
            
            app.point_handle.(sprintf('PID_%d_Axial', app.point_ID)) ...
                            = drawpoint('Parent', app.UIAxialAxis, 'Position',...
                            [sagittal_plane*2 coronal_plane*2], "Color", 'r',...
                            "InteractionsAllowed", "all", 'LineWidth', 2, 'Tag', num2str(app.point_ID)  );
                        
            app.points_pos.axial.(sprintf('point_%d', app.point_ID)) = [sagittal_plane*2 coronal_plane*2];
            
            y_lim_sagittal = app.slices_limits.zLim_sagittal_view;
            
            app.point_handle.(sprintf('PID_%d_Sagittal', app.point_ID)) ...
                            = drawpoint('Parent', app.UISagittalAxis, 'Position',...
                            [coronal_plane*2 (y_lim_sagittal(2) - axial_plane*2)], "Color", 'r',...
                            "InteractionsAllowed", "all", 'LineWidth', 2, 'Tag', num2str(app.point_ID) );
                        
            app.points_pos.sagittal.(sprintf('point_%d', app.point_ID)) = [coronal_plane*2 (y_lim_sagittal(2) - axial_plane*2)];
            
            y_lim_coronal = app.slices_limits.zLim_coronal_view;
            
            app.point_handle.(sprintf('PID_%d_Coronal', app.point_ID)) ...
                            = drawpoint('Parent', app.UICoronalAxis, 'Position',...
                            [sagittal_plane*2 (y_lim_coronal(2) - axial_plane*2)], "Color", 'r',...
                            "InteractionsAllowed", "all", 'LineWidth', 2, 'Tag', num2str(app.point_ID) );
            
            my_point = app.point_handle.(sprintf('PID_%d_Coronal', app.point_ID));
            app.CL_points.(sprintf('point_%d', app.point_ID)) = [sagittal_plane coronal_plane axial_plane];
            
            app.points_pos.coronal.(sprintf('point_%d', app.point_ID)) = [sagittal_plane*2 (y_lim_coronal(2) - axial_plane*2)];
            
            handle_array = [app.point_handle.(sprintf('PID_%d_Axial', app.point_ID)),...
                app.point_handle.(sprintf('PID_%d_Sagittal', app.point_ID)),...
                app.point_handle.(sprintf('PID_%d_Coronal', app.point_ID))];
            
            addlistener(handle_array, ...
                 'ROIMoved',@(src,evt)clickCallback(app, src, evt, app.point_ID));
            
%             addlistener(app.point_handle.(sprintf('PID_%d_Axial', app.point_ID)), ...
%                 'ROIMoved',@(src,evt)clickCallback(app, src, evt, app.point_ID));
%             
%             addlistener(app.point_handle.(sprintf('PID_%d_Sagittal', app.point_ID)), ...
%                 'ROIMoved',@(src,evt)clickCallback(app, src, evt, app.point_ID));
%             
%             addlistener(app.point_handle.(sprintf('PID_%d_Coronal', app.point_ID)), ...
%                 'ROIMoved',@(src,evt)clickCallback(app, src, evt, app.point_ID));


            % Add point in the table
            app.UITablePoints.Data = [app.UITablePoints.Data;sagittal_plane coronal_plane axial_plane];
            app.UITablePoints.RowName = 'numbered';

                            
        end
        
        function clickCallback(app, src, evt, point_ID)
            
            new_position = evt.CurrentPosition;
%             sag_point = app.points_pos.sagittal.(strcat('point_', point_tag))
            
%             axial_point_selected = get(app.point_handle.(sprintf('PID_%d_Axial', app.point_ID)), 'Selected' )
%             sagittal_point_selected = get(app.point_handle.(sprintf('PID_%d_Sagittal', app.point_ID)), 'Selected' )
%             coronal_point_selected = get(app.point_handle.(sprintf('PID_%d_Coronal', app.point_ID)), 'Selected' )

              
            axial_point_selected = get(app.point_handle.(sprintf('PID_%d_Axial', app.point_ID)), 'Selected' );
            sagittal_point_selected = get(app.point_handle.(sprintf('PID_%d_Sagittal', app.point_ID)), 'Selected' );
            coronal_point_selected = get(app.point_handle.(sprintf('PID_%d_Coronal', app.point_ID)), 'Selected' );

            
%             if new_position ~= app.points_pos.axial.(strcat('point_', point_tag))
            if axial_point_selected == true


                point_tag = get(app.point_handle.(sprintf('PID_%d_Axial', point_ID)), 'Tag');
                
                app.points_pos.axial.(strcat('point_', point_tag)) = new_position;
                point_sagittal = app.points_pos.sagittal.(strcat('point_', point_tag));
                point_coronal = app.points_pos.coronal.(strcat('point_', point_tag));
                
                y_lim_coronal = app.slices_limits.zLim_coronal_view;
                
                app.points_pos.sagittal.(strcat('point_', point_tag)) = [new_position(2) point_sagittal(2)];
                app.points_pos.coronal.(strcat('point_', point_tag)) = [new_position(1) point_coronal(2)];
                
                update_sagittal_view(app, new_position(1)/2);
                update_coronal_view(app, new_position(2)/2);
                
                delete(app.point_handle.(sprintf('PID_%d_Sagittal', point_ID)));
                delete(app.point_handle.(sprintf('PID_%d_Coronal', point_ID)));
                
                app.point_handle.(sprintf('PID_%d_Sagittal', point_ID))...
                    = drawpoint('Parent', app.UISagittalAxis, 'Position',...
                            [new_position(2) point_sagittal(2)], "Color", 'r',...
                            "InteractionsAllowed", "all", 'LineWidth', 2, 'Tag', num2str(app.point_ID) );


                app.point_handle.(sprintf('PID_%d_Coronal', point_ID))...
                    = drawpoint('Parent', app.UICoronalAxis, 'Position',...
                            [new_position(1) (point_coronal(2))], "Color", 'r',...
                            "InteractionsAllowed", "all", 'LineWidth', 2, 'Tag', num2str(app.point_ID) );
                            
                
                update_sliders_value(app, app.plane_location.axial, new_position(1)/2, new_position(2)/2);
                            
                
                app.CL_points.(sprintf('point_%d', app.point_ID)) = [app.plane_location.sagittal app.plane_location.coronal app.plane_location.axial];
                

%             elseif new_position ~= app.points_pos.sagittal.(strcat('point_', point_tag))
            elseif sagittal_point_selected == true
                
                
                point_tag = get(app.point_handle.(sprintf('PID_%d_Sagittal', point_ID)), 'Tag');
                
                app.points_pos.sagittal.(strcat('point_', point_tag)) = new_position;
                
                point_axial = app.points_pos.axial.(strcat('point_', point_tag));
                point_coronal = app.points_pos.coronal.(strcat('point_', point_tag));
                
                y_lim_coronal = app.slices_limits.zLim_coronal_view;
                y_lim_sagittal = app.slices_limits.zLim_sagittal_view;
                
                app.points_pos.axial.(strcat('point_', point_tag)) = [point_axial(1) new_position(1)];
                app.points_pos.coronal.(strcat('point_', point_tag)) = [point_coronal(1) new_position(2)];
                
                update_axial_view(app, (y_lim_sagittal(2) - new_position(2))/2);
                update_coronal_view(app, new_position(1)/2);
                
                delete(app.point_handle.(sprintf('PID_%d_Axial', point_ID)));
                delete(app.point_handle.(sprintf('PID_%d_Coronal', point_ID)));
                
                app.point_handle.(sprintf('PID_%d_Axial', point_ID))...
                    = drawpoint('Parent', app.UIAxialAxis, 'Position',...
                            [point_axial(1) new_position(1)], "Color", 'r',...
                            "InteractionsAllowed", "all", 'LineWidth', 2, 'Tag', num2str(app.point_ID) );
                
                
                app.point_handle.(sprintf('PID_%d_Coronal', point_ID))...
                    = drawpoint('Parent', app.UICoronalAxis, 'Position',...
                            [point_coronal(1) (new_position(2))], "Color", 'r',...
                            "InteractionsAllowed", "all", 'LineWidth', 2, 'Tag', num2str(app.point_ID) );
                            
                
                update_sliders_value(app, (y_lim_sagittal(2) - new_position(2))/2, app.plane_location.sagittal, new_position(1)/2);
                            
                
                app.CL_points.(sprintf('point_%d', app.point_ID)) = ...
                    [app.plane_location.sagittal app.plane_location.coronal app.plane_location.axial];
                

%             elseif new_position ~= app.points_pos.coronal.(strcat('point_', point_tag))
%                 app.points_pos.coronal.(strcat('point_', point_tag)) = new_position;

            elseif coronal_point_selected == true
                
                
                point_tag = get(app.point_handle.(sprintf('PID_%d_Coronal', point_ID)), 'Tag');
                
                app.points_pos.coronal.(strcat('point_', point_tag)) = new_position;
                
                point_axial = app.points_pos.axial.(strcat('point_', point_tag));
                point_sagittal = app.points_pos.sagittal.(strcat('point_', point_tag));
                
                y_lim_coronal = app.slices_limits.zLim_coronal_view;
                y_lim_sagittal = app.slices_limits.zLim_sagittal_view;
                
                app.points_pos.axial.(strcat('point_', point_tag)) = [new_position(1) point_axial(2)];
                app.points_pos.sagittal.(strcat('point_', point_tag)) = [point_sagittal(1) new_position(2)];
                
                update_axial_view(app, (y_lim_sagittal(2) - new_position(2))/2);
                update_sagittal_view(app, new_position(1)/2);
                
                delete(app.point_handle.(sprintf('PID_%d_Axial', point_ID)));
                delete(app.point_handle.(sprintf('PID_%d_Sagittal', point_ID)));
                
                app.point_handle.(sprintf('PID_%d_Axial', point_ID))...
                    = drawpoint('Parent', app.UIAxialAxis, 'Position',...
                            [new_position(1) point_axial(2)], "Color", 'r',...
                            "InteractionsAllowed", "all", 'LineWidth', 2, 'Tag', num2str(app.point_ID) );
                
                
                app.point_handle.(sprintf('PID_%d_Sagittal', point_ID))...
                    = drawpoint('Parent', app.UISagittalAxis, 'Position',...
                            [point_sagittal(1) new_position(2)], "Color", 'r',...
                            "InteractionsAllowed", "all", 'LineWidth', 2, 'Tag', num2str(app.point_ID) );
                            
                
                update_sliders_value(app, new_position(2)/2, new_position(1)/2, app.plane_location.coronal);
                            
                
                app.CL_points.(sprintf('point_%d', app.point_ID)) = ...
                    [app.plane_location.sagittal app.plane_location.coronal app.plane_location.axial];

            end
            
            
%             app.CL_points.(sprintf('point_', app.point_ID)) = evt.CurrentPosition;
            
            axial_point_selected = false;
            sagittal_point_selected = false;
            coronal_point_selected = false;
        
            set(app.point_handle.(sprintf('PID_%d_Axial', app.point_ID)), 'Selected', false );
            set(app.point_handle.(sprintf('PID_%d_Sagittal', app.point_ID)), 'Selected', false );
            set(app.point_handle.(sprintf('PID_%d_Coronal', app.point_ID)), 'Selected', false );

            handle_array = [app.point_handle.(sprintf('PID_%d_Axial', app.point_ID)),...
                app.point_handle.(sprintf('PID_%d_Sagittal', app.point_ID)),...
                app.point_handle.(sprintf('PID_%d_Coronal', app.point_ID))];
            
            addlistener(handle_array, ...
                 'ROIMoved',@(src,evt)clickCallback(app, src, evt, app.point_ID));

        end
        
        function create_centerline(app, CL_points)
        
            CS = cat(1,0,cumsum(sqrt(sum(diff(CL_points,[],1).^2,2))));
            
            dd = interp1(CS, CL_points, unique([CS(:)' linspace(0,CS(end),20)]),'pchip');
            
            hold(app.UI3DPlanesAxis, 'on')
            plot3(app.UI3DPlanesAxis, dd(:,1),dd(:,2),dd(:,3),'.r-')
%             set(app.UI3DPlanesAxis, 'Projection','perspective')
            resetplotview(app.UI3DPlanesAxis,'InitializeCurrentView');
            hold(app.UI3DPlanesAxis, 'off')


        end


        function [slice, sliceInd, subX, subY, subZ] = extractSlice(app, volume,centerX,centerY,centerZ,normX,normY,normZ,radius)

        %%extractSlice extracts an arbitray slice from a volume.
        % [slice, sliceInd, subX, subY, subZ] = extractSlice extracts an arbitrary 
        % slice given the center and normal of the slice.  The function outputs the 
        % intensities, indices and the subscripts of the slice base on the input volume.
        % If you are familiar with IDL, this is the equivalent function to EXTRACT_SLICE.
        %
        % Note that output array 'sliceInd' contains integers and NaNs if the particular
        % locations are outside the volume, while output arrays 'subX, subY, subZ' 
        % contain floating points and does not contain NaNs when locations are
        % outside the volume.
        %
        % Example:
        % %Extracts slices from a mri volume by varying the slice orientation from
        % sagittal to transverse while shifting the center of the slice from left to right.
        % %(pardon me for demonstrating with an image without orientation labels and out-of-scale pixels.)
        %
        % load mri;
        % D = squeeze(D);
        % for i = 0:30;
        % pt = [64 i*2 14];
        % vec = [0 30-i i];
        % [slice, sliceInd,subX,subY,subZ] = extractSlice(D,pt(1),pt(2),pt(3),vec(1),vec(2),vec(3),64);
        % surf(subX,subY,subZ,slice,'FaceColor','texturemap','EdgeColor','none');
        % axis([1 130 1 130 1 40]);
        % drawnow;end;
        %
        %
        % $Revision: 1.0 $ $Date: 2011/07/02 18:00$ $Author: Pangyu Teng $
        % $Updated $ $Date: 2011/08/012 07:00$ $Author: Pangyu Teng $
        
        
            if nargin < 7
               display('requires at least 7 parameters');
               return;
            end
            
            if nargin < 8,
                % sets the size for output slice radius*2+1.
                radius = 30;
            end
            
            isDebug = 0;
            if isDebug,   
                clear all;close all;
                isDebug = 1;
                load mri; D = squeeze(D);
                volume = D;
                pt = [63 63 24];
                vec = [0 0 1];
                radius = 30;
            end
            
            pt = [centerX,centerY,centerZ];
            vec = [normX,normY,normZ];
            
            %initialize needed parameters
            %size of volume.
            volSz=size(volume); 
            %a very small value.
            epsilon = 1e-12; 
            
            %assume the slice is initially at [0,0,0] with a vector [0,0,1] and a silceSize.
            hsp = surf(linspace(-radius,radius,10*radius+1),linspace(-radius,radius,10*radius+1),zeros(10*radius+1));
            hspInitialVector = [0,0,1];
            
            %normalize vectors;
            hspVec = hspInitialVector/norm(hspInitialVector);
            hspVec(hspVec ==0) = epsilon;
            vec = vec/norm(vec);
            vec(vec == 0)=epsilon;
            
            %this does not rotate the surface, but initializes the subscript z in hsp.
            rotate(hsp,[0,0,1],0);
            
            if isDebug,
                %get the coordinates
                xdO = get(hsp,'XData');
                ydO = get(hsp,'YData');
                zdO = get(hsp,'ZData');
            end
            
            %find how much rotation is needed, below are the references.
            %http://www.siggraph.org/education/materials/HyperGraph/modeling/mod_tran/3drota.htm
            %http://www.mathworks.com/help/toolbox/sl3d/vrrotvec.html
            hspVecXvec = cross(hspVec, vec)/norm(cross(hspVec, vec));
            acosineVal = acos(dot(hspVec, vec));
            
            %help prevents errors (i.e. if vec is same as hspVec),
            hspVecXvec(isnan(hspVecXvec)) = epsilon;
            acosineVal(isnan(acosineVal)) = epsilon;
            
            %rotate to the requested orientation
            rotate(hsp,hspVecXvec(:)',180*acosineVal/pi);
            
            %get the coordinates
            xd = get(hsp,'XData');
            yd = get(hsp,'YData');
            zd = get(hsp,'ZData');
            
            %translate;
            subX = xd + pt(1);
            subY = yd + pt(2);
            subZ = zd + pt(3);
            
            %round the subscripts to obtain its corresponding values and indices in the volume.
            xd = round(subX);
            yd = round(subY);
            zd = round(subZ);
            
            %delete the surf
            delete(hsp);
            
            %obtain the requested slice intensitis and indices from the input volume.
            xdSz=size(xd);
            sliceInd=ones(xdSz)*NaN;
            slice=ones(xdSz)*NaN;
            for i = 1:xdSz(1)
                for j = 1:xdSz(2)
                    if xd(i,j) > 0 && xd(i,j)<= volSz(1) &&...
                         yd(i,j) > 0 && yd(i,j)<= volSz(2) &&...
                         zd(i,j) > 0 && zd(i,j)<= volSz(3),
                     sliceInd(i,j) = sub2ind(volSz,xd(i,j),yd(i,j),zd(i,j));
                     slice(i,j) = volume(xd(i,j),yd(i,j),zd(i,j));
                    end
                end    
            end
            
            if isDebug,
                plot3(xdO,ydO,zdO,'b+'); hold on;
                plot3(xd,yd,zd,'m.');
                figure(2);
                imagesc(slice);axis tight;axis equal;
            end
        
        end
        
        function oblique_slice(app, dcm_series_volume_HU_scaled, normal_vec, center_point)
        
%             [X_0 Y_0] = meshgrid( (1:app.volume_dim(1)), (1:app.volume_dim(2)) )

            [X_0 Y_0] = meshgrid( (center_point(1)-5:0.01:center_point(1)+5), (center_point(2)-5:0.01:center_point(2)+5) );
        
            Z_oblique = (-1/normal_vec(3))*( normal_vec(1)*(X_0 - center_point(1)) + normal_vec(2)*(Y_0 - center_point(2)) ) + center_point(3);
            
            a2 = interp3(app.X, app.Y, app.Z, dcm_series_volume_HU_scaled, X_0, Y_0, Z_oblique);
            
%             X = app.X
%             Y = app.Y
%             Z = app.Z

            if normal_vec(3) == 0
                a1 = slice(app.X, app.Y, app.Z, dcm_series_volume_HU_scaled, X_0, Y_0, 0.*X_0 + center_point(3));
                set(a1, 'edgecolor', 'none')
            else
                a1 = slice(app.X, app.Y, app.Z, dcm_series_volume_HU_scaled, X_0, Y_0, Z_oblique);
                set(a1, 'edgecolor', 'none')
            end
            
            colormap(gray)
            fig = figure;
            my_img = imshow(a2 , [app.lower_level app.upper_level]);
%             
%             new_x = get(my_img, 'XData');
%             new_y = get(my_img, 'YData');
%             C = get(my_img, 'CData');
%             
%             figure;
%             image('XData',2.*new_x,'YData',new_y,'CData',C);
%             colormap(gray)
%             
%             T = maketform('affine',[1 0 0; 1 0.9 0; 0 0 1]);
%             I2 = imtransform(a2,T);
            
%             figure;
%             diffusedImage = imdiffusefilt(a2,'NumberOfIterations',3);
%             imshow(diffusedImage , [app.lower_level app.upper_level]);
            
        end
        
        % Read Centerline File From Simvascular Output
        function [centerline, tangent_vector, rotation_vector] = read_SV_centerline(app, filename)
            % Read the .path file
            file_text = fileread(filename);
            % Extract the lines containing the position of CL points
            CL_points_line_read = regexpi(file_text, 'pos x="([+-]?\d+\.?\d+)" y="([+-]?\d+\.?\d+)" z="([+-]?\d+\.?\d+)', 'match');
            % Extract the numerics out of each line
            CL_points_numerics = regexp(CL_points_line_read,'[+-]?\d+\.?\d*','match');
            % Convert the cellArray into matrix
            out = cellfun(@(x) cellfun(@str2num, x, 'uni', 0), CL_points_numerics, 'uni', 0)
            centerline = cell2mat(cellfun(@cell2mat, out, 'uni', 0));
            centerline = reshape(centerline,3,size(centerline,2)/3);
            centerline = centerline';
            
%             centerline(:,1) = centerline(:,1) - app.patientPosition(1);
%             centerline(:,2) = centerline(:,2) - app.patientPosition(2);
%             centerline(:,3) = centerline(:,3) - app.patientPosition(3);
            
            if app.TranslateCLCheckBox.Value == 1
                file_name = app.sorted_filenames(end);              % Get the last filename in dcm series
                dcm_info = dicominfo(file_name);
                patientPosition = dcm_info.ImagePositionPatient;
%                 centerline(:,1) = centerline(:,1) + patientPosition(1) + 2*app.delta_x;
%                 centerline(:,2) = centerline(:,2) + patientPosition(2) + 2*app.delta_y;
%                 centerline(:,3) = centerline(:,3) - patientPosition(3) + 2*app.delta_z;
                centerline(:,1) = centerline(:,1) + patientPosition(1);
                centerline(:,2) = centerline(:,2) + patientPosition(2);
                centerline(:,3) = centerline(:,3) - patientPosition(3);
                
            end
            
            
            % Computing the tangent vectors at each point
            manual_flag = 1; % For computing the tangents based on centerline points
            tangent_vector = zeros(size(centerline,1),3);
            if manual_flag == 1
                for idx = 1:size(centerline,1)
                    if idx == size(centerline,1)
                        tangent_vector(idx,:) = tangent_vector(idx-1,:);
                    else
                        tangent_vector(idx,:) = (centerline(idx+1,:) - centerline(idx,:))./norm(centerline(idx+1,:) - centerline(idx,:));
                    end
                end
            else % For reading the tangent vectors from SV .pth file
                CL_tagent_line_read = regexpi(file_text, 'tangent x="([+-]?\d+\.?\d+)" y="([+-]?\d+\.?\d+)" z="([+-]?\d+\.?\d+)', 'match');
                CL_tangent_numerics = regexp(CL_tagent_line_read,'[+-]?\d+\.?\d*','match');
                tangent_out = cellfun(@(x) cellfun(@str2num, x, 'uni', 0), CL_tangent_numerics, 'uni', 0);
                tangent_vector = cell2mat(cellfun(@cell2mat, tangent_out, 'uni', 0));
                tangent_vector = reshape(tangent_vector,3,size(tangent_vector,2)/3);
                tangent_vector = tangent_vector';
            end
            
            CL_rotation_line_read = regexpi(file_text, 'rotation x="([+-]?\d+)" y="([+-]?\d+\.?\d+)" z="([+-]?\d+\.?\d+)', 'match');
            CL_rotation_numerics = regexp(CL_rotation_line_read,'[+-]?\d+\.?\d*','match');
            rotation_out = cellfun(@(x) cellfun(@str2num, x, 'uni', 0), CL_rotation_numerics, 'uni', 0);
            rotation_vector = cell2mat(cellfun(@cell2mat, rotation_out, 'uni', 0));
            rotation_vector = reshape(rotation_vector,3,size(rotation_vector,2)/3);
            rotation_vector = rotation_vector';
            
            
%             centerline
            
        end
        
        
        function [slice_2D, slice_3D] = Extract_Oblique_Slice(app, Image, center_point, normal_vector, slice_size, physical_coor_flag)
            % Oblique Slice Function
            % Extract_Oblique_Slice extracts an arbitray slice from a volume.
            % [slice_2D, slice_3D] = Extract_Oblique_Slice extracts an arbitrary 
            % slice given the center and normal of the slice.  The function outputs the 
            % 2D slice and 3D slice.
            %
            
            % Get pixel spacing from DICOM meta data
%             px_spacing = DICOM_info.PixelSpacing;
%             app.delta_x = px_spacing(1);
%             app.delta_y = px_spacing(2);
%             app.delta_z = DICOM_info.SliceThickness;
            
            % Normalize the normal vector
            normal_vector = normal_vector./norm(normal_vector);
            
            Image = squeeze(Image);
            
            if physical_coor_flag == 0
                % Scale the 3D Image to physical coordinates
                img_physical_coord = imresize3(Image, 'scale', [app.delta_x app.delta_y app.delta_z]);
%                 img_physical_coord = imdiffusefilt(img_physical_coord, 'NumberOfIterations',3);
            else
                img_physical_coord = Image;
%                 img_physical_coord = imdiffusefilt(img_physical_coord, 'NumberOfIterations',3);
            end
            
            % Refine the pixels
            % X_refined_range = (0:0.8:size(img_physical_coord,1))/delta_x;
            % Y_refined_range = (0:0.8:size(img_physical_coord,2))/delta_y;
            % Z_refined_range = (0:0.8:size(img_physical_coord,3))/delta_z;
            % [X_refined Y_refined Z_refined] = meshgrid(X_refined_range, Y_refined_range, Z_refined_range);
            % img_physical_coord = interp3(img_physical_coord, X_refined, Y_refined, Z_refined, 'linear', 0);
            
            % Creating a meshgrid with size "slice_size" 
            % with the center point of [0 0 0] and normal vector of [0 0 1]
            X_range = (-slice_size/2:0.08:slice_size/2)/app.delta_x;
            Y_range = (-slice_size/2:0.08:slice_size/2)/app.delta_y;
            Z_range = 1;
            
            [X_0 Y_0 Z_0] = meshgrid(X_range, Y_range, 0);
            
            
            % Reshape the meshgrid
            image_reshaped = [reshape(X_0,numel(X_0),1)'; reshape(Y_0,numel(Y_0),1)'; reshape(Z_0,numel(Z_0),1)'];
            
            % Augment the meshgrid to be ready for affine transformations
            image_reshaped_augmented = [image_reshaped; ones(1,size(image_reshaped,2))];
            
            % Building the rotation matrix based on the current normal [0 0 1]
            % and the target normal "normal_vector"
            
            vec_1 = [0 0 1]; % Normal Vector for the initial plane
            
            dot_v_n = dot(vec_1, normal_vector);
            cross_v_n = cross(vec_1, normal_vector);
            v_skew = [0                -cross_v_n(3)       cross_v_n(2);
                       cross_v_n(3)     0                   -cross_v_n(1);
                       -cross_v_n(2)    cross_v_n(1)        0];
            
            rot_mat = eye(3) + v_skew + v_skew*v_skew * (1/(1 + dot_v_n));
            
            % Obtaining the rotation angles around the x, y, and z axes
            % Euler angles
            % -pi < alpha < pi
            % -pi/2 < beta < pi/2
            % -pi < gamma < pi
            
            alpha = atan2(rot_mat(3,2), rot_mat(3,3));
            beta = atan2(-rot_mat(3,1), sqrt(rot_mat(3,2)^2 + rot_mat(3,3)^2));
            gamma = atan2(rot_mat(2,1), rot_mat(1,1));
            
            % Defining the symbolic Rotation Matrices around x, y, and z axes
            syms x
            rot_matrix_x = @(x) [1 0 0 0; 0 cos(x) -sin(x) 0; 0 sin(x) cos(x) 0; 0 0 0 1];
            rot_matrix_y = @(x) [cos(x) 0 sin(x) 0; 0 1 0 0; -sin(x) 0 cos(x) 0; 0 0 0 1];
            rot_matrix_z = @(x) [cos(x) -sin(x) 0 0; sin(x) cos(x) 0 0; 0 0 1 0; 0 0 0 1];
            
            % Building the Affine transformation matrix 
            % (3 Rotation around x, y, and z axes following 
            % a translation to the target center point)
            R = rot_matrix_z(gamma) * rot_matrix_y(beta) * rot_matrix_x(alpha);
            T = [1 0 0 0;
                 0 1 0 0;
                 0 0 1 0
                 center_point(1) center_point(2) center_point(3) 1];
            
            RT = R*T;
            
            % Applying the affine transformation to the initial plane
            transformed_plane = RT' * image_reshaped_augmented;
            
            % Getting rid of the augmented column of ones
            transformed_plane = transformed_plane(1:3,:)';
            
            % Reshape the X, Y, and Z coordinates
            X_final = reshape(transformed_plane(:,1), length(X_range),length(Y_range),length(Z_range));
            Y_final = reshape(transformed_plane(:,2), length(X_range),length(Y_range),length(Z_range));
            Z_final = reshape(transformed_plane(:,3), length(X_range),length(Y_range),length(Z_range));
            
            % Finding the pixel values on the transformed plane using interpolation in
            % the 3D image
            slice_2D = interp3(img_physical_coord, X_final, Y_final, Z_final, 'makima', 0);
            
            % Constructing the 3D slice
            tform_RT = affine3d(RT);
            slice_3D = imwarp(img_physical_coord, tform_RT);

        end


        function [slice_2D, slice_3D] = Extract_Oblique_Slice_idx(app, Image, center_point, normal_vector, slice_size)
            % Oblique Slice Function
            % Extract_Oblique_Slice extracts an arbitray slice from a volume.
            % [slice_2D, slice_3D] = Extract_Oblique_Slice extracts an arbitrary 
            % slice given the center and normal of the slice.  The function outputs the 
            % 2D slice and 3D slice.
            %
            
            % Normalize the normal vector
            normal_vector = normal_vector./norm(normal_vector);
            
            Image = squeeze(Image);
            
            img_physical_coord = Image;
            
            
            % Refine the pixels
            % X_refined_range = (0:0.8:size(img_physical_coord,1))/delta_x;
            % Y_refined_range = (0:0.8:size(img_physical_coord,2))/delta_y;
            % Z_refined_range = (0:0.8:size(img_physical_coord,3))/delta_z;
            % [X_refined Y_refined Z_refined] = meshgrid(X_refined_range, Y_refined_range, Z_refined_range);
            % img_physical_coord = interp3(img_physical_coord, X_refined, Y_refined, Z_refined, 'linear', 0);
            
            % Creating a meshgrid with size "slice_size" 
            % with the center point of [0 0 0] and normal vector of [0 0 1]
            X_range = (-slice_size/2:0.1:slice_size/2);
            Y_range = (-slice_size/2:0.1:slice_size/2);
            Z_range = 1;
            
            [X_0 Y_0 Z_0] = meshgrid(X_range, Y_range, 0);
            
            
            % Reshape the meshgrid
            image_reshaped = [reshape(X_0,numel(X_0),1)'; reshape(Y_0,numel(Y_0),1)'; reshape(Z_0,numel(Z_0),1)'];
            
            % Augment the meshgrid to be ready for affine transformations
            image_reshaped_augmented = [image_reshaped; ones(1,size(image_reshaped,2))];
            
            % Building the rotation matrix based on the current normal [0 0 1]
            % and the target normal "normal_vector"
            
            vec_1 = [0 0 1]; % Normal Vector for the initial plane
            
            dot_v_n = dot(vec_1, normal_vector);
            cross_v_n = cross(vec_1, normal_vector);
            v_skew = [0                -cross_v_n(3)       cross_v_n(2);
                       cross_v_n(3)     0                   -cross_v_n(1);
                       -cross_v_n(2)    cross_v_n(1)        0];
            
            rot_mat = eye(3) + v_skew + v_skew*v_skew * (1/(1 + dot_v_n));
            
            % Obtaining the rotation angles around the x, y, and z axes
            % Euler angles
            % -pi < alpha < pi
            % -pi/2 < beta < pi/2
            % -pi < gamma < pi
            
            alpha = atan2(rot_mat(3,2), rot_mat(3,3));
            beta = atan2(-rot_mat(3,1), sqrt(rot_mat(3,2)^2 + rot_mat(3,3)^2));
            gamma = atan2(rot_mat(2,1), rot_mat(1,1));
            
            % Defining the symbolic Rotation Matrices around x, y, and z axes
            syms x
            rot_matrix_x = @(x) [1 0 0 0; 0 cos(x) -sin(x) 0; 0 sin(x) cos(x) 0; 0 0 0 1];
            rot_matrix_y = @(x) [cos(x) 0 sin(x) 0; 0 1 0 0; -sin(x) 0 cos(x) 0; 0 0 0 1];
            rot_matrix_z = @(x) [cos(x) -sin(x) 0 0; sin(x) cos(x) 0 0; 0 0 1 0; 0 0 0 1];
            
            % Building the Affine transformation matrix 
            % (3 Rotation around x, y, and z axes following 
            % a translation to the target center point)
            R = rot_matrix_z(gamma) * rot_matrix_y(beta) * rot_matrix_x(alpha);
            T = [1 0 0 0;
                 0 1 0 0;
                 0 0 1 0
                 center_point(1) center_point(2) center_point(3) 1];
            
            RT = R*T;
            
            % Applying the affine transformation to the initial plane
            transformed_plane = RT' * image_reshaped_augmented;
            
            % Getting rid of the augmented column of ones
            transformed_plane = transformed_plane(1:3,:)';
            
            % Reshape the X, Y, and Z coordinates
            X_final = reshape(transformed_plane(:,1), length(X_range),length(Y_range),length(Z_range));
            Y_final = reshape(transformed_plane(:,2), length(X_range),length(Y_range),length(Z_range));
            Z_final = reshape(transformed_plane(:,3), length(X_range),length(Y_range),length(Z_range));
            
            % Finding the pixel values on the transformed plane using interpolation in
            % the 3D image
            slice_2D = interp3(img_physical_coord, X_final, Y_final, Z_final, 'makima', 0);
            
            % Constructing the 3D slice
            tform_RT = affine3d(RT);
            slice_3D = imwarp(img_physical_coord, tform_RT);

        end
        
        
        function [slice_2D, slice_3D, X_final, Y_final, Z_final] = Extract_slice_SV_method(app, Image, centerline, normal, rotation_vector, slice_size)

            zhat = [0 0 1];
            theta = subspace(zhat, normal);
            ax_rot = cross(zhat, normal);
            Rot1 = makehgtform('axisrotate', ax_rot, theta);
            
            pt_tmp = [1 0 0 1];
            
            pt = Rot1 * pt_tmp';
            
            rot = subspace(pt(1:3), rotation_vector);
            
            x = cross(pt(1:3), rotation_vector);
            
            d = dot(x, normal);
            
            if d<0.0
                rot = -rot;
            end
            
            M1 = makehgtform('axisrotate', ax_rot, theta);
            M2 = makehgtform('axisrotate', normal, rot);
            M3 = makehgtform('translate', centerline);
            
%             slice_size = 50;
            
            X_range = 1;
            Y_range = (-slice_size/2:0.1:slice_size/2);
            Z_range = (-slice_size/2:0.1:slice_size/2);
        
            [X_0 Y_0 Z_0] = meshgrid(1, Y_range, Z_range);
        
        
            % Reshape the meshgrid
            image_reshaped = [reshape(X_0,numel(X_0),1)'; reshape(Y_0,numel(Y_0),1)'; reshape(Z_0,numel(Z_0),1)'];
        
            % Augment the meshgrid to be ready for affine transformations
            image_reshaped_augmented = [image_reshaped; ones(1,size(image_reshaped,2))];
        
            % Applying the affine transformation to the initial plane
            transformed_plane = M3 * M2 * M1 * image_reshaped_augmented;
        
            % Getting rid of the augmented column of ones
            transformed_plane = transformed_plane(1:3,:)';
        
            % Reshape the X, Y, and Z coordinates
            X_final = reshape(transformed_plane(:,1), length(X_range),length(Y_range),length(Z_range));
            Y_final = reshape(transformed_plane(:,2), length(X_range),length(Y_range),length(Z_range));
            Z_final = reshape(transformed_plane(:,3), length(X_range),length(Y_range),length(Z_range));
             
            slice_2D = interp3(Image, X_final, Y_final, Z_final, 'makima', -1000);
            
            my_tr = M3 * M2 * M1;
            tform_RT = affine3d(my_tr');
            slice_3D = imwarp(Image, tform_RT);
            
        end
        
        
        function [slice_2D, slice_3D, X_final, Y_final, Z_final] = Extract_slice_vtk_method(app, Image, centerline, normal, slice_size)
            
            a = [0 0 1];
            theta = acos(dot(a,normal));
            axis_rotation = cross(a,normal)./norm(cross(a,normal));
            Rot_mat = makehgtform('axisrotate', axis_rotation, theta);
            trans_mat = makehgtform('translate', centerline);
            
%             slice_size = 50;
            
            Z_range = 1;
            Y_range = (-slice_size/2:0.125:slice_size/2);
            X_range = (-slice_size/2:0.125:slice_size/2);
        
            [X_0 Y_0 Z_0] = meshgrid(X_range, Y_range, Z_range);
        
        
            % Reshape the meshgrid
            image_reshaped = [reshape(X_0,numel(X_0),1)'; reshape(Y_0,numel(Y_0),1)'; reshape(Z_0,numel(Z_0),1)'];
        
            % Augment the meshgrid to be ready for affine transformations
            image_reshaped_augmented = [image_reshaped; ones(1,size(image_reshaped,2))];
        
            % Applying the affine transformation to the initial plane
            transformed_plane = trans_mat * Rot_mat * image_reshaped_augmented;
        
            % Getting rid of the augmented column of ones
            transformed_plane = transformed_plane(1:3,:)';
        
            % Reshape the X, Y, and Z coordinates
            X_final = reshape(transformed_plane(:,1), length(X_range),length(Y_range),length(Z_range));
            Y_final = reshape(transformed_plane(:,2), length(X_range),length(Y_range),length(Z_range));
            Z_final = reshape(transformed_plane(:,3), length(X_range),length(Y_range),length(Z_range));
             
            slice_2D = interp3(Image, X_final, Y_final, Z_final, 'makima', -1000);
            
            my_tr = trans_mat * Rot_mat;
            tform_RT = affine3d(my_tr');
            slice_3D = imwarp(Image, tform_RT);
        
        end
        
        
        function [slice_2D, slice_3D, X_final, Y_final, Z_final] = Extract_slice_vtk_method2(app, Image, centerline, normal)
    
            a = [0 0 1];
            theta = acos(dot(a,normal));
            axis_rotation = cross(a,normal)./norm(cross(a,normal));
            Rot_mat = makehgtform('axisrotate', axis_rotation, theta);
            trans_mat = makehgtform('translate', centerline);
            
            slice_size = 80;
            
        %     Z_range = 1.*0.625;
        %     Y_range = (-slice_size/2:1:slice_size/2).*0.3516;
        %     X_range = (-slice_size/2:1:slice_size/2).*0.3516;
            
            Z_range = 1;
            Y_range = (-slice_size/2:1:slice_size/2);
            X_range = (-slice_size/2:1:slice_size/2);
        
            [X_0 Y_0 Z_0] = meshgrid(X_range, Y_range, Z_range);
        
        
            % Reshape the meshgrid
            image_reshaped = [reshape(X_0,numel(X_0),1)'; reshape(Y_0,numel(Y_0),1)'; reshape(Z_0,numel(Z_0),1)'];
        
            % Augment the meshgrid to be ready for affine transformations
            image_reshaped_augmented = [image_reshaped; ones(1,size(image_reshaped,2))];
        
            % Applying the affine transformation to the initial plane
            transformed_plane = trans_mat * Rot_mat * image_reshaped_augmented;
        
            % Getting rid of the augmented column of ones
            transformed_plane = transformed_plane(1:3,:)';
        
            % Reshape the X, Y, and Z coordinates
            X_final = reshape(transformed_plane(:,1), length(X_range),length(Y_range),length(Z_range));
            Y_final = reshape(transformed_plane(:,2), length(X_range),length(Y_range),length(Z_range));
            Z_final = reshape(transformed_plane(:,3), length(X_range),length(Y_range),length(Z_range));
             
            slice_2D = interp3(Image, X_final, Y_final, Z_final, 'linear', -1000);
            
            my_tr = trans_mat * Rot_mat;
            tform_RT = affine3d(my_tr');
            slice_3D = imwarp(Image, tform_RT);
        
        end


        function rotation_vectors_updated = find_rotation_vector(app, centerline, CL_splitted, rotation_vectors)
            
            for ii = 1:size(CL_splitted,1)
                diff(ii) = norm(centerline - CL_splitted(ii,:));
                [M, idx] = min(diff);
                rotation_vectors_updated(ii,:) = rotation_vectors(idx,:);
            end
            
        end


        function image_database = create_transferrable_database(app)
            
            
            image_database.dcm_series_volume_HU = app.dcm_series_volume_HU;
            image_database.dcm_series_volume_HU_scaled = app.dcm_series_volume_HU_scaled;
            image_database.sorted_filenames = app.sorted_filenames;
            image_database.centerline = app.centerline_interp;
            counter = 1;
            for idx = 2:2:length(fieldnames(app.slice_2D_zoomed))
                slice_2d_tmp.(sprintf('idx_%d', counter)) = app.slice_2D.(sprintf('idx_%d', idx));
                slice_2d_zoomed_tmp.(sprintf('idx_%d', counter)) = app.slice_2D_zoomed.(sprintf('idx_%d', idx));
                counter = counter + 1;
            end
            
%             image_database.slice_2D = app.slice_2D
%             image_database.slice_2D_zoomed = app.slice_2D_zoomed;
            image_database.slice_2D = slice_2d_tmp;
            image_database.slice_2D_zoomed = slice_2d_zoomed_tmp;
 
        
        end


        function q = interp3D_curve(app, points, N, interval_target)
        
            currentpt = points(1,:); % current point
            indfirst = 2; % index of the most closest point in p from curpt
            len = size(points,1); % length of p
            q = currentpt; % output point
            k = 0;
            
            % distance between points in points
            for k0 = 1:len-1
               dist_between_pts(k0) = app.distance(points(k0,:),points(k0+1,:));
            end
            
            total_dist = sum(dist_between_pts);
            % Target interval
            if interval_target == 0
                interval_target = total_dist./(N-1);
            else
                N = (total_dist./interval_target) + 1;
            end
            
            
            for k = 1:N-1
           
               new_pt = []; 
               distsum = 0;
               ptnow = currentpt;
               kk = 0;
               pttarget = points(indfirst,:);
               remainder = interval_target; % remainder of distance that should be accumulated
               while isempty(new_pt)
                  % calculate the distance from active point to the most
                  % closest point in p
                  disttmp = app.distance(ptnow,pttarget);
                  distsum = distsum + disttmp;
                  % if distance is enough, generate newpt. else, accumulate
                  % distance
                  if distsum >= interval_target
                     new_pt = app.interpintv(ptnow,pttarget,remainder);
                  else
                     remainder = remainder - disttmp;
                     ptnow = pttarget;
                     kk = kk + 1;
                     if indfirst+kk > len
                        new_pt = points(len,:);
                     else
                        pttarget = points(indfirst+kk,:);
                     end
                  end
               end
        
               % add to the output points
               q = [q; new_pt];
        
               % update currentpt and indfirst
               currentpt = new_pt;
               indfirst = indfirst + kk;
        
            end


        end
        
        
        function l = distance(app, x,y)
            % calculate distance
            if size(x,2) == 2
               l = sqrt((x(1)-y(:,1)).^2+(x(2)-y(:,2)).^2);
            elseif size(x,2) == 3
               l = sqrt((x(1)-y(:,1)).^2+(x(2)-y(:,2)).^2+(x(3)-y(:,3)).^2);
            else
               error('Number of dimensions should be 2 or 3.');
            end
        end
        
        function newpt = interpintv(app, pt1,pt2,intv)
        
            % Generate a point between pt1 and pt2 in such a way that
            % the distance between pt1 and new point is intv.
            % pt1 and pt2 should be 1x3 or 1x2 vector.
        
            dirvec = pt2 - pt1;
            dirvec = dirvec./norm(dirvec);
            l = dirvec(1); m = dirvec(2);
            newpt = [intv*l+pt1(1),intv*m+pt1(2)];
            if length(pt1) == 3
               n = dirvec(3);
               newpt = [newpt,intv*n+pt1(3)];
            end
        
        end
        
        function tangents = update_tangents(app, centerline)
        
            for idx = 1:size(centerline,1)
                if idx == size(centerline,1)
                    tangent_vector(idx,:) = tangent_vector(idx-1,:);
                else
                    tangent_vector(idx,:) = (centerline(idx+1,:) - centerline(idx,:))./norm(centerline(idx+1,:) - centerline(idx,:));
                end
            end
            
            tangents = tangent_vector;
        
        end
        
        
        function tangents_aug = update_tangents_aug(app, tangents)
            
        
            for idx = 1:size(tangents,1)
                if idx < 4
                    tangents_aug(idx,:) = tangents(1,:);
                    continue;
                elseif idx == size(tangents,1)
                    tangents_aug(idx,:) = tangents(idx,:);
                    continue;
                elseif mod(idx,4)==0
                    tangents_aug(idx,:) = tangents(idx,:);
                    tan_tmp = tangents(idx,:);
                    continue;
                end
                
                tangents_aug(idx,:) = tan_tmp;
                
            end
            
%             tangents_aug
        
        end
        
        
        function [CL_splitted bif_index] = split_CL_at_bifurcation(app, CL, bif_point)
            
            X_bif = bif_point(1);
            Y_bif = bif_point(2);
            Z_bif = bif_point(3);
            
            size(CL)
            
            err = CL - bif_point;
            for idx = 1:size(CL, 1)
                err_mag(idx) = norm(err(idx,:))
            end
            [M,bif_idx] = min(err_mag)
            
            
            CL_sp1 = flip(CL(1:bif_idx,:))
            CL_sp2 = CL(bif_idx+1:end,:)
            
            CL_sp1_interp = flip(app.interp3D_curve(CL_sp1, 100, 0.5));
            CL_sp2_interp = app.interp3D_curve(CL_sp2, 100, 0.5);
            
            CL_splitted = [CL_sp1_interp; CL_sp2_interp];
            bif_index = size(CL_sp1_interp,1);
            
        end
        
        
        function Export_dicom_series(app, filename)
        
            series_uid = dicomuid;
            new_delta_x = 0.08/app.delta_x;
            new_delta_y = 0.08/app.delta_y;
            slice_thickness = 0.25;
            
            info = dicominfo(app.sorted_filenames(1));
%             [I,cmap] = dicomread(app.sorted_filenames(1));
%             cmap
            counter = 1;
            
            for idx = 1:1:length(fieldnames(app.slice_2D_zoomed))

                info.SeriesInstanceUID = series_uid;
                info.PixelSpacing = [new_delta_x; new_delta_y];
                info.SliceThickness = slice_thickness;
                info.InstanceNumber = counter;
                info.Width = 61;
                info.Height = 61;
                info.SeriesDescription = 'Slices';
                
                
                slice_tmp = int16( (app.slice_2D_zoomed.(sprintf('idx_%d', idx)) - info.RescaleIntercept)/info.RescaleSlope );
                
                dicomwrite(slice_tmp, filename+"/Slice_"+num2str(counter)+".dcm", info)
                
                counter = counter + 1;
                
            end
            
            series_uid = dicomuid;
%             new_delta_x = 0.08/app.delta_x;
%             new_delta_y = 0.08/app.delta_y;
            new_delta_x = 0.25*app.delta_x;
            new_delta_y = 0.25*app.delta_y;
            slice_thickness = 0.25;
            
            info = dicominfo(app.sorted_filenames(1));
            
            counter = 1;
            
            for idx = 1:1:length(fieldnames(app.slice_2D_reduced_enhsd))

                info.SeriesInstanceUID = series_uid;
                info.PixelSpacing = [new_delta_x; new_delta_y];
                info.SliceThickness = slice_thickness;
                info.InstanceNumber = counter;
                info.Width = size(app.slice_2D_reduced_enhsd.(sprintf('idx_%d', idx)),1);
                info.Height = size(app.slice_2D_reduced_enhsd.(sprintf('idx_%d', idx)),2);
                info.SeriesDescription = 'Slices';
                
                tmp_2D = app.slice_2D_reduced_enhsd.(sprintf('idx_%d', idx));
                assignin('base','tmp_2D',tmp_2D);
                
                slice_tmp = int16( (tmp_2D - info.RescaleIntercept)./info.RescaleSlope );
                
                dicomwrite(slice_tmp, filename+"/new_slices_enhsd/Slice_reduced_enhsd"+num2str(counter)+".dcm", info)
                
                counter = counter + 1;
                
            end
            
            
            series_uid = dicomuid;
%             new_delta_x = 0.08/app.delta_x;
%             new_delta_y = 0.08/app.delta_y;
            new_delta_x = 0.25*app.delta_x;
            new_delta_y = 0.25*app.delta_y;
            slice_thickness = 0.25;
            
            info = dicominfo(app.sorted_filenames(1));
            
            counter = 1;
            
            for idx = 1:1:length(fieldnames(app.slice_2D_reduced))

                info.SeriesInstanceUID = series_uid;
                info.PixelSpacing = [new_delta_x; new_delta_y];
                info.SliceThickness = slice_thickness;
                info.InstanceNumber = counter;
                info.Width = size(app.slice_2D_reduced.(sprintf('idx_%d', idx)),1);
                info.Height = size(app.slice_2D_reduced.(sprintf('idx_%d', idx)),2);
                info.SeriesDescription = 'Slices';
                
                tmp_2D = app.slice_2D_reduced.(sprintf('idx_%d', idx));
                assignin('base','tmp_2D',tmp_2D);
                
                slice_tmp = int16( (tmp_2D - info.RescaleIntercept)./info.RescaleSlope );
                
                dicomwrite(slice_tmp, filename+"/new_slices/Slice_reduced_"+num2str(counter)+".dcm", info)
                
                counter = counter + 1;
                
            end
            
        
        end
        
        
        % ************************************************************
        % ********************* Laplacian filter *********************
        % ************************************************************
        
        function img = Laplacian_filter(app, Image, sigma, alpha)
        
            if(~exist('alpha','var')) 
                alpha = 0.1;            % Characterizes the amplitude of edges
            end
            
            if(~exist('sigma','var')) 
                sigma = 0.3;            % Controls smoothing of details
            end
            
        %     sigma = 0.3;            % Characterizes the amplitude of edges
        %     alpha = 0.1;              % Controls smoothing of details
        
            img_tmp = int8(Image);
        
            img_lap = locallapfilt(img_tmp, sigma, alpha);
        
            img_double = double(img_tmp);
            
%             figure;
%             subplot(1,3,1)
%             imshow(Image, [lower_level upper_level])
%             title('Hounsfield Image')
%             subplot(1,3,2)
%             imshow(img_lap)
%             title('Laplacian Filter')
%             subplot(1,3,3)
%             imshow(img_double, [lower_level upper_level])
%             title('Hounsfield Image (Double)')
            
            img = img_double;
            
        end
        
        % ************************************************************
        % ******************* Gradient Mag filter ********************
        % ************************************************************
        
        function img = Gradient_mag_filter(app, Image)
        
            img_gmag = imgradient(Image);
            img_double = double(img_gmag);
%             figure;
%             subplot(2,3,1)
%             imshow(my_pic, [lower_level upper_level])
%             title('Hounsfield Image')
%             subplot(2,3,2)
%             imshow(img_gmag, [])
%             title('Gradient Magnitude Image')
%             subplot(2,3,3)
%             imshow(img_double, [lower_level upper_level])
%             title('Gradient Magnitude Image (Double)')
            
            img = img_double;
        
        end
        
        
        % ************************************************************
        % ********************* Sigmoid filter ***********************
        % ************************************************************
        
        function img = Sigmoid_function(app, Image, a, c)
   
            if(~exist('a','var')) 
                a = 0.1;        
            end
            
            if(~exist('c','var')) 
                c = 20;            
            end
        
            img_sigmoid = 1./(1 + exp(-a.*(Image - c)));
        %     subplot(2,3,5)
        %     imshow(img_sigmoid, [])
        %     title('Sigmoid Function Image, c=50 (vessel)')
        % 
        %     sgtitle("1A-2012-LCx: Slice#30",'Color','red', 'FontSize', 20)
            
            img = img_sigmoid;
        
        end
        
        
        function ROI_mask = init_levelset(app, Image, seed, seed_bound)

            if(~exist('seed_bound','var')) 
                seed_bound = 5;        
            end
            
            mask_bound_X = (seed(1) - seed_bound):(seed(1) + seed_bound);
            mask_bound_Y = (seed(2) - seed_bound):(seed(2) + seed_bound);
        
            I = Image;
            m = zeros(size(I,1),size(I,2));          %-- create initial mask
            m(mask_bound_Y,mask_bound_X) = 1;
        
            I = imresize(I,1);  %-- make image smaller 
            m = imresize(m,1);  %     for fast computation
        
        %     figure;
        %     subplot(2,2,1); imshow(I, []); title('Input Image');
        %     subplot(2,2,2); imshow(m); title('Initialization');
        %     subplot(2,2,3); title('Segmentation');
        % 
            seg = region_seg(app, I, m, 400, 0.1, false); %-- Run segmentation
        % 
        %     subplot(2,2,4); imshow(seg); title('Global Region-Based Segmentation');
        
            ROI_mask = seg;
            
        end
        
        
        function seg = region_seg(app, I, init_mask, max_its, alpha, display)
          
          %-- default value for parameter alpha is .1
          if(~exist('alpha','var')) 
            alpha = .2; 
          end
          %-- default behavior is to display intermediate outputs
          if(~exist('display','var'))
            display = true;
          end
          %-- ensures image is 2D double matrix
          I = im2graydouble(app, I);    
          
          %-- Create a signed distance map (SDF) from mask
          phi = mask2phi(app, init_mask);
          
          %--main loop
          for its = 1:max_its   % Note: no automatic convergence test
        
            idx = find(phi <= 1.2 & phi >= -1.2);  %get the curve's narrow band
            
            %-- find interior and exterior mean
            upts = find(phi<=0);                 % interior points
            vpts = find(phi>0);                  % exterior points
            u = sum(I(upts))/(length(upts)+eps); % interior mean
            v = sum(I(vpts))/(length(vpts)+eps); % exterior mean
            
            F = (I(idx)-u).^2-(I(idx)-v).^2;         % force from image information
            curvature = get_curvature(app, phi,idx);  % force from curvature penalty
            
            dphidt = F./max(abs(F)) + alpha*curvature;  % gradient descent to minimize energy
            
            %-- maintain the CFL condition
            dt = .45/(max(dphidt)+eps);
                
            %-- evolve the curve
            phi(idx) = phi(idx) + dt.*dphidt;
        
            %-- Keep SDF smooth
            phi = sussman(app, phi, .5);
        
            %-- intermediate output
            if((display>0)&&(mod(its,20) == 0)) 
              showCurveAndPhi(app, I,phi,its);  
            end
          end
          
          %-- final output
          if(display)
            showCurveAndPhi(app, I,phi,its);
          end
          
          %-- make mask from SDF
          seg = phi<=0; %-- Get mask from levelset
        end
          
        %---------------------------------------------------------------------
        %---------------------------------------------------------------------
        %-- AUXILIARY FUNCTIONS ----------------------------------------------
        %---------------------------------------------------------------------
        %---------------------------------------------------------------------
          
          
        %-- Displays the image with curve superimposed
        function showCurveAndPhi(app, I, phi, i)
        %   imshow(I,'initialmagnification',200,'displayrange',[0 255]); hold on;
          imshow(I,'initialmagnification',200,'displayrange',[]); hold on;
          contour(phi, [0 0], 'g','LineWidth',4);
          contour(phi, [0 0], 'k','LineWidth',2);
          hold off; title([num2str(i) ' Iterations']); drawnow;
        end 
        %-- converts a mask to a SDF
        function phi = mask2phi(app, init_a)
          phi=bwdist(init_a)-bwdist(1-init_a)+im2double(init_a)-.5;
        end
        %-- compute curvature along SDF
        function curvature = get_curvature(app, phi,idx)
            [dimy, dimx] = size(phi);        
            [y x] = ind2sub([dimy,dimx],idx);  % get subscripts
        
            %-- get subscripts of neighbors
            ym1 = y-1; xm1 = x-1; yp1 = y+1; xp1 = x+1;
        
            %-- bounds checking  
            ym1(ym1<1) = 1; xm1(xm1<1) = 1;              
            yp1(yp1>dimy)=dimy; xp1(xp1>dimx) = dimx;    
        
            %-- get indexes for 8 neighbors
            idup = sub2ind(size(phi),yp1,x);    
            iddn = sub2ind(size(phi),ym1,x);
            idlt = sub2ind(size(phi),y,xm1);
            idrt = sub2ind(size(phi),y,xp1);
            idul = sub2ind(size(phi),yp1,xm1);
            idur = sub2ind(size(phi),yp1,xp1);
            iddl = sub2ind(size(phi),ym1,xm1);
            iddr = sub2ind(size(phi),ym1,xp1);
            
            %-- get central derivatives of SDF at x,y
            phi_x  = -phi(idlt)+phi(idrt);
            phi_y  = -phi(iddn)+phi(idup);
            phi_xx = phi(idlt)-2*phi(idx)+phi(idrt);
            phi_yy = phi(iddn)-2*phi(idx)+phi(idup);
            phi_xy = -0.25*phi(iddl)-0.25*phi(idur)...
                     +0.25*phi(iddr)+0.25*phi(idul);
            phi_x2 = phi_x.^2;
            phi_y2 = phi_y.^2;
            
            %-- compute curvature (Kappa)
            curvature = ((phi_x2.*phi_yy + phi_y2.*phi_xx - 2*phi_x.*phi_y.*phi_xy)./...
                      (phi_x2 + phi_y2 +eps).^(3/2)).*(phi_x2 + phi_y2).^(1/2);        
        end
        %-- Converts image to one channel (grayscale) double
        function img = im2graydouble(app, img)    
          [dimy, dimx, c] = size(img);
          if(isfloat(img)) % image is a double
            if(c==3) 
              img = rgb2gray(uint8(img)); 
            end
          else           % image is a int
            if(c==3) 
              img = rgb2gray(img); 
            end
            img = double(img);
          end
        end
        %-- level set re-initialization by the sussman method
        function D = sussman(app, D, dt)
          % forward/backward differences
          a = D - shiftR(app, D); % backward
          b = shiftL(app, D) - D; % forward
          c = D - shiftD(app, D); % backward
          d = shiftU(app, D) - D; % forward
          
          a_p = a;  a_n = a; % a+ and a-
          b_p = b;  b_n = b;
          c_p = c;  c_n = c;
          d_p = d;  d_n = d;
          
          a_p(a < 0) = 0;
          a_n(a > 0) = 0;
          b_p(b < 0) = 0;
          b_n(b > 0) = 0;
          c_p(c < 0) = 0;
          c_n(c > 0) = 0;
          d_p(d < 0) = 0;
          d_n(d > 0) = 0;
          
          dD = zeros(size(D));
          D_neg_ind = find(D < 0);
          D_pos_ind = find(D > 0);
          dD(D_pos_ind) = sqrt(max(a_p(D_pos_ind).^2, b_n(D_pos_ind).^2) ...
                               + max(c_p(D_pos_ind).^2, d_n(D_pos_ind).^2)) - 1;
          dD(D_neg_ind) = sqrt(max(a_n(D_neg_ind).^2, b_p(D_neg_ind).^2) ...
                               + max(c_n(D_neg_ind).^2, d_p(D_neg_ind).^2)) - 1;
          
          D = D - dt .* sussman_sign(app, D) .* dD;
        end
        %-- whole matrix derivatives
        function shift = shiftD(app, M)
          shift = shiftR(app, M')';
        end
        
        function shift = shiftL(app, M)
          shift = [ M(:,2:size(M,2)) M(:,size(M,2)) ];
        end
        
        function shift = shiftR(app, M)
          shift = [ M(:,1) M(:,1:size(M,2)-1) ];
        end
        
        function shift = shiftU(app, M)
          shift = shiftL(app, M')';
        end
        
        function S = sussman_sign(app, D)
          S = D ./ sqrt(D.^2 + 1);  
        end


        % *************************************************************
        % ************ Multiscale Vessel Enhancement Filter ***********
        % *************************************************************
        
        function [Iout,whatScale,Voutx,Vouty,Voutz] = FrangiFilter3D(app, I, options)
            
            %
            % This function FRANGIFILTER3D uses the eigenvectors of the Hessian to
            % compute the likeliness of an image region to vessels, according
            % to the method described by Frangi
            %
            % [J,Scale,Vx,Vy,Vz] = FrangiFilter3D(I, Options)
            %
            % inputs,
            %   I : The input image volume (vessel volume)
            %   Options : Struct with input options,
            %       .FrangiScaleRange : The range of sigmas used, default [1 8]
            %       .FrangiScaleRatio : Step size between sigmas, default 2
            %       .FrangiAlpha : Frangi vesselness constant, treshold on Lambda2/Lambda3
            %					   determines if its a line(vessel) or a plane like structure
            %					   default .5;
            %       .FrangiBeta  : Frangi vesselness constant, which determines the deviation
            %					   from a blob like structure, default .5;
            %       .FrangiC     : Frangi vesselness constant which gives
            %					   the threshold between eigenvalues of noise and 
            %					   vessel structure. A thumb rule is dividing the 
            %					   the greyvalues of the vessels by 4 till 6, default 500;
            %       .BlackWhite : Detect black ridges (default) set to true, for
            %                       white ridges set to false.
            %       .verbose : Show debug information, default true
            %
            % outputs,
            %   J : The vessel enhanced image (pixel is the maximum found in all scales)
            %   Scale : Matrix with the scales on which the maximum intensity 
            %           of every pixel is found
            %   Vx,Vy,Vz: Matrices with the direction of the smallest eigenvector, pointing
            %				in the direction of the line/vessel.
            %
            % Literature, 
            %	Manniesing et al. "Multiscale Vessel Enhancing Diffusion in 
            %		CT Angiography Noise Filtering"
            %
            % Example,
            %   % compile needed mex file
            %   mex eig3volume.c
            %
            %   load('ExampleVolumeStent');
            %   
            %   % Frangi Filter the stent volume
            %   options.BlackWhite=false;
            %   options.FrangiScaleRange=[1 1];
            %   Vfiltered=FrangiFilter3D(V,options);
            %
            %   % Show maximum intensity plots of input and result
            %   figure, 
            %   subplot(2,2,1), imshow(squeeze(max(V,[],2)),[])
            %   subplot(2,2,2), imshow(squeeze(max(Vfiltered,[],2)),[])
            %   subplot(2,2,3), imshow(V(:,:,100),[])
            %   subplot(2,2,4), imshow(Vfiltered(:,:,100),[])
            %
            % Written by D.Kroon University of Twente (May 2009)
            
            % Constants vesselness function
            
            mex eig3volume.c
            
            defaultoptions = struct('FrangiScaleRange', [1 10], 'FrangiScaleRatio', 2, 'FrangiAlpha', 0.5, 'FrangiBeta', 0.5, 'FrangiC', 20, 'verbose',true,'BlackWhite',true);
            
            % Process inputs
            if(~exist('options','var'))
                options=defaultoptions; 
            else
                tags = fieldnames(defaultoptions);
                for i=1:length(tags)
                     if(~isfield(options,tags{i})),  options.(tags{i})=defaultoptions.(tags{i}); end
                end
                if(length(tags)~=length(fieldnames(options))) 
                    warning('FrangiFilter3D:unknownoption','unknown options found');
                end
            end
            
            % Use single or double for calculations
            if(~isa(I,'double')), I=single(I); end
            
            sigmas=options.FrangiScaleRange(1):options.FrangiScaleRatio:options.FrangiScaleRange(2);
            sigmas = sort(sigmas, 'ascend');
            
            % Frangi filter for all sigmas
            for i = 1:length(sigmas)
                % Show progress
                if(options.verbose)
                    disp(['Current Frangi Filter Sigma: ' num2str(sigmas(i)) ]);
                end
                
                % Calculate 3D hessian
                [Dxx, Dyy, Dzz, Dxy, Dxz, Dyz] = Hessian3D(app, I,sigmas(i));
            
                if(sigmas(i)>0)
                    % Correct for scaling
                    c=(sigmas(i)^2);
                    Dxx = c*Dxx; Dxy = c*Dxy;
                    Dxz = c*Dxz; Dyy = c*Dyy;
                    Dyz = c*Dyz; Dzz = c*Dzz;
                end
                
                % Calculate eigen values
                if(nargout>2)
                    [Lambda1,Lambda2,Lambda3,Vx,Vy,Vz]=eig3volume(Dxx,Dxy,Dxz,Dyy,Dyz,Dzz);
                else
                    [Lambda1,Lambda2,Lambda3]=eig3volume(Dxx,Dxy,Dxz,Dyy,Dyz,Dzz);
                end
                
                % Free memory
                clear Dxx Dyy  Dzz Dxy  Dxz Dyz;
            
                % Calculate absolute values of eigen values
                LambdaAbs1=abs(Lambda1);
                LambdaAbs2=abs(Lambda2);
                LambdaAbs3=abs(Lambda3);
            
                % The Vesselness Features
                Ra=LambdaAbs2./LambdaAbs3;
                Rb=LambdaAbs1./sqrt(LambdaAbs2.*LambdaAbs3);
            
                % Second order structureness. S = sqrt(sum(L^2[i])) met i =< D
                S = sqrt(LambdaAbs1.^2+LambdaAbs2.^2+LambdaAbs3.^2);
                A = 2*options.FrangiAlpha^2; B = 2*options.FrangiBeta^2;  C = 2*options.FrangiC^2;
            	
                % Free memory
                clear LambdaAbs1 LambdaAbs2 LambdaAbs3
            
                %Compute Vesselness function
                expRa = (1-exp(-(Ra.^2./A)));
                expRb =    exp(-(Rb.^2./B));
                expS  = (1-exp(-S.^2./(2*options.FrangiC^2)));
            %     keyboard
                % Free memory
                clear S A B C Ra Rb
            
                %Compute Vesselness function
                Voxel_data = expRa.* expRb.* expS;
                
                % Free memory
                clear expRa expRb expRc;
                
                if(options.BlackWhite)
                    Voxel_data(Lambda2 < 0)=0; Voxel_data(Lambda3 < 0)=0;
                else
                    Voxel_data(Lambda2 > 0)=0; Voxel_data(Lambda3 > 0)=0;
                end
                    
                % Remove NaN values
                Voxel_data(~isfinite(Voxel_data))=0;
            
                % Add result of this scale to output
                if(i==1)
                    Iout=Voxel_data;
                    if(nargout>1)
                        whatScale = ones(size(I),class(Iout));
                    end
                    if(nargout>2)
                        Voutx=Vx; Vouty=Vy; Voutz=Vz;
                    end
                else
                    if(nargout>1)
                        whatScale(Voxel_data>Iout)=i;
                    end
                    if(nargout>2)
                        Voutx(Voxel_data>Iout)=Vx(Voxel_data>Iout);
                        Vouty(Voxel_data>Iout)=Vy(Voxel_data>Iout);
                        Voutz(Voxel_data>Iout)=Vz(Voxel_data>Iout);
                    end
                    % Keep maximum filter response
                    Iout=max(Iout,Voxel_data);
                end
            end
        
        end


        function [Dxx, Dyy, Dzz, Dxy, Dxz, Dyz] = Hessian3D(app, Volume,Sigma)
            %  This function Hessian3D filters the image with an Gaussian kernel
            %  followed by calculation of 2nd order gradients, which aprroximates the
            %  2nd order derivatives of the image.
            % 
            % [Dxx, Dyy, Dzz, Dxy, Dxz, Dyz] = Hessian3D(I,Sigma)
            % 
            % inputs,
            %   I : The image volume, class preferable double or single
            %   Sigma : The sigma of the gaussian kernel used. If sigma is zero
            %           no gaussian filtering.
            %
            % outputs,
            %   Dxx, Dyy, Dzz, Dxy, Dxz, Dyz: The 2nd derivatives
            %
            % Function is written by D.Kroon University of Twente (June 2009)
            % defaults
            if nargin < 2, Sigma = 1; end
            
            if(Sigma>0)
                F=imgaussian(app, Volume,Sigma);
            else
                F=Volume;
            end
            
            % Create first and second order diferentiations
            Dz=gradient3(app, F,'z');
            Dzz=(gradient3(app, Dz,'z'));
            clear Dz;
            
            Dy=gradient3(app, F,'y');
            Dyy=(gradient3(app, Dy,'y'));
            Dyz=(gradient3(app, Dy,'z'));
            clear Dy;
            
            Dx=gradient3(app, F,'x');
            Dxx=(gradient3(app, Dx,'x'));
            Dxy=(gradient3(app, Dx,'y'));
            Dxz=(gradient3(app, Dx,'z'));
            clear Dx;
            end
            

            function D = gradient3(app, F,option)
            % This function does the same as the default matlab "gradient" function
            % but with one direction at the time, less cpu and less memory usage.
            %
            % Example:
            %
            % Fx = gradient3(F,'x');
            
            [k,l,m] = size(F);
            D  = zeros(size(F),class(F)); 
            
            switch lower(option)
            case 'x'
                % Take forward differences on left and right edges
                D(1,:,:) = (F(2,:,:) - F(1,:,:));
                D(k,:,:) = (F(k,:,:) - F(k-1,:,:));
                % Take centered differences on interior points
                D(2:k-1,:,:) = (F(3:k,:,:)-F(1:k-2,:,:))/2;
            case 'y'
                D(:,1,:) = (F(:,2,:) - F(:,1,:));
                D(:,l,:) = (F(:,l,:) - F(:,l-1,:));
                D(:,2:l-1,:) = (F(:,3:l,:)-F(:,1:l-2,:))/2;
            case 'z'
                D(:,:,1) = (F(:,:,2) - F(:,:,1));
                D(:,:,m) = (F(:,:,m) - F(:,:,m-1));
                D(:,:,2:m-1) = (F(:,:,3:m)-F(:,:,1:m-2))/2;
            otherwise
                disp('Unknown option')
            end
                
        end
        
        function I=imgaussian(app, I,sigma,siz)
            % IMGAUSSIAN filters an 1D, 2D color/greyscale or 3D image with an 
            % Gaussian filter. This function uses for filtering IMFILTER or if 
            % compiled the fast  mex code imgaussian.c . Instead of using a 
            % multidimensional gaussian kernel, it uses the fact that a Gaussian 
            % filter can be separated in 1D gaussian kernels.
            %
            % J=IMGAUSSIAN(I,SIGMA,SIZE)
            %
            % inputs,
            %   I: The 1D, 2D greyscale/color, or 3D input image with 
            %           data type Single or Double
            %   SIGMA: The sigma used for the Gaussian kernel
            %   SIZE: Kernel size (single value) (default: sigma*6)
            % 
            % outputs,
            %   J: The gaussian filtered image
            %
            % note, compile the code with: mex imgaussian.c -v
            %
            % example,
            %   I = im2double(imread('peppers.png'));
            %   figure, imshow(imgaussian(I,10));
            % 
            % Function is written by D.Kroon University of Twente (September 2009)
            
            if(~exist('siz','var')), siz=sigma*6; end
            
            if(sigma>0)
                % Make 1D Gaussian kernel
                x=-ceil(siz/2):ceil(siz/2);
                H = exp(-(x.^2/(2*sigma^2)));
                H = H/sum(H(:));
            
                % Filter each dimension with the 1D Gaussian kernels\
                if(ndims(I)==1)
                    I=imfilter(I,H, 'same' ,'replicate');
                elseif(ndims(I)==2)
                    Hx=reshape(H,[length(H) 1]);
                    Hy=reshape(H,[1 length(H)]);
                    I=imfilter(imfilter(I,Hx, 'same' ,'replicate'),Hy, 'same' ,'replicate');
                elseif(ndims(I)==3)
                    if(size(I,3)<4) % Detect if 3D or color image
                        Hx=reshape(H,[length(H) 1]);
                        Hy=reshape(H,[1 length(H)]);
                        for k=1:size(I,3)
                            I(:,:,k)=imfilter(imfilter(I(:,:,k),Hx, 'same' ,'replicate'),Hy, 'same' ,'replicate');
                        end
                    else
                        Hx=reshape(H,[length(H) 1 1]);
                        Hy=reshape(H,[1 length(H) 1]);
                        Hz=reshape(H,[1 1 length(H)]);
                        I=imfilter(imfilter(imfilter(I,Hx, 'same' ,'replicate'),Hy, 'same' ,'replicate'),Hz, 'same' ,'replicate');
                    end
                else
                    error('imgaussian:input','unsupported input dimension');
                end
            end
        
        end
        
        
        function I_lumen_ave = Lumen_ave_intensity(app, image, centerline)
            
            I_lumen(1) = 320;
            counter = 1;
            for idx = 1:size(centerline,1)
                CL_x = centerline(idx,1);
                CL_y = centerline(idx,2);
                CL_z = centerline(idx,3);
                
                for dist_x = -5:5
                    for dist_y = -5:5
                        for dist_z = -5:5
                        
                            if image(CL_x+dist_x, CL_y+dist_y, CL_z+dist_z) >= 150
        
                                I_lumen(counter) = image(CL_x, CL_y, CL_z);
                                counter = counter + 1;
        
                            end
                            
                        end
                    end
                end
                
            end
            
            I_lumen_ave = mean(I_lumen);
            
            clear I_lumen;
            
        end
        
        
        function g_bell = bell_function(app, I, a, b, c)
                
            g_bell = 1./(1 + abs( (I-c)./a ).^2*b );
        
        end
        
        function g_sigmoid = sigm_function(app, I, a, b)
            
            g_sigmoid = 1./(1 + exp( -a.*(I - b) ) );
        
        end
        
        function f_lumen = Lumen_membership_function(app, I, a, epsilon, I_ave, l_thres, cp_thres)
        
            if(~exist('a','var')) 
                a = 0.02; 
            end
            
            if(~exist('epsilon','var')) 
                epsilon = 0.05; 
            end
            
            b = min(max(I_ave - l_thres, 150), 500) - 0.01;
            c = I_ave + cp_thres;
            
            f_lumen = (1 - epsilon).*bell_function(app, I, a, b, c) + epsilon;
            
        
        end
        
        function f_outer = OuterWall_membership_function(app, I, a, epsilon, I_ave, l_thres, ncp_thres)
        
            if(~exist('a','var')) 
                a = 0.02; 
            end
            
            if(~exist('epsilon','var')) 
                epsilon = 0.05; 
            end
            
            if(~exist('ncp_thres','var')) 
                ncp_thres = 50; 
            end
            
            b = min(max(I_ave - l_thres - ncp_thres, 100), 200);
            
            f_outer = (1 - epsilon).*sigm_function(app, I, a, b) + epsilon;
            
        
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            d=get(0,'screensize')
            app.UIFigure.Position = [1 1 d(3) d(2)];
            drawnow;
            
            app.UIFigure.WindowState = 'maximized';
            
            pause(1)
            drawnow;
            
            app.UITablePoints.Position = [0.92*d(3) 0.64*d(4) 0.07*d(3) 0.25*d(4)];
            drawnow;
%             app.AllViewsPanel.Parent = app.UIFigure;
%             
            app.SavePointsButton.Position = [0.94*d(3) 0.60*d(4) 0.04*d(3) 0.03*d(4)];
            drawnow;
            
            app.LoadSVpathButton.Position = [0.935*d(3) 0.56*d(4) 0.05*d(3) 0.03*d(4)];
            drawnow;
            
            app.QuantifyButton.Position = [0.935*d(3) 0.52*d(4) 0.05*d(3) 0.03*d(4)];
            drawnow;
            
            app.ShowCLButton.Position = [0.935*d(3) 0.48*d(4) 0.05*d(3) 0.03*d(4)];
            drawnow;
            
            app.TranslateCLCheckBox.Position = [0.935*d(3) 0.44*d(4) 0.05*d(3) 0.03*d(4)];
            drawnow;
            
            app.BifurcationPointLabel.Position = [0.935*d(3) 0.40*d(4) 0.05*d(3) 0.03*d(4)];
            drawnow;
            
            app.XEditField.Position = [0.935*d(3) 0.36*d(4) 0.05*d(3) 0.03*d(4)];
            drawnow;
            app.XEditFieldLabel.Position = [0.88*d(3) 0.36*d(4) 0.05*d(3) 0.03*d(4)];
            drawnow;
            
            app.YEditField.Position = [0.935*d(3) 0.32*d(4) 0.05*d(3) 0.03*d(4)];
            drawnow;
            app.YEditFieldLabel.Position = [0.88*d(3) 0.32*d(4) 0.05*d(3) 0.03*d(4)];
            drawnow;
            
            app.ZEditField.Position = [0.935*d(3) 0.28*d(4) 0.05*d(3) 0.03*d(4)];
            drawnow;
            app.ZEditFieldLabel.Position = [0.88*d(3) 0.28*d(4) 0.05*d(3) 0.03*d(4)];
            drawnow;
            
            app.ExportSlicesButton.Position = [0.935*d(3) 0.24*d(4) 0.05*d(3) 0.03*d(4)];
            drawnow;
            
            app.LevelsetButton.Position = [0.935*d(3) 0.20*d(4) 0.05*d(3) 0.03*d(4)];
            
            drawnow;
            
            app.VVLevelsetButton.Position = [0.935*d(3) 0.16*d(4) 0.05*d(3) 0.03*d(4)];
            drawnow;
            
            app.VVFLevelsetButton.Position = [0.935*d(3) 0.12*d(4) 0.05*d(3) 0.03*d(4)];
            drawnow;
            
            app.LoadrawpathButton.Position = [0.935*d(3) 0.08*d(4) 0.05*d(3) 0.03*d(4)];
            drawnow;
            
            app.AllViewsPanel.Position = [d(3)/4 d(2) 2*d(3)/3 0.9*d(4)];
            app.AllViewsPanel.Visible = true;
            pause(0.1);
            
            app.UIAxialAxis = axes('Parent',app.AllViewsPanel, 'Position',[0.05 .55 0.40 0.40],...
                'Tag', 'AxialAxis');
            app.UISagittalAxis = axes('Parent',app.AllViewsPanel, 'Position',[0.5 .55 0.40 0.40],...
                'Tag', 'SagittalAxis');
            app.UICoronalAxis = axes('Parent',app.AllViewsPanel, 'Position',[0.05 .05 0.40 0.40],...
                'Tag', 'CoronalAxis');
            app.UI3DPlanesAxis = axes('Parent',app.AllViewsPanel, 'Position',[0.5 .05 0.40 0.40]);


            app.crosshair_enable = false;
            app.h_crosshair_exist = 0;
            
            app.point_ID = 0;
            

        end

        % Button pushed function: SelectdirectoryButton
        function SelectdirectoryButtonPushed(app, event)
            
            app.StatusLamp.Color = [1 0 0];
            app.LampLabel.Text = "Importing DICOM dataset ...";
            
            app.UIFigure.Visible = 'off';
            % Open dialogue box to select directory
            dcm_dir = uigetdir;
            
            app.UIFigure.Visible = 'on';
            
            % Create DICOM metadata collection
            app.UIFigure.Visible = 'off';
            dcm_metadata = dicomCollection(dcm_dir);
            app.UIFigure.Visible = 'on';
            % Add select column to table
            SelectSeries(1:size(dcm_metadata,1),1) = false;
            T1 = table(SelectSeries);
            data = [T1 dcm_metadata];

            
            % Show the metadata info in the table
            app.UIdicomCollectionTable.Data = data;
            
            % Make the first column editable
            editable_column(1) = true;
            editable_column(2:size(data,2)) = false;
            
            app.UIdicomCollectionTable.ColumnName = [data.Properties.VariableNames];
            app.UIdicomCollectionTable.RowName = dcm_metadata.SeriesDescription;
            
            app.UIdicomCollectionTable.ColumnEditable = editable_column;
            
            app.StatusLamp.Color = [0 1 0];
            app.LampLabel.Text = "Importing done!";
            
        end

        % Cell edit callback: UIdicomCollectionTable
        function UIdicomCollectionTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            data = app.UIdicomCollectionTable.Data;
            
            SelectSeries(1:size(data,1),1) = false;
            SelectSeries(indices(1)) = true;
            
            T1 = table(SelectSeries);
            
            data(:,1) = T1;
            app.UIdicomCollectionTable.Data = data;
            
        end

        % Button pushed function: LoadSelectedSeriesButton
        function LoadSelectedSeriesButtonPushed(app, event)
        
            app.StatusLamp.Color = [1 0 0];
            app.LampLabel.Text = "Loading the images ...";
            
            data = app.UIdicomCollectionTable.Data;
            checkboxes = data.SelectSeries;
            % Read the dicom series volume
            app.dcm_series_volume = squeeze(dicomreadVolume(data, strcat('s',int2str(find(checkboxes)))) );
            
            % Sort dicom files based on InstanceNumber in dicom headers
            app.dcm_series_Filenames = data.Filenames{find(checkboxes),1};
            app.sorted_filenames = sort_metadata(app, app.dcm_series_Filenames);
            
            % Get the .RescaleSlope and .Intercept from first dicom file
            dcm_info = dicominfo(app.sorted_filenames(1));
            % Convert the dicom series to Hounsfield Unit series
            app.dcm_series_volume_HU = double(app.dcm_series_volume)*dcm_info.RescaleSlope+dcm_info.RescaleIntercept;
            
%             for column = 1:size(app.dcm_series_volume_HU,1)
%                 for row = 1:size(app.dcm_series_volume_HU,2)
%                     for ax = 1:size(app.dcm_series_volume_HU,3)
%                         if app.dcm_series_volume_HU(column, row, ax)>599
%                             app.dcm_series_volume_HU_enhcd(column, row, ax) = 800;
%                         else
%                             app.dcm_series_volume_HU_enhcd(column, row, ax) = app.dcm_series_volume_HU(column, row, ax);
%                         end
%                     end
%                 end
%             end

            app.dcm_series_volume_HU_enhcd = app.dcm_series_volume_HU;
            app.dcm_series_volume_HU_enhcd(app.dcm_series_volume_HU_enhcd>599) = 800;
            
            % Scale the dcm Volume to actual size
            
            Pixel_spacing = dcm_info.PixelSpacing;
            app.delta_x = Pixel_spacing(1);                             % Space between two Sagittal slices
            app.delta_y = Pixel_spacing(2);                             % Space between two Coronal slices
            app.delta_z = dcm_info.SliceThickness;                      % Space between two Axial slices
%             app.patientPosition = dcm_info.ImagePositionPatient;        % Real coordinates of the upper left hand corner (center of the first voxel transmitted) of the image, in mm.
%             app.patientOrientation = dcm_info.ImageOrientationPatient;  % The direction cosines of the first row and the first column with respect to the patient
            
            app.dcm_series_volume_HU_scaled = imresize3(app.dcm_series_volume_HU, 'scale', [app.delta_x app.delta_y app.delta_z]);
            app.dcm_series_volume_HU_enhcd_scaled = imresize3(app.dcm_series_volume_HU_enhcd, 'scale', [app.delta_x app.delta_y app.delta_z]);
            
            app.dcm_series_volume_HU_scaled = imdiffusefilt(app.dcm_series_volume_HU_scaled, 'NumberOfIterations',3);
            
            app.volume_dim =  size(app.dcm_series_volume_HU_scaled);
            
            app.AxialSlider.Limits = [1 app.volume_dim(3)];
            app.SagittalSlider.Limits = [1 app.volume_dim(1)];
            app.CoronalSlider.Limits = [1 app.volume_dim(2)];
            
            % Create volume mesh            
            [app.X app.Y app.Z] = meshgrid( (1:app.volume_dim(1)), (1:app.volume_dim(2)), (1:app.volume_dim(3)) );


            WL = round(app.WLEditField.Value);
            WW = round(app.WWEditField.Value);
            [app.lower_level, app.upper_level] = set_WW_WL(app, WW, WL);
            
            
            % Updating all the views
            update_Planes_3D_view(app, app.volume_dim(3)/2, app.volume_dim(1)/2, app.volume_dim(2)/2)
            
            update_axial_view(app, app.volume_dim(3)/2)
            
            xLim_axial_view = app.UIAxialAxis.XLim;
            yLim_axial_view = app.UIAxialAxis.YLim;
            
            update_sagittal_view(app, app.volume_dim(1)/2)
            
            update_coronal_view(app, app.volume_dim(2)/2)
            
            % Updating the sliders
            app.AxialSlider.Value = app.volume_dim(3)/2;
            app.AxialLabel.Text = num2str(app.AxialSlider.Value,'% 10.1f');
            
            app.SagittalSlider.Value = app.volume_dim(1)/2;
            app.SagittalLabel.Text = num2str(app.SagittalSlider.Value,'% 10.1f');
            
            app.CoronalSlider.Value = app.volume_dim(2)/2;
            app.CoronalLabel.Text = num2str(app.CoronalSlider.Value,'% 10.1f');
            
            update_image_limits(app)
            update_plane_location(app)
            
            app.h_crosshair_exist = 0;
            
            app.crosshair_enable = true;
            
            app.StatusLamp.Color = [0 1 0];
            app.LampLabel.Text = "Loading images done!";
            
        end

        % Button pushed function: SetWLWWButton
        function SetWLWWButtonPushed(app, event)
            WL = round(app.WLEditField.Value);
            WW = round(app.WWEditField.Value);
            
            [app.lower_level, app.upper_level] = set_WW_WL(app, WW, WL);
        end

        % Value changed function: AxialSlider
        function AxialSliderValueChanged(app, event)
            value = app.AxialSlider.Value;
            
            event.Source.Value = value;
            app.AxialLabel.Text = num2str(value,'% 10.1f');
            
            update_axial_view(app, round(value,1))
            update_Planes_3D_view(app, round(value,1), round(app.SagittalSlider.Value, 1), round(app.CoronalSlider.Value, 1))
            
            update_image_limits(app)
            update_plane_location(app)
            
        end

        % Value changed function: SagittalSlider
        function SagittalSliderValueChanged(app, event)
            value = app.SagittalSlider.Value;
            
            event.Source.Value = value;
            app.SagittalLabel.Text = num2str(value,'% 10.1f');
            
            update_sagittal_view(app, round(value,1))
            update_Planes_3D_view(app, round(app.AxialSlider.Value, 1), round(value,1), round(app.CoronalSlider.Value, 1))
            
            update_image_limits(app)
            update_plane_location(app)
            
        end

        % Value changed function: CoronalSlider
        function CoronalSliderValueChanged(app, event)
            value = app.CoronalSlider.Value;
            
            event.Source.Value = value;
            app.CoronalLabel.Text = num2str(value,'% 10.1f');
            
            update_coronal_view(app, round(value,1))
            update_Planes_3D_view(app, round(app.AxialSlider.Value, 1), round(app.SagittalSlider.Value, 1), round(value, 1))
            
            update_image_limits(app)
            update_plane_location(app)
            
        end

        % Value changing function: AxialSlider
        function AxialSliderValueChanging(app, event)
            changingValue = event.Value;
            
            event.Source.Value = changingValue;
            app.AxialLabel.Text = num2str(changingValue,'% 10.1f');
            
            update_axial_view(app, round(changingValue,1))
            update_Planes_3D_view(app, round(changingValue,1), round(app.SagittalSlider.Value, 1), round(app.CoronalSlider.Value, 1))
            
            update_image_limits(app)
            update_plane_location(app)
            
            
        end

        % Value changing function: SagittalSlider
        function SagittalSliderValueChanging(app, event)
            changingValue = event.Value;
            
            event.Source.Value = changingValue;
            app.SagittalLabel.Text = num2str(changingValue,'% 10.1f');
            
            update_sagittal_view(app, round(changingValue,1))
            update_Planes_3D_view(app, round(app.AxialSlider.Value, 1), round(changingValue,1), round(app.CoronalSlider.Value, 1))
            
            update_image_limits(app)
            update_plane_location(app)
            
            
        end

        % Value changing function: CoronalSlider
        function CoronalSliderValueChanging(app, event)
            changingValue = event.Value;
            
            event.Source.Value = changingValue;
            app.CoronalLabel.Text = num2str(changingValue,'% 10.1f');
            
            update_coronal_view(app, round(changingValue,1))
            update_Planes_3D_view(app, round(app.AxialSlider.Value, 1), round(app.SagittalSlider.Value, 1), round(changingValue, 1))
            
            update_image_limits(app)
            update_plane_location(app)
            
        end

        % Button pushed function: AddPointsButton
        function AddPointsButtonPushed(app, event)
%             set (app.UIFigure, 'WindowButtonMotionFcn', @activate_crosshair);

%             set(app.UIAxialAxis, 'ButtonDownFcn', @activate_crosshair)

%             app.crosshair_enable = true;

            add_point(app, app.AxialSlider.Value, app.SagittalSlider.Value, app.CoronalSlider.Value)


        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
%             create_centerline(app, CL_points)

%             size(app.CL_points.(sprintf('point_%d', app.point_ID)))
            a=size(app.CL_points)
            b = app.point_ID
            
            for i = 1:app.point_ID
                app.centerline(i,:,:,:) = app.CL_points.(sprintf('point_%d', i));
            end
            
            my_CL = app.centerline
            
            create_centerline(app, app.centerline)
            
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
        
            center = app.centerline(3,:,:)
            
            my_norm = (app.centerline(4,:,:) - app.centerline(3,:,:))./norm(app.centerline(4,:,:) - app.centerline(3,:,:))
        
%             [slice, sliceInd, subX, subY, subZ] = extractSlice(app, app.dcm_series_volume_HU_scaled,center(1),center(2),center(3),my_norm(1),my_norm(2),my_norm(3), 10)
%             surf(subX,subY,subZ,slice,'FaceColor','texturemap','EdgeColor','none');
%             colormap(gray)
            oblique_slice(app, app.dcm_series_volume_HU_scaled, my_norm, center)
            
            view(2)
        end

        % Button pushed function: SavePointsButton
        function SavePointsButtonPushed(app, event)
        
            FileName = uiputfile('*.xls','Save as');
        
            CL_points_save = app.UITablePoints.Data;
            column_name = app.UITablePoints.ColumnName;
            row_name = app.UITablePoints.RowName;
            
            NewData = mat2cell(CL_points_save, ones(size(CL_points_save, 1),1), ones(size(CL_points_save, 2),1), ones(size(CL_points_save, 3),1))
            CombData=[column_name'; NewData]
            writecell(CombData, FileName);
        
        end

        % Button pushed function: LoadSVpathButton
        function LoadSVpathButtonPushed(app, event)
        
            app.StatusLamp.Color = [1 0 0];
            app.LampLabel.Text = "Loading .pth file ...";
            
            app.UIFigure.Visible = 'off';
            % Open dialogue box to select SV path file
            [file,path] = uigetfile('*.pth');
            
            app.UIFigure.Visible = 'on';
            
            % Create DICOM metadata collection
            app.UIFigure.Visible = 'off';
            file_name_full = fullfile( path, file );
            app.UIFigure.Visible = 'on';
            
            [app.centerline, app.tangent_vector, rotation_vectors] = read_SV_centerline(app, file_name_full);
%             app.centerline
            
            X_bif = str2double(app.XEditField.Value);
            Y_bif = str2double(app.YEditField.Value);
            Z_bif = str2double(app.ZEditField.Value);


            if (X_bif==0) && (Y_bif==0) && (Z_bif ==0)
                app.centerline_interp = interp3D_curve(app, app.centerline, 100, 0.25);
                app.tangents_updated = update_tangents(app, app.centerline_interp);
            else
                
                if app.TranslateCLCheckBox.Value == 1
                    file_name = app.sorted_filenames(end);              % Get the last filename in dcm series
                    dcm_info = dicominfo(file_name);
                    patientPosition = dcm_info.ImagePositionPatient;
                    X_bif = X_bif + patientPosition(1);
                    Y_bif = Y_bif + patientPosition(2);
                    Z_bif = Z_bif - patientPosition(3);
                end
                
                [app.centerline_interp app.bif_index] = split_CL_at_bifurcation(app, app.centerline, [X_bif Y_bif Z_bif]);
                app.tangents_updated = update_tangents(app, app.centerline_interp);
                assignin('base','bif_idx_SV',app.bif_index);
            end
            
            app.rotation_vectors_updated = find_rotation_vector(app, app.centerline, app.centerline_interp, rotation_vectors);


%             app.centerline_interp = interp3D_curve(app, app.centerline, 100, 0.5);
%             app.tangents_updated = update_tangents(app, app.centerline_interp);
            
            hold(app.UI3DPlanesAxis, 'on')
%             plot3(app.UI3DPlanesAxis, app.centerline_interp(:,1), app.centerline_interp(:,2), app.centerline_interp(:,3),'.r-')
            plot3(app.UI3DPlanesAxis, app.centerline(:,1), app.centerline(:,2), app.centerline(:,3),'.r-')
            hold(app.UI3DPlanesAxis, 'off')
            
            app.LampLabel.Text = "Creating cross-sectional images ...";
            
            for idx = 1:size(app.centerline_interp,1)
                
                app.LampLabel.Text = sprintf("Segmentation is in progress (%d%%)...", round(idx/size(app.centerline_interp,1)*100));
                drawnow;
                % Get 2D slices
%                 [app.slice_2D.(sprintf('idx_%d', idx)), app.slice_3D.(sprintf('idx_%d', idx))] = Extract_Oblique_Slice(app, app.dcm_series_volume_HU, app.centerline_interp(idx,:), app.tangents_updated(idx,:), 50, 0);
                [app.slice_2D.(sprintf('idx_%d', idx)), app.slice_3D.(sprintf('idx_%d', idx))] = Extract_Oblique_Slice(app, app.dcm_series_volume_HU_enhcd, app.centerline_interp(idx,:), app.tangents_updated(idx,:), 50, 0);
%                 [app.slice_2D.(sprintf('idx_%d', idx)), app.slice_3D.(sprintf('idx_%d', idx))] = Extract_Oblique_Slice(app, app.dcm_series_volume_HU, app.centerline(idx,:), app.tangent_vector(idx,:), 50, 0);                
                
                
%                 [app.slice_2D.(sprintf('idx_%d', idx)), app.slice_3D.(sprintf('idx_%d', idx))] = Extract_Oblique_Slice(app, double(app.dcm_series_volume), app.centerline(idx,:), app.tangent_vector(idx,:), 50, 0);
%                 app.slice_2D
                % Create a box for arterial cross-sectional veiw
                box_x = (round(size(app.slice_2D.(sprintf('idx_%d', idx)),1)/2) - 30):1:(round(size(app.slice_2D.(sprintf('idx_%d', idx)),1)/2) + 30);
                box_y = (round(size(app.slice_2D.(sprintf('idx_%d', idx)),2)/2) - 30):1:(round(size(app.slice_2D.(sprintf('idx_%d', idx)),2)/2) + 30);
                % Save the arterial cross-sectional veiw
                dummy_slice = app.slice_2D.(sprintf('idx_%d', idx));
                app.slice_2D_zoomed.(sprintf('idx_%d', idx)) = dummy_slice(box_x,box_y);
                % Build the curved MPR (straightened) 3D image
                Straightened_curved_MPR(:,:,idx) = app.slice_2D_zoomed.(sprintf('idx_%d', idx));
            end
            
            app.LampLabel.Text = "Cross-sectional images are created.";
            app.StatusLamp.Color = [0 1 0];
            drawnow;
            
        end

        % Button pushed function: QuantifyButton
        function QuantifyButtonPushed(app, event)
            
            app.flag_imported_centerline = true;
            image_database = create_transferrable_database(app);
            
            app.QuantifyWindow = my_app(app, image_database, app.flag_imported_centerline);
            
        end

        % Value changed function: ShowCLButton
        function ShowCLButtonValueChanged(app, event)
            value = app.ShowCLButton.Value;
            
                        app.UIFigure.Visible = 'off';
            % Open dialogue box to select SV path file
            [file,path] = uigetfile('*.pth');
            
            app.UIFigure.Visible = 'on';
            
            % Create DICOM metadata collection
            app.UIFigure.Visible = 'off';
            file_name_full = fullfile( path, file );
            app.UIFigure.Visible = 'on';
            
            [app.centerline, app.tangent_vector] = read_SV_centerline(app, file_name_full);
%             app.centerline
            
            X_bif = str2double(app.XEditField.Value);
            Y_bif = str2double(app.YEditField.Value);
            Z_bif = str2double(app.ZEditField.Value);


            if (X_bif==0) && (Y_bif==0) && (Z_bif ==0)
                app.centerline_interp = interp3D_curve(app, app.centerline, 100, 0.25);
                app.tangents_updated = update_tangents(app, app.centerline_interp);
            else
                
                if app.TranslateCLCheckBox.Value == 1
                    file_name = app.sorted_filenames(end);              % Get the last filename in dcm series
                    dcm_info = dicominfo(file_name);
                    patientPosition = dcm_info.ImagePositionPatient;
                    X_bif = X_bif + patientPosition(1);
                    Y_bif = Y_bif + patientPosition(2);
                    Z_bif = Z_bif - patientPosition(3);
                end
                
                [app.centerline_interp app.bif_index] = split_CL_at_bifurcation(app, app.centerline, [X_bif Y_bif Z_bif]);
                app.tangents_updated = update_tangents(app, app.centerline_interp);
            end


%             app.centerline_interp = interp3D_curve(app, app.centerline, 100, 0.5);
%             app.tangents_updated = update_tangents(app, app.centerline_interp);
            
            hold(app.UI3DPlanesAxis, 'on')
%             plot3(app.UI3DPlanesAxis, app.centerline_interp(:,1), app.centerline_interp(:,2), app.centerline_interp(:,3),'.r-')
            plot3(app.UI3DPlanesAxis, app.centerline(:,1), app.centerline(:,2), app.centerline(:,3),'.r-')
            hold(app.UI3DPlanesAxis, 'off')


        end

        % Button pushed function: ExportSlicesButton
        function ExportSlicesButtonPushed(app, event)
            
        
            app.StatusLamp.Color = [1 0 0];
            app.LampLabel.Text = "Exporting slices ...";
            drawnow;
            
            app.UIFigure.Visible = 'off';
            % Open dialogue box to select SV path file
            target_dir = uigetdir;
            
            app.UIFigure.Visible = 'on';
            
            Export_dicom_series(app, target_dir)
            
            app.StatusLamp.Color = [0 1 0];
            app.LampLabel.Text = "Dicom files were exported.";
            drawnow;
        
        
        end

        % Button pushed function: LevelsetButton
        function LevelsetButtonPushed(app, event)
            
            app.StatusLamp.Color = [1 0 0];
            app.LampLabel.Text = "Levelset is in process ...";
            drawnow;
            
%             tangents_aug = update_tangents_aug(app, app.tangents_updated);
            
            app.reduced_dataset = ones(size(app.dcm_series_volume_HU,1), size(app.dcm_series_volume_HU,2), size(app.dcm_series_volume_HU,3)).*(-1000);
            
            center_point = app.centerline_interp;

%             [CL_splitted bif_index] = split_CL_at_bifurcation(center_point, bif_point);
            
%             center_point = CL_splitted + [patientPosition(1) patientPosition(2) -patientPosition(3)];
            
            center_point_idx = round(center_point./[app.delta_x app.delta_y app.delta_z]);
            
            % aa = slice(hounsfield_dataset, size(hounsfield_dataset,1)/2, size(hounsfield_dataset,2)/2, size(hounsfield_dataset,3)/2);
            % set(aa, 'edgecolor', 'none')
            % colormap(gray)
            % 
            % hold on
            % plot3(center_point_idx(:,1), center_point_idx(:,2), center_point_idx(:,3),'.r-')
            % hold off
            
            previous_slice = center_point_idx(1,3);
            
            for idx = 1:size(center_point_idx,1)
%             for idx = 1:app.bif_index
                
                fprintf('Segmenting point %d out of %d ...\n', idx, size(center_point_idx,1))
                
                current_slice = center_point_idx(idx,3);
                
                if (current_slice == previous_slice) && (idx ~= 1)
                    continue
                end
                
                slice_tmp = app.dcm_series_volume_HU(:,:,center_point_idx(idx,3));
            %     slice_tmp_inf = hounsfield_dataset(:,:,center_point_idx(idx,3) - 1);
            %     slice_tmp_sup = hounsfield_dataset(:,:,center_point_idx(idx,3) + 1);
                img = Sigmoid_function(app, slice_tmp, 0.1, 50);
                ROI_mask = init_levelset(app, img, center_point_idx(idx,1:2), 3);
            %     slice_tmp(~ROI_mask) = 0;
                for xx_idx = 1:size(app.reduced_dataset,1)
                    for yy_idx = 1:size(app.reduced_dataset,2)
            
                        if ROI_mask(yy_idx,xx_idx) == 1
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx(idx,3)-3) = app.dcm_series_volume_HU(yy_idx,xx_idx,center_point_idx(idx,3)-3);
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx(idx,3)-2) = app.dcm_series_volume_HU(yy_idx,xx_idx,center_point_idx(idx,3)-2);
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx(idx,3)-1) = app.dcm_series_volume_HU(yy_idx,xx_idx,center_point_idx(idx,3)-1);
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx(idx,3)) = app.dcm_series_volume_HU(yy_idx,xx_idx,center_point_idx(idx,3));
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx(idx,3)+1) = app.dcm_series_volume_HU(yy_idx,xx_idx,center_point_idx(idx,3)+1);
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx(idx,3)+2) = app.dcm_series_volume_HU(yy_idx,xx_idx,center_point_idx(idx,3)+2);
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx(idx,3)+3) = app.dcm_series_volume_HU(yy_idx,xx_idx,center_point_idx(idx,3)+3);
                        end
                    end
                end
                
                previous_slice = current_slice;
                
            end


            mins = min(center_point_idx);
            maxs = max(center_point_idx);
            
            [my_box_x my_box_y my_box_z] = meshgrid((mins(1)-10):0.25:(maxs(1)+10),(mins(2)-10):0.25:(maxs(2)+10), (mins(3)-10):0.25:(maxs(3)+10));
            reduced_dataset_cropped = interp3(app.reduced_dataset, my_box_x, my_box_y, my_box_z, 'makima', 0);
            
            center_point_idx5 = center_point./[app.delta_x app.delta_y app.delta_z];
            center_point_idx6 = center_point_idx5 - [mins(1)-10 mins(2)-10 mins(3)-10];
            center_point_idx7 = center_point_idx6./[0.25 0.25 0.25];
            
            % Updating the 3D figure
            Planes_3D = slice(app.UI3DPlanesAxis, reduced_dataset_cropped, size(reduced_dataset_cropped,1)/2, size(reduced_dataset_cropped,2)/2, size(reduced_dataset_cropped,3)/2);
            set(Planes_3D, 'edgecolor', 'none')
            set(app.UI3DPlanesAxis,'Colormap', gray)
            set(app.UI3DPlanesAxis, 'CLim', [app.lower_level app.upper_level]);
            app.UI3DPlanesAxis.Color = 'none';
            axis(app.UI3DPlanesAxis, 'off');
            text( 1, 3, '3D', 'color', 'm', 'FontSize', 10, 'Parent', app.UI3DPlanesAxis);
            
            hold(app.UI3DPlanesAxis, 'on')
%             plot3(app.UI3DPlanesAxis, app.centerline_interp(:,1), app.centerline_interp(:,2), app.centerline_interp(:,3),'.r-')
            plot3(app.UI3DPlanesAxis, center_point_idx7(:,1), center_point_idx7(:,2), center_point_idx7(:,3),'.r-')
            hold(app.UI3DPlanesAxis, 'off')
            
            drawnow;
%             update_Planes_3D_view(app, app.volume_dim(3)/2, app.volume_dim(1)/2, app.volume_dim(2)/2)
%             
%             update_axial_view(app, app.volume_dim(3)/2)
%             
%             xLim_axial_view = app.UIAxialAxis.XLim;
%             yLim_axial_view = app.UIAxialAxis.YLim;
%             
%             update_sagittal_view(app, app.volume_dim(1)/2)
%             
%             update_coronal_view(app, app.volume_dim(2)/2)
            
            
            % Creating cross-sectional slices
            
            app.LampLabel.Text = "Creating cross-sectional images ...";
            drawnow;
            
            for idx = 1:size(center_point_idx7,1)
                % Get 2D slices

%                 [app.slice_2D_reduced.(sprintf('idx_%d', idx)), app.slice_3D.(sprintf('idx_%d', idx))] = Extract_Oblique_Slice_idx(app, reduced_dataset_cropped, center_point_idx7(idx,:), app.tangents_updated(idx,:), 100);
                [tmp_slice_2D_reduced, app.slice_3D.(sprintf('idx_%d', idx)), X_final.(sprintf('idx_%d', idx)), Y_final.(sprintf('idx_%d', idx)), Z_final.(sprintf('idx_%d', idx))] = Extract_slice_SV_method(app, reduced_dataset_cropped, center_point_idx7(idx,:), app.tangents_updated(idx,:), app.rotation_vectors_updated(idx,:), 100);
                app.slice_2D_reduced.(sprintf('idx_%d', idx)) = squeeze(tmp_slice_2D_reduced);
                % Create a box for arterial cross-sectional veiw
%                 box_x = (round(size(app.slice_2D_reduced.(sprintf('idx_%d', idx)),1)/2) - 30):1:(round(size(app.slice_2D_reduced.(sprintf('idx_%d', idx)),1)/2) + 30);
%                 box_y = (round(size(app.slice_2D_reduced.(sprintf('idx_%d', idx)),2)/2) - 30):1:(round(size(app.slice_2D_reduced.(sprintf('idx_%d', idx)),2)/2) + 30);
                % Save the arterial cross-sectional veiw
%                 dummy_slice = app.slice_2D_reduced.(sprintf('idx_%d', idx));
%                 app.slice_2D_reduced_zoomed.(sprintf('idx_%d', idx)) = dummy_slice(box_x,box_y);
                % Build the curved MPR (straightened) 3D image
%                 Straightened_curved_MPR(:,:,idx) = app.slice_2D_reduced_zoomed.(sprintf('idx_%d', idx));
            end
            
            app.LampLabel.Text = "Cross-sectional images are created.";
            app.StatusLamp.Color = [0 1 0];
            drawnow;
            
%             imshow(app.slice_2D_reduced.(sprintf('idx_%d', 40)), [])
        
        
        end

        % Button pushed function: VVLevelsetButton
        function VVLevelsetButtonPushed(app, event)
            
            app.StatusLamp.Color = [1 0 0];
            app.LampLabel.Text = "Image enhancement is in progress ...";
            drawnow;
            
            % Multiscale vessel Enhancement filter
            options.BlackWhite=false;
            options.FrangiScaleRange=[1 21];
            options.FrangiC = 100;
            Vfiltered = FrangiFilter3D(app, app.dcm_series_volume_HU,options);
            assignin('base','Vfiltered',Vfiltered);
            
            % Get the pixel intensities from hounsfield image based on the
            % Frangi filter image
            V2 = ones(size(Vfiltered, 1), size(Vfiltered, 2), size(Vfiltered, 3)).*(-1000);
            for ii = 1:size(Vfiltered, 1)
                for jj = 1:size(Vfiltered, 2)
                    for kk = 1:size(Vfiltered, 3)
                        if Vfiltered(ii,jj,kk)>0.01
                            V2(ii, jj, kk) = app.dcm_series_volume_HU(ii, jj, kk);
                        end
                    end
                end
            end


%             mins = min(center_point_idx);
%             maxs = max(center_point_idx);
%             
%             [my_box_x my_box_y my_box_z] = meshgrid((mins(1)-10):0.25:(maxs(1)+10),(mins(2)-10):0.25:(maxs(2)+10), (mins(3)-10):0.25:(maxs(3)+10));
%             reduced_dataset_cropped = interp3(V2, my_box_x, my_box_y, my_box_z, 'makima', 0);
            
            
            % Performing levelset
            center_point = app.centerline_interp;
            center_point_idx = round(center_point./[app.delta_x app.delta_y app.delta_z]);
            
            app.reduced_dataset = ones(size(app.dcm_series_volume_HU,1), size(app.dcm_series_volume_HU,2), size(app.dcm_series_volume_HU,3)).*(-1000);
            previous_slice = center_point_idx(1,3);
            for idx = 1:size(center_point_idx,1)
             
%                 fprintf('Segmenting point %d out of %d ...\n', idx, size(center_point_idx,1))
                app.LampLabel.Text = sprintf("Levelset is in progress (%d%%)...", round(idx/size(center_point_idx,1)*100));
                drawnow;
                
                
                current_slice = center_point_idx(idx,3);
                
                if (current_slice == previous_slice) && (idx ~= 1)
                    continue
                end
                
                slice_tmp = V2(:,:,center_point_idx(idx,3));
                img = Sigmoid_function(app, slice_tmp, 0.1, 20);
                ROI_mask = init_levelset(app, img, center_point_idx(idx,1:2), 3);
                
                for xx_idx = 1:size(app.reduced_dataset,2)
                    for yy_idx = 1:size(app.reduced_dataset,1)
            
                        if ROI_mask(yy_idx,xx_idx) == 1
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx(idx,3)-2) = V2(yy_idx,xx_idx,center_point_idx(idx,3)-2);
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx(idx,3)-1) = V2(yy_idx,xx_idx,center_point_idx(idx,3)-1);
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx(idx,3)) = V2(yy_idx,xx_idx,center_point_idx(idx,3));
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx(idx,3)+1) = V2(yy_idx,xx_idx,center_point_idx(idx,3)+1);
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx(idx,3)+2) = V2(yy_idx,xx_idx,center_point_idx(idx,3)+2);
                        end
                    end
                end
                
                previous_slice = current_slice;
                
            end
            
            % Get the volume of interest around the centerline
            mins = min(center_point_idx);
            maxs = max(center_point_idx);
            
            spacing_fine = 1;
            box_margin = 3;
            
            [my_box_x my_box_y my_box_z] = meshgrid((mins(1)-box_margin):spacing_fine:(maxs(1)+box_margin),(mins(2)-box_margin):spacing_fine:(maxs(2)+box_margin), (mins(3)-box_margin):spacing_fine:(maxs(3)+box_margin));
            reduced_dataset_cropped = interp3(app.reduced_dataset, my_box_x, my_box_y, my_box_z, 'makima', -1000);
            
            
            center_point_idx2 = center_point_idx - [mins(1) mins(2) mins(3)];
            center_point_idx3 = center_point_idx2 + [box_margin box_margin box_margin];
            
            center_point_idx5 = center_point./[app.delta_x app.delta_y app.delta_z];
            center_point_idx6 = center_point_idx5 - [mins(1)-box_margin mins(2)-box_margin mins(3)-box_margin];
            center_point_idx7 = center_point_idx6./[spacing_fine spacing_fine spacing_fine];
            
            % Updating the 3D figure
            Planes_3D = slice(app.UI3DPlanesAxis, reduced_dataset_cropped, size(reduced_dataset_cropped,1)/2, size(reduced_dataset_cropped,2)/2, size(reduced_dataset_cropped,3)/2);
            set(Planes_3D, 'edgecolor', 'none')
            set(app.UI3DPlanesAxis,'Colormap', gray)
            set(app.UI3DPlanesAxis, 'CLim', [app.lower_level app.upper_level]);
            app.UI3DPlanesAxis.Color = 'none';
            axis(app.UI3DPlanesAxis, 'off');
            text( 1, 3, '3D', 'color', 'm', 'FontSize', 10, 'Parent', app.UI3DPlanesAxis);
            
            hold(app.UI3DPlanesAxis, 'on')
            plot3(app.UI3DPlanesAxis, center_point_idx7(:,1), center_point_idx7(:,2), center_point_idx7(:,3),'.r-')
            hold(app.UI3DPlanesAxis, 'off')
            
            drawnow;


            for idx = 1:size(center_point_idx7,1)
                
                app.LampLabel.Text = sprintf("Segmentation is in progress (%d%%)...", round(idx/size(center_point_idx7,1)*100));
                drawnow;
                [tmp_slice_2D_reduced, app.slice_3D.(sprintf('idx_%d', idx)), X_final.(sprintf('idx_%d', idx)), Y_final.(sprintf('idx_%d', idx)), Z_final.(sprintf('idx_%d', idx))] = Extract_slice_vtk_method(app, reduced_dataset_cropped, center_point_idx7(idx,:), app.tangents_updated(idx,:), 20);
                app.slice_2D_reduced.(sprintf('idx_%d', idx)) = tmp_slice_2D_reduced;
                
            end
            
            app.StatusLamp.Color = [0 1 0];
            app.LampLabel.Text = "Segmentation Done!";
            drawnow;
        
        
        end

        % Button pushed function: VVFLevelsetButton
        function VVFLevelsetButtonPushed(app, event)
            
            app.StatusLamp.Color = [1 0 0];
            app.LampLabel.Text = "Image enhancement is in progress ...";
            drawnow;
            
%             app.reduced_dataset = ones(size(app.dcm_series_volume_HU,1), size(app.dcm_series_volume_HU,2), size(app.dcm_series_volume_HU,3)).*(-1000);
            
            img_physical_coord = imresize3(app.dcm_series_volume_HU, 'Scale', [1 1 app.delta_z/app.delta_x]);
            
            app.reduced_dataset = ones(size(img_physical_coord,1), size(img_physical_coord,2), size(img_physical_coord,3)).*(-1000);
            
            center_point = app.centerline_interp;
%             center_point_idx = round(center_point./[app.delta_x app.delta_y app.delta_z]);
            
            center_point_idx_actual = center_point./[app.delta_x app.delta_y app.delta_z];
            center_point_idx_actual2 = center_point_idx_actual.*[1 1 app.delta_z/app.delta_x];
            center_point_idx_actual_round = round(center_point_idx_actual2);
            
            options.BlackWhite=false;
            options.FrangiScaleRange=[1 20];
            Vfiltered = FrangiFilter3D(app, img_physical_coord, options);
            
            
            IV_enhanced = ones(size(Vfiltered, 1), size(Vfiltered, 2), size(Vfiltered, 3)).*(-1000);
            for ii = 1:size(Vfiltered, 1)
                for jj = 1:size(Vfiltered, 2)
                    for kk = 1:size(Vfiltered, 3)
                        if Vfiltered(ii,jj,kk)>0.0
                            IV_enhanced(ii, jj, kk) = img_physical_coord(ii, jj, kk);
                        end
                    end
                end
            end
            
            app.StatusLamp.Color = [1 0 0];
            app.LampLabel.Text = "Applying weight functions ...";
            drawnow;

            I_ave = Lumen_ave_intensity(app, IV_enhanced, center_point_idx_actual_round);
            f_outer = OuterWall_membership_function(app, IV_enhanced, 0.02, 0.05, I_ave, 50, 5);
            
            IV_enhcd_weight_updated = ones(size(f_outer, 1), size(f_outer, 2), size(f_outer, 3)).*(-1000);
            for ii = 1:size(f_outer, 1)
                for jj = 1:size(f_outer, 2)
                    for kk = 1:size(f_outer, 3)
                        if f_outer(ii,jj,kk)>0.1
                            IV_enhcd_weight_updated(ii, jj, kk) = img_physical_coord(ii, jj, kk);
                        end
                    end
                end
            end
            
            
            previous_slice = center_point_idx_actual_round(1,3);

            for idx = 1:size(center_point_idx_actual_round,1)
            % for idx = 1:bif_index
                
%                 fprintf('Segmenting point %d out of %d ...\n', idx, bif_index)
                app.LampLabel.Text = sprintf("Levelset is in progress (%d%%)...", round(idx/size(center_point_idx_actual_round,1)*100));
                drawnow;
                
                current_slice = center_point_idx_actual_round(idx,3);
                
                if (current_slice == previous_slice) && (idx ~= 1)
                    continue
                end
                
                slice_tmp = IV_enhcd_weight_updated(:,:,center_point_idx_actual_round(idx,3));

                img = Sigmoid_function(app, slice_tmp, 0.7, 0.08);
                ROI_mask = init_levelset(app, img, center_point_idx_actual_round(idx,1:2), 3);

                for xx_idx = 1:size(app.reduced_dataset,1)
                    for yy_idx = 1:size(app.reduced_dataset,2)
            
                        if ROI_mask(yy_idx,xx_idx) == 1
            %                 col_range = center_point_idx_actual_round(idx,1)-4:center_point_idx_actual_round(idx,1):center_point_idx_actual_round(idx,1)+4;
            %                 row_range = center_point_idx_actual_round(idx,2)-4:center_point_idx_actual_round(idx,2):center_point_idx_actual_round(idx,2)+4;
            %                 axial_range = center_point_idx_actual_round(idx,3)-4:center_point_idx_actual_round(idx,3):center_point_idx_actual_round(idx,3)+4;
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)-4) = IV_enhcd_weight_updated(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)-4);
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)-3) = IV_enhcd_weight_updated(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)-3);
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)-2) = IV_enhcd_weight_updated(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)-2);
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)-1) = IV_enhcd_weight_updated(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)-1);
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)) = IV_enhcd_weight_updated(yy_idx,xx_idx,center_point_idx_actual_round(idx,3));
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)+1) = IV_enhcd_weight_updated(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)+1);
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)+2) = IV_enhcd_weight_updated(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)+2);
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)+3) = IV_enhcd_weight_updated(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)+3);
                            app.reduced_dataset(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)+4) = IV_enhcd_weight_updated(yy_idx,xx_idx,center_point_idx_actual_round(idx,3)+4);
                            
            %                 reduced_dataset(col_range,row_range,axial_range) = V2(col_range,row_range,axial_range);
                        end
                    end
                end
                
                previous_slice = current_slice;
                
            end
            
            
            mins = min(center_point_idx_actual_round);
            maxs = max(center_point_idx_actual_round);
            
            [my_box_x my_box_y my_box_z] = meshgrid((mins(1)-10):0.25:(maxs(1)+10),(mins(2)-10):0.25:(maxs(2)+10), (mins(3)-10):0.25:(maxs(3)+10));
            reduced_dataset_cropped = interp3(app.reduced_dataset, my_box_x, my_box_y, my_box_z, 'makima', -1000);
            
            original_dataset_cropped = interp3(img_physical_coord, my_box_x, my_box_y, my_box_z, 'makima', -1000);
            
            center_point_idx6 = center_point_idx_actual2 - [mins(1)-10 mins(2)-10 mins(3)-10];
            center_point_idx7 = center_point_idx6./[0.25 0.25 0.25];
            
            
            % Updating the 3D figure
            Planes_3D = slice(app.UI3DPlanesAxis, reduced_dataset_cropped, size(reduced_dataset_cropped,1)/2, size(reduced_dataset_cropped,2)/2, size(reduced_dataset_cropped,3)/2);
            set(Planes_3D, 'edgecolor', 'none')
            set(app.UI3DPlanesAxis,'Colormap', gray)
            set(app.UI3DPlanesAxis, 'CLim', [app.lower_level app.upper_level]);
            app.UI3DPlanesAxis.Color = 'none';
            axis(app.UI3DPlanesAxis, 'off');
            text( 1, 3, '3D', 'color', 'm', 'FontSize', 10, 'Parent', app.UI3DPlanesAxis);
            
            hold(app.UI3DPlanesAxis, 'on')
            plot3(app.UI3DPlanesAxis, center_point_idx7(:,1), center_point_idx7(:,2), center_point_idx7(:,3),'.r-')
            hold(app.UI3DPlanesAxis, 'off')
            
            drawnow;
            
            for idx = 1:size(center_point_idx7,1)
                
                app.LampLabel.Text = sprintf("Segmentation is in progress (%d%%)...", round(idx/size(center_point_idx7,1)*100));
                drawnow;
                [tmp_slice_2D_reduced, app.slice_3D.(sprintf('idx_%d', idx)), X_final.(sprintf('idx_%d', idx)), Y_final.(sprintf('idx_%d', idx)), Z_final.(sprintf('idx_%d', idx))] = Extract_slice_vtk_method2(app, reduced_dataset_cropped, center_point_idx7(idx,:), app.tangents_updated(idx,:));
                [tmp_slice_2D_original, slice_3D_original.(sprintf('idx_%d', idx)), X_final_original.(sprintf('idx_%d', idx)), Y_final_original.(sprintf('idx_%d', idx)), Z_final_original.(sprintf('idx_%d', idx))] = Extract_slice_vtk_method2(app, original_dataset_cropped, center_point_idx7(idx,:), app.tangents_updated(idx,:));
                app.slice_2D_reduced.(sprintf('idx_%d', idx)) = tmp_slice_2D_reduced;
                slice_2D_original.(sprintf('idx_%d', idx)) = tmp_slice_2D_original;
                
            end
            
            app.StatusLamp.Color = [1 0 0];
            app.LampLabel.Text = "Final image enhancement in progress ...";
            drawnow;
            
            for idx = 1:size(center_point_idx7,1)
                
                app.LampLabel.Text = sprintf("Final image enhancement in progress (%d%%)...", round(idx/size(center_point_idx7,1)*100));
                drawnow;
                
                tmp_slice_enhs = app.slice_2D_reduced.(sprintf('idx_%d', idx));
                tmp_slice_original = slice_2D_original.(sprintf('idx_%d', idx));
                for x_idx = 1:size(tmp_slice_enhs, 1)
                    
                    for y_idx = 1:size(tmp_slice_enhs, 2)
                        
                        if tmp_slice_enhs(x_idx, y_idx) < 200
                            tmp_slice_enhs(x_idx, y_idx) = tmp_slice_original(x_idx, y_idx);
                        end
                        
                    end
                    
                end
                
                app.slice_2D_reduced_enhsd.(sprintf('idx_%d', idx)) = tmp_slice_enhs;
                
            end
            
            
            app.StatusLamp.Color = [0 1 0];
            app.LampLabel.Text = "Segmentation Done!";
            drawnow;
            
            
        end

        % Button pushed function: LoadrawpathButton
        function LoadrawpathButtonPushed(app, event)
            
        
            app.StatusLamp.Color = [1 0 0];
            app.LampLabel.Text = "Loading raw centerline file ...";
            
            app.UIFigure.Visible = 'off';
            % Open dialogue box to select SV path file
            [file,path] = uigetfile('*.*');
            
            app.UIFigure.Visible = 'on';
            
            % Create DICOM metadata collection
            app.UIFigure.Visible = 'off';
            file_name_full = fullfile( path, file );
            app.UIFigure.Visible = 'on';
            
            CL_coords = readmatrix(file_name_full);
            centerline_points = CL_coords(:,1:3);
            centerline_fine = interp3D_curve(app, centerline_points, 100, 0.25);
            
            if app.TranslateCLCheckBox.Value == 1
                file_name = app.sorted_filenames(end);              % Get the last filename in dcm series
                dcm_info = dicominfo(file_name);
                patientPosition = dcm_info.ImagePositionPatient;
%                 centerline(:,1) = centerline(:,1) + patientPosition(1) + 2*app.delta_x;
%                 centerline(:,2) = centerline(:,2) + patientPosition(2) + 2*app.delta_y;
%                 centerline(:,3) = centerline(:,3) - patientPosition(3) + 2*app.delta_z;
                centerline_fine(:,1) = centerline_fine(:,1) + patientPosition(1);
                centerline_fine(:,2) = centerline_fine(:,2) + patientPosition(2);
                centerline_fine(:,3) = centerline_fine(:,3) - patientPosition(3);
                
            end


            X_bif = str2double(app.XEditField.Value);
            Y_bif = str2double(app.YEditField.Value);
            Z_bif = str2double(app.ZEditField.Value);


            if (X_bif==0) && (Y_bif==0) && (Z_bif ==0)
                app.centerline_interp = interp3D_curve(app, centerline_fine, 100, 0.25);
                app.tangents_updated = update_tangents(app, app.centerline_interp);
            else
                
                if app.TranslateCLCheckBox.Value == 1
                    file_name = app.sorted_filenames(end);              % Get the last filename in dcm series
                    dcm_info = dicominfo(file_name);
                    patientPosition = dcm_info.ImagePositionPatient;
                    X_bif = X_bif + patientPosition(1);
                    Y_bif = Y_bif + patientPosition(2);
                    Z_bif = Z_bif - patientPosition(3);
                end
                
                [app.centerline_interp app.bif_index] = split_CL_at_bifurcation(app, centerline_fine(2:end,:), [X_bif Y_bif Z_bif]);
                assignin('base','bif_idx',app.bif_index);
                app.tangents_updated = update_tangents(app, app.centerline_interp);
            end
            
            
            hold(app.UI3DPlanesAxis, 'on')
%             plot3(app.UI3DPlanesAxis, app.centerline_interp(:,1), app.centerline_interp(:,2), app.centerline_interp(:,3),'.r-')
            plot3(app.UI3DPlanesAxis, centerline_fine(:,1), centerline_fine(:,2), centerline_fine(:,3),'.r-')
            hold(app.UI3DPlanesAxis, 'off')
            
            app.LampLabel.Text = "Creating cross-sectional images ...";
            
            for idx = 1:size(app.centerline_interp,1)
                
                app.LampLabel.Text = sprintf("Segmentation is in progress (%d%%)...", round(idx/size(app.centerline_interp,1)*100));
                drawnow;
                % Get 2D slices
%                 [app.slice_2D.(sprintf('idx_%d', idx)), app.slice_3D.(sprintf('idx_%d', idx))] = Extract_Oblique_Slice(app, app.dcm_series_volume_HU, app.centerline_interp(idx,:), app.tangents_updated(idx,:), 50, 0);
                [app.slice_2D.(sprintf('idx_%d', idx)), app.slice_3D.(sprintf('idx_%d', idx))] = Extract_Oblique_Slice(app, app.dcm_series_volume_HU_enhcd, app.centerline_interp(idx,:), app.tangents_updated(idx,:), 50, 0);
%                 [app.slice_2D.(sprintf('idx_%d', idx)), app.slice_3D.(sprintf('idx_%d', idx))] = Extract_Oblique_Slice(app, app.dcm_series_volume_HU, app.centerline(idx,:), app.tangent_vector(idx,:), 50, 0);                
                
                
%                 [app.slice_2D.(sprintf('idx_%d', idx)), app.slice_3D.(sprintf('idx_%d', idx))] = Extract_Oblique_Slice(app, double(app.dcm_series_volume), app.centerline(idx,:), app.tangent_vector(idx,:), 50, 0);
%                 app.slice_2D
                % Create a box for arterial cross-sectional veiw
                box_x = (round(size(app.slice_2D.(sprintf('idx_%d', idx)),1)/2) - 30):1:(round(size(app.slice_2D.(sprintf('idx_%d', idx)),1)/2) + 30);
                box_y = (round(size(app.slice_2D.(sprintf('idx_%d', idx)),2)/2) - 30):1:(round(size(app.slice_2D.(sprintf('idx_%d', idx)),2)/2) + 30);
                % Save the arterial cross-sectional veiw
                dummy_slice = app.slice_2D.(sprintf('idx_%d', idx));
                app.slice_2D_zoomed.(sprintf('idx_%d', idx)) = dummy_slice(box_x,box_y);
                % Build the curved MPR (straightened) 3D image
                Straightened_curved_MPR(:,:,idx) = app.slice_2D_zoomed.(sprintf('idx_%d', idx));
            end
            
            app.LampLabel.Text = "Cross-sectional images are created.";
            app.StatusLamp.Color = [0 1 0];
            drawnow;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ImportDICOMMetadataButtonGroup = uibuttongroup(app.UIFigure);
            app.ImportDICOMMetadataButtonGroup.Title = 'Import DICOM Metadata';
            app.SelectdirectoryButton = uibutton(app.ImportDICOMMetadataButtonGroup, 'push');
            app.SelectdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdirectoryButtonPushed, true);
            app.SelectdirectoryButton.Text = 'Select directory';
            app.ChoosethedirectorytoDICOMmetadataLabel = uilabel(app.ImportDICOMMetadataButtonGroup);
            app.ChoosethedirectorytoDICOMmetadataLabel.Text = 'Choose the directory to DICOM metadata:';
            app.UIdicomCollectionTable = uitable(app.ImportDICOMMetadataButtonGroup);
            app.UIdicomCollectionTable.ColumnName = {''};
            app.UIdicomCollectionTable.RowName = {};
            app.UIdicomCollectionTable.CellEditCallback = createCallbackFcn(app, @UIdicomCollectionTableCellEdit, true);
            app.LoadSelectedSeriesButton = uibutton(app.ImportDICOMMetadataButtonGroup, 'push');
            app.LoadSelectedSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSelectedSeriesButtonPushed, true);
            app.LoadSelectedSeriesButton.Text = 'Load Selected Series';
            app.AllViewsPanel = uipanel(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ImageNavigatorPanel = uipanel(app.UIFigure);
            app.ImageNavigatorPanel.Title = 'Image Navigator';
            app.AxialSliderLabel = uilabel(app.ImageNavigatorPanel);
            app.AxialSliderLabel.Text = 'Axial';
            app.AxialSlider = uislider(app.ImageNavigatorPanel);
            app.AxialSlider.MajorTicks = [];
            app.AxialSlider.MajorTickLabels = {};
            app.AxialSlider.ValueChangedFcn = createCallbackFcn(app, @AxialSliderValueChanged, true);
            app.AxialSlider.ValueChangingFcn = createCallbackFcn(app, @AxialSliderValueChanging, true);
            app.AxialSlider.MinorTicks = [];
            app.SagittalSliderLabel = uilabel(app.ImageNavigatorPanel);
            app.SagittalSliderLabel.Text = 'Sagittal';
            app.SagittalSlider = uislider(app.ImageNavigatorPanel);
            app.SagittalSlider.MajorTicks = [];
            app.SagittalSlider.MajorTickLabels = {};
            app.SagittalSlider.ValueChangedFcn = createCallbackFcn(app, @SagittalSliderValueChanged, true);
            app.SagittalSlider.ValueChangingFcn = createCallbackFcn(app, @SagittalSliderValueChanging, true);
            app.SagittalSlider.MinorTicks = [];
            app.CoronalSliderLabel = uilabel(app.ImageNavigatorPanel);
            app.CoronalSliderLabel.Text = 'Coronal';
            app.CoronalSlider = uislider(app.ImageNavigatorPanel);
            app.CoronalSlider.MajorTicks = [];
            app.CoronalSlider.MajorTickLabels = {};
            app.CoronalSlider.ValueChangedFcn = createCallbackFcn(app, @CoronalSliderValueChanged, true);
            app.CoronalSlider.ValueChangingFcn = createCallbackFcn(app, @CoronalSliderValueChanging, true);
            app.CoronalSlider.MinorTicks = [];
            app.AxialLabel = uilabel(app.ImageNavigatorPanel);
            app.AxialLabel.Text = '1';
            app.SagittalLabel = uilabel(app.ImageNavigatorPanel);
            app.SagittalLabel.Text = '1';
            app.CoronalLabel = uilabel(app.ImageNavigatorPanel);
            app.CoronalLabel.Text = '1';
            app.WLWWPanel = uipanel(app.UIFigure);
            app.WLWWPanel.Title = 'WL/WW';
            app.SetWLWWButton = uibutton(app.WLWWPanel, 'push');
            app.SetWLWWButton.ButtonPushedFcn = createCallbackFcn(app, @SetWLWWButtonPushed, true);
            app.SetWLWWButton.Text = 'Set WL/WW';
            app.WWEditFieldLabel = uilabel(app.WLWWPanel);
            app.WWEditFieldLabel.Text = 'WW:';
            app.WWEditField = uieditfield(app.WLWWPanel, 'numeric');
            app.WWEditField.Value = 830;
            app.WLEditFieldLabel = uilabel(app.WLWWPanel);
            app.WLEditFieldLabel.Text = 'WL:';
            app.WLEditField = uieditfield(app.WLWWPanel, 'numeric');
            app.WLEditField.Value = 350;
            app.ExtractLuminalCrosssectionImagesPanel = uipanel(app.UIFigure);
            app.ExtractLuminalCrosssectionImagesPanel.Title = 'Extract Luminal Cross-section Images';
            app.AddPointsButton = uibutton(app.ExtractLuminalCrosssectionImagesPanel, 'push');
            app.AddPointsButton.ButtonPushedFcn = createCallbackFcn(app, @AddPointsButtonPushed, true);
            app.AddPointsButton.Text = 'Add Points';
            app.CreateButton = uibutton(app.ExtractLuminalCrosssectionImagesPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.ViewButton = uibutton(app.ExtractLuminalCrosssectionImagesPanel, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.SaveButton = uibutton(app.ExtractLuminalCrosssectionImagesPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.CreateaseriesforpointforcenterlinecalculationLabel = uilabel(app.ExtractLuminalCrosssectionImagesPanel);
            app.CreateaseriesforpointforcenterlinecalculationLabel.Text = 'Create a series for point for centerline calculation';
            app.CreatethecenterlineLabel = uilabel(app.ExtractLuminalCrosssectionImagesPanel);
            app.CreatethecenterlineLabel.Text = 'Create the centerline';
            app.Viewthecrosssectionalandcurved3DMPRviewsLabel = uilabel(app.ExtractLuminalCrosssectionImagesPanel);
            app.Viewthecrosssectionalandcurved3DMPRviewsLabel.Text = 'View the cross-sectional and curved 3D MPR views';
            app.SavecrosssectionalimagesindcmseriesLabel = uilabel(app.ExtractLuminalCrosssectionImagesPanel);
            app.SavecrosssectionalimagesindcmseriesLabel.Text = 'Save cross-sectional images in dcm series';
            app.UITablePoints = uitable(app.UIFigure);
            app.UITablePoints.ColumnName = {'Sag'; 'Cor'; 'Ax'};
            app.UITablePoints.RowName = {''};
            app.SavePointsButton = uibutton(app.UIFigure, 'push');
            app.SavePointsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePointsButtonPushed, true);
            app.SavePointsButton.Text = 'Save Points';
            app.LoadSVpathButton = uibutton(app.UIFigure, 'push');
            app.LoadSVpathButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSVpathButtonPushed, true);
            app.LoadSVpathButton.Text = 'Load SV path';
            app.QuantifyButton = uibutton(app.UIFigure, 'push');
            app.QuantifyButton.ButtonPushedFcn = createCallbackFcn(app, @QuantifyButtonPushed, true);
            app.QuantifyButton.Text = 'Quantify';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = '';
            app.ShowCLButton = uibutton(app.UIFigure, 'state');
            app.ShowCLButton.ValueChangedFcn = createCallbackFcn(app, @ShowCLButtonValueChanged, true);
            app.ShowCLButton.Text = 'Show CL';
            app.TranslateCLCheckBox = uicheckbox(app.UIFigure);
            app.TranslateCLCheckBox.Text = 'Translate CL';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.XEditField.Value = '0';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.YEditField.Value = '0';
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'text');
            app.ZEditField.Value = '0';
            app.BifurcationPointLabel = uilabel(app.UIFigure);
            app.BifurcationPointLabel.Text = 'Bifurcation Point';
            app.ExportSlicesButton = uibutton(app.UIFigure, 'push');
            app.ExportSlicesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSlicesButtonPushed, true);
            app.ExportSlicesButton.Text = 'Export Slices';
            app.LevelsetButton = uibutton(app.UIFigure, 'push');
            app.LevelsetButton.ButtonPushedFcn = createCallbackFcn(app, @LevelsetButtonPushed, true);
            app.LevelsetButton.Text = 'Levelset';
            app.VVLevelsetButton = uibutton(app.UIFigure, 'push');
            app.VVLevelsetButton.ButtonPushedFcn = createCallbackFcn(app, @VVLevelsetButtonPushed, true);
            app.VVLevelsetButton.Text = 'VV+Levelset';
            app.VVFLevelsetButton = uibutton(app.UIFigure, 'push');
            app.VVFLevelsetButton.ButtonPushedFcn = createCallbackFcn(app, @VVFLevelsetButtonPushed, true);
            app.VVFLevelsetButton.Text = 'VV+F+Levelset';
            app.LoadrawpathButton = uibutton(app.UIFigure, 'push');
            app.LoadrawpathButton.ButtonPushedFcn = createCallbackFcn(app, @LoadrawpathButtonPushed, true);
            app.LoadrawpathButton.Text = 'Load raw path';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = centerline_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end