classdef my_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutomatedInteractivePlaqueQuantificationAIPQUIFigure  matlab.ui.Figure
        ExportMenu                      matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        PropagateROIMenu                matlab.ui.container.Menu
        VisualizationPanel              matlab.ui.container.Panel
        UIROI                           matlab.ui.control.UIAxes
        UIPlaqueQuant                   matlab.ui.control.UIAxes
        UILumen                         matlab.ui.control.UIAxes
        UImain_image                    matlab.ui.control.UIAxes
        ScrollthroughImagesSliderLabel  matlab.ui.control.Label
        ScrollthroughImagesSlider       matlab.ui.control.Slider
        NextImageButton                 matlab.ui.control.Button
        PreviousImageButton             matlab.ui.control.Button
        ImportdcmButtonGroup            matlab.ui.container.ButtonGroup
        ImportsingledcmButton           matlab.ui.control.RadioButton
        ImportdcmseriesButton           matlab.ui.control.RadioButton
        SelectfileButton                matlab.ui.control.Button
        SelectdirectoryButton           matlab.ui.control.Button
        PlaqueQuantificationParametersPanel  matlab.ui.container.Panel
        LumenHULabel                    matlab.ui.control.Label
        LumenMinEditField               matlab.ui.control.NumericEditField
        NecroticMaxEditField            matlab.ui.control.NumericEditField
        toEditField_2Label              matlab.ui.control.Label
        CaPLQHULabel                    matlab.ui.control.Label
        CaMinHUEditField                matlab.ui.control.NumericEditField
        CaMaxEditField                  matlab.ui.control.NumericEditField
        toEditField_3Label              matlab.ui.control.Label
        LumenMaxEditField               matlab.ui.control.NumericEditField
        toEditField_4Label              matlab.ui.control.Label
        NecrCoreHULabel                 matlab.ui.control.Label
        NecroticMinEditField            matlab.ui.control.NumericEditField
        SetPQParamButton                matlab.ui.control.Button
        FIBPLQHULabel                   matlab.ui.control.Label
        FibminEditField                 matlab.ui.control.NumericEditField
        FibmaxEditField                 matlab.ui.control.NumericEditField
        toLabel                         matlab.ui.control.Label
        ApplyonlytocurrentsegmentCheckBox  matlab.ui.control.CheckBox
        UseadaptivethresholdCheckBox    matlab.ui.control.CheckBox
        AutoSegmentationCheckBox        matlab.ui.control.CheckBox
        FromLabel                       matlab.ui.control.Label
        FromEditField                   matlab.ui.control.NumericEditField
        ToEditFieldLabel                matlab.ui.control.Label
        ToEditField                     matlab.ui.control.NumericEditField
        TabGroup                        matlab.ui.container.TabGroup
        Seg1Tab                         matlab.ui.container.Tab
        UITable_seg1                    matlab.ui.control.Table
        WLWWPanel                       matlab.ui.container.Panel
        SetWLWWButton                   matlab.ui.control.Button
        WWEditFieldLabel                matlab.ui.control.Label
        WWEditField                     matlab.ui.control.NumericEditField
        WLEditFieldLabel                matlab.ui.control.Label
        WLEditField                     matlab.ui.control.NumericEditField
        ROIManagerPanel                 matlab.ui.container.Panel
        DrawROIButton                   matlab.ui.control.StateButton
        DrawROIforasingleimageLabel     matlab.ui.control.Label
        PropagateROIButton              matlab.ui.control.StateButton
        PropagateROIthroughaseriesofimagesLabel  matlab.ui.control.Label
        StartButton                     matlab.ui.control.StateButton
        WatershedLabel                  matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.StateButton
        UpdatethetableLabel             matlab.ui.control.Label
        QuantifyButton                  matlab.ui.control.Button
        PropagateROIthroughaseriesofimagesLabel_2  matlab.ui.control.Label
        ExportButton                    matlab.ui.control.StateButton
        ExportthetableLabel             matlab.ui.control.Label
        CalculatethelumenalareaLabel    matlab.ui.control.Label
        LumenButton                     matlab.ui.control.Button
        StatusLampLabel                 matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        ResetquantificationButton       matlab.ui.control.Button
        LampLabel                       matlab.ui.control.Label
        UseGradientofImagesCheckBox     matlab.ui.control.CheckBox
        QuantoptionsPanel               matlab.ui.container.Panel
        DCPCheckBox                     matlab.ui.control.CheckBox
        LAPCheckBox                     matlab.ui.control.CheckBox
        PositiveremodelingCheckBox      matlab.ui.control.CheckBox
        NRSCheckBox                     matlab.ui.control.CheckBox
        SpottyCalcificationCheckBox     matlab.ui.control.CheckBox
        SelectbiomarkersLabel           matlab.ui.control.Label
        SeverestenosisCheckBox          matlab.ui.control.CheckBox
        ApplyButton                     matlab.ui.control.Button
        CalibrationPanel                matlab.ui.container.Panel
        FirstImageEditFieldLabel        matlab.ui.control.Label
        FirstImageEditField             matlab.ui.control.NumericEditField
        LastImageEditFieldLabel         matlab.ui.control.Label
        LastImageEditField              matlab.ui.control.NumericEditField
        CalibrateButton                 matlab.ui.control.Button
    end


    properties (Access = public)
        CallingApp;
        
    end
    properties (Access = private)
        dcm_image_series                % Sorted dicom files based on InstanceNumber
        dcm_number_total                % Total number files in dicom series
        HounsFieldImageSeries           % Sorted HU dicom files based on InstanceNumber
        HounsFieldImageSeriesVol        % Sorted HU dicom volume files based on InstanceNumber
        HounsFieldImageSeriesVol_scaled % Scaled HU dicom files
        full_file_names_series          % List of full file name for each of dcm files
        
        dcm_image_single                % Single dicom opend
        HounsFieldImageSingle           % Single HU dicom file
        full_file_name_single           % Full dcm file name
        
        upper_level                     % Upper level of contrast for images based on WW and WL
        lower_level                     % Lower level of contrast for images based on WW and WL
        
        single_dcm_flag                 % Flag for using only one dicom file
        series_dcm_flag                 % Flag for using dicom series
        
        fib_plaque_min                  % minimum threshold for soft plaque (global)
        fib_plaque_max                  % maximum threshold for soft plaque (global)
        lumen_min                       % minimum threshold for lumen (global)
        lumen_max                       % maximum threshold for lumen (global)
        ca_plaque_min                   % minimum threshold for calcium plaque (global)
        ca_plaque_max                   % maximum threshold for calcium plaque (global)
        necratic_min                    % minimum threshold for necrotic core (global)
        necratic_max                    % maximum threshold for necrotic core (global)
        
        report_data                     % Variable storing the plaque quantification data
        
        fib_plaque_min_seg              % minimum threshold for soft plaque (segment)
        fib_plaque_max_seg              % maximum threshold for soft plaque (segment)
        lumen_min_seg                   % minimum threshold for lumen (segment)
        lumen_max_seg                   % maximum threshold for lumen (segment)
        ca_plaque_min_seg               % minimum threshold for calcium plaque (segment)
        ca_plaque_max_seg               % maximum threshold for calcium plaque (segment)
        necratic_min_seg                % minimum threshold for necrotic core (segment)
        necratic_max_seg                % maximum threshold for necrotic core (segment)
        
        propagation                     % Stores the ROIs for dcm series
        
        semiManualSeg_dataset           % Stores the semi-manual segmentation dataset. Struct:
                                        % .ROI_Position
                                        % .ROI_Mask
                                        % .ROI_Centroid
                                        % .Lumen_MIN
                                        % .Lumen_MAX
                                        % .Lumen_AVE
        
        IsSegAccepted                   % Stores the flag for accepted segments. Array(N of images)
                                        % 1 if segment is accepted; 0 if
                                        % otherwise
        
        propagationWindow               % Name of new GUI window for ROI propagation algorithm
        first_dcm_mask                  % First dcm index (original series) in ROI propagation series
        last_dcm_mask                   % Last dcm index (original series) in ROI propagation series
        ROI_propagation_mask            % Structure (.masks) for storing masked ROIs
        series_mask_success             % Flag indicating the successs of creating masks for a dcm series
        ROI_centroid                    % Structure (.centeroid) centeroid for all the ROIs
        
        UITable_seg                     % Array of uitable objects
        report_tab                      % UITab objects
        
        watershed_ax                    % An interactive axes for Watershed algorithm
        
        masked_img_lumen                % Stores the thresholded mask of lumens; Struct: .mask        
        masked_img_fib_plaque           % Stores the thresholded mask of fib plaques; Struct: .mask
        masked_img_nec_plaque           % Stores the thresholded mask of nec plaques; Struct: .mask
        masked_img_calcium_plaque       % Stores the thresholded mask of ca plaques; Struct: .mask
        
        flag_imported_centerline = false% Flag for imported centerline. "True" if centerline was imported; "False" if dicom slices were imported
        
        centerline                      % Store the centerline points
        slice_2D                        % Stores the 2D slices. struct: (.idx_#centerline_point)
        slice_2D_zoomed                 % Stores the zoomed 2D slices. struct: (.idx_#centerline_point)
        
        plq_quant_flags                 % Stores the flags for each segment (.idx_#centerline_point.{flag_name})
        
        lumen_levelset                  % Stores the lumen for each segment (.images)
        
        lumen_bounds_calib              % Array: [lower_threshold upper_threshold]
        
    end
    
    methods (Access = public)
        
        % % ***************************************************
        % % ******************** Functions ********************
        % % ***************************************************
        
        function mask = DrawFreehandRegion(app, rgbImage)
        try
        	fontSize = 14;
         
%                 app.VisualizationPanel.Children = flip(app.VisualizationPanel.Children);
%                 app.UImain_image.Visible = 'off';
%                 uax = axes('Parent',app.UImain_image,"Position",app.UImain_image.Position);
%                 imshow(rgbImage, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', uax);


                fig = figure;
                imshow(rgbImage,'DisplayRange', [app.lower_level app.upper_level]); 
                set(fig, 'Position', get(0, 'Screensize'));  % Fullscreen image
                set(fig,'CloseRequestFcn','')                % Prevent pop-up from closing
                set(fig, 'MenuBar', 'none');
                set(fig, 'ToolBar', 'none');
                

                message = sprintf('Left click and hold to begin drawing.\nSimply lift the mouse button to finish');
        	text( 10, 40, message, 'color', 'r', 'FontSize', fontSize);
        
%                 Prompt user to draw a region on the image.
        	uiwait(msgbox(message));
                
                hFH = imfreehand();
                
                mask = hFH.createMask();
         
                delete(fig)
                
                app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure.Visible = 'off';
                app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure.Visible = 'on';


%                 all_handles = app.VisualizationPanel.Children;
%                 
%                 all_indices = (1:numel(all_handles))';
%                 
%                 for index = 1:numel(app.UImain_image)
%                     if ishandle(app.UImain_image(index))
%                         this_index = find(all_handles == app.UImain_image(index));
%             
%                         if ~isempty(this_index)
%                             rest_of_indices = setdiff(all_indices, this_index);
%                             all_handles = all_handles([rest_of_indices; this_index]);
%                         end
%                     end
%                 end
%                 
%                 app.VisualizationPanel.Children = all_handles;


%                 appHandles = app.VisualizationPanel.Children;       % list all handles in app
%                 hIdx = find(appHandles==uax);       % find index of obj in appHandles
%                 % Create new index order of app handles
%                 newOrder = [hIdx,setdiff(1:numel(appHandles),hIdx)]; 
%                 % assign new stack order to app
%                 app.VisualizationPanel.Children = appHandles(newOrder);

                
%                 set(uax, 'Visible', 'on')
                
         
%         	if enlargeForDrawing
%         		hImage = findobj(gca,'Type','image');
%         		numberOfImagesInside = length(hImage);
%         		if numberOfImagesInside > 1
%         			imageInside = get(hImage(1), 'CData');
%         		else
%         			imageInside = get(hImage, 'CData');
%                         end
%             
% %         		hTemp = figure;
%         		hImage2 = imshow(imageInside, [app.lower_level app.upper_level]);
%         		[rows columns NumberOfColorBands] = size(imageInside);
% %         		set(gcf, 'Position', get(0,'Screensize')); % Maximize figure.
%         	end
%          
%                 imshow(rgbImage, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image);
%     		[rows columns NumberOfColorBands] = size(imageInside);
%                 draw_fig = figure;
%                 imshow(rgbImage, [app.lower_level app.upper_level]);
%                 set(draw_fig, 'Position', get(0, 'Screensize'));
% %                 set(draw_fig, 'Position', app.UImain_image.Position);
%                 set(draw_fig,'CloseRequestFcn','');
%                 set(draw_fig, 'MenuBar', 'none');
%                 set(draw_fig, 'ToolBar', 'none');
%                 delete(draw_fig)
                
%         	message = sprintf('Left click and hold to begin drawing.\nSimply lift the mouse button to finish');
%         	text(app.UImain_image, 10, 40, message, 'color', 'r', 'FontSize', fontSize);
        
            % Prompt user to draw a region on the image.
%         	uiwait(msgbox(message));
        	
        	% Now, finally, have the user freehand draw the mask in the image.
%         	hFH = imfreehand(handleToImage);
%                 hFH = images.roi.Freehand('Parent', handleToImage);
%                 hFH = images.roi.Polygon("Parent",handleToImage);
%                 hFH = drawpolygon('Parent',uax);
%                 hFH = drawcircle('Parent', app.UImain_image)
        
        	% Once we get here, the user has finished drawing the region.
        	% Create a binary image ("mask") from the ROI object.


        	% Close the maximized figure because we're done with it.
%         	close(hTemp);
        
        	% Display the freehand mask.
%         	subplot(2, 4, 3);
%         	imshow(mask, []);
%         	title('Binary mask of the region', 'FontSize', fontSize);
        	
        	% Mask the image.
%         	maskedRgbImage = bsxfun(@times, rgbImage, cast(mask,class(rgbImage)));
%         	% Display it.
%         	subplot(2, 4, 4);
%         	imshow(maskedRgbImage, []);
        catch ME
        	errorMessage = sprintf('Error running DrawFreehandRegion:\n\n\nThe error message is:\n%s', ...
        		ME.message);
        	WarnUser(app, errorMessage);
        end
        
        end
        % return; % from DrawFreehandRegion
        
        function WarnUser(app, warningMessage)
        	uiwait(warndlg(warningMessage));
        %	return; % from WarnUser()
        end
        
        % Get dicom Header
        function info = get_dicom_info(app, dicom_directory)
        
            info = dicominfo(dicom_directory);
            
        end
        
        % Convert the dicom image into HU
        function hounsfieldImage = calculate_HU(app, dicom_image, dicom_info)
            hounsfieldImage=double(dicom_image)*dicom_info.RescaleSlope+dicom_info.RescaleIntercept;
        end
        
        % Draw the ROI
        function ROI = draw_ROI(app, hounsfieldImage)

            mask = DrawFreehandRegion(app, hounsfieldImage);  % Draw a freehand, irregularly-shaped region.
        
            ROI = bsxfun(@times, hounsfieldImage, cast(mask, class(hounsfieldImage)));

            imshow(ROI,'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UIROI)

        end
        
        
        % Get histogram of masked image, but only within the mask area.
        function find_hist(app, maskedRgbImage, mask)
            histogram(maskedRgbImage(mask), 256, 'EdgeColor', 'none')
            grid on;
            title('Histogram of Masked Image', 'FontSize', 14, 'Interpreter', 'None');
        end
        
        % Find the Lumen: 250<= HU <= 450
        function  img_threshold = threshold_min_max(app, maskedRgbImage, min_thrshold, max_thrshold)
            
            if isstruct(maskedRgbImage)==1
                img_threshold_cell = struct2cell(maskedRgbImage);
                
                for struc_num = 1:length(img_threshold_cell)
                    img_threshold_ar = cell2mat(img_threshold_cell(struc_num));
                    
                    img_threshold_tmp = img_threshold_ar;
                    threshold_region = img_threshold_tmp >= min_thrshold & img_threshold_tmp <= max_thrshold;
                    img_threshold_tmp(~threshold_region) = 0;
                    
                    img_threshold.(sprintf('sec%d',struc_num)) = img_threshold_tmp;
                end
                
            else
                img_threshold = maskedRgbImage;
                threshold_region = img_threshold >= min_thrshold & img_threshold <= max_thrshold;
                img_threshold(~threshold_region) = 0;
            end


        end 
        
        % Calculate the Lumen Area
        function ROI_area = calc_ROI_area(app, dicom_info, img_threshold)
            
            if app.flag_imported_centerline == true
                spacing = 0.08./dicom_info.PixelSpacing;
            else
                spacing = dicom_info.PixelSpacing;
            end
            per_pixel_area = spacing(1) * spacing(2);
        
            % Create a Matrix to Tag Voxels inside ROI with 1
%             nRows = dicom_info.Rows;
%             nCols = dicom_info.Columns;
            
%             [nRows nCols NumberOfColorBands] = size(img_threshold);
            
            if isstruct(img_threshold)==1
                img_threshold_cell = struct2cell(img_threshold);
            
                for struc_num = 1:length(img_threshold_cell)
                    img_threshold_ar = cell2mat(img_threshold_cell(struc_num));
                    [nRows nCols NumberOfColorBands] = size( img_threshold_ar );
                    area_tag = zeros(nRows, nCols);
                    
            
                    for R=1:nRows
                        for C=1:nCols
                            if  img_threshold_ar(R,C)== 0
                                area_tag(R,C) = 0;
                            else
                                area_tag(R,C) = 1;
                            end
                        end
                    end
                    ROI_area(struc_num) = sum(area_tag * per_pixel_area,'all');
                
                end
                
                ROI_area(length(img_threshold_cell)+1) = sum(ROI_area,"all");
            
            else
                
                [nRows nCols NumberOfColorBands] = size(img_threshold);
                for R=1:nRows
                    for C=1:nCols
                        if img_threshold(R,C) == 0
                            area_tag(R,C) = 0;
                        else
                            area_tag(R,C) = 1;
                        end
                    end
                end
                ROI_area = sum(area_tag * per_pixel_area,'all');
                
            end
        
        end
        
        % Mask the Lumen on an Image with Green Color: Patch function
        function patch_hexagon(app, hounsfieldImage, img_threshold)
            % Uses only a hexagone
            L = bwlabel(img_threshold);
            s = regionprops(L, 'Extrema');
            
            subplot(2, 4, 6);
            imshow(hounsfieldImage, [])
            hold on
            for k = 1:numel(s)
                x = s(k).Extrema(:,1);
                y = s(k).Extrema(:,2);
                patch(x, y, 'g')
            end
            hold off
        end
        
        % Mask the Lumen on an Image with Green Color: Accurate Patching
        function patch_ROI(app, img_threshold, hounsfieldImage)
%             subplot(2, 4, 6);
            masked_img_lumen_bi = imbinarize(img_threshold);
            imshow(hounsfieldImage,'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UILumen);
            green = cat(3, zeros(size(hounsfieldImage)), ones(size(hounsfieldImage)), zeros(size(hounsfieldImage)));
            hold(app.UILumen,'on')
            h = imshow(green, 'Parent', app.UILumen);
            hold(app.UILumen,"off")
            h.AlphaData = img_threshold;
        end
        
        function multiple_overlay(app, img_threshold1, img_threshold2, img_threshold3, img_threshold4, hounsfieldImage)

            imshow(hounsfieldImage, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UIPlaqueQuant);
            green = cat(3, zeros(size(hounsfieldImage)), ones(size(hounsfieldImage)), zeros(size(hounsfieldImage)));
            blue = cat(3, zeros(size(hounsfieldImage)), zeros(size(hounsfieldImage)), ones(size(hounsfieldImage)));
            red = cat(3, ones(size(hounsfieldImage)), zeros(size(hounsfieldImage)), zeros(size(hounsfieldImage)));
            calcium = cat(3, ones(size(hounsfieldImage)), ones(size(hounsfieldImage)), zeros(size(hounsfieldImage)));
            hold(app.UIPlaqueQuant, 'on')
            h = imshow(green, 'Parent', app.UIPlaqueQuant);
            hold(app.UIPlaqueQuant, 'off')
            h.AlphaData = img_threshold1;
            
            hold(app.UIPlaqueQuant, 'on')
            img_blue = imshow(blue, 'Parent', app.UIPlaqueQuant);
            hold(app.UIPlaqueQuant, 'off')
            img_blue.AlphaData = img_threshold2;
            
            hold(app.UIPlaqueQuant, 'on')
            img_red = imshow(red, 'Parent', app.UIPlaqueQuant);
            hold(app.UIPlaqueQuant, 'off')
            img_red.AlphaData = img_threshold3;
            
            hold(app.UIPlaqueQuant, 'on')
            img_calcium = imshow(calcium, 'Parent', app.UIPlaqueQuant);
            hold(app.UIPlaqueQuant, 'off')
            img_calcium.AlphaData = img_threshold4;
        end
        
        function geom_features = seg_sectors(app, img_lumen)
            masked_img_lumen_bi = imbinarize(img_lumen);
            geom_features = regionprops('table',masked_img_lumen_bi,'Centroid',...
            'MajorAxisLength','MinorAxisLength');
            %figure
            %imshow(masked_img_lumen_bi, [])
        end
        
        function output_secs = create_sectors(app, maskedRgbImage, centroid_info)
        

            [y, x] = size(maskedRgbImage);
            min_x = 1;
            max_x = x;
            min_y = 1;
            max_y = y;
            
            
            % ***********************************************
            %                       |
            %                4      |       1
            %                       |
            %          _____________|_____________
            %                       |
            %                3      |       2
            %                       |
            % ***********************************************
            
            mean_x = floor(centroid_info(2));
            mean_y = floor(centroid_info(1));
            
            sector_3 = maskedRgbImage(mean_x:max_x, min_y:mean_y);
            sector_2 = maskedRgbImage(mean_x:max_x, (mean_y+1):max_y);
            
            sector_4 = maskedRgbImage(min_x:(mean_x-1), min_y:mean_y);
            sector_1 = maskedRgbImage(min_x:(mean_x-1), (mean_y+1):max_y);
            
%             figure;
%             subplot(2,2,1)
%             imshow(sector_1, [])
%             
%             subplot(2,2,2)
%             imshow(sector_2, [])
%             
%             subplot(2,2,3)
%             imshow(sector_3, [])
%             
%             subplot(2,2,4)
%             imshow(sector_4, [])
            
            output_secs.sec1 = sector_1;
            output_secs.sec2 = sector_2;
            output_secs.sec3 = sector_3;
            output_secs.sec4 = sector_4;
            
            %output_secs.sec1(1) = (x >= centroid_info(1) && y >= centroid_info(2));
            %output_secs.sec1(1) = (x > centriod_info(1));
        end
        
        
        function watershed_calcium = watershed_separation(app, img_lumen, img_fibrous, hounsfieldImage, dicom_info)
        
%             assignin('base', 'img_lumen', img_lumen)
%             assignin('base', 'dicom_info', dicom_info)
%             assignin('base', 'img_fibrous', img_fibrous)
        
            D = bwdist(~img_lumen);
            
            D = -D;
            D(~img_lumen) = Inf;
            
            L = watershed(D, 4);
            L(~img_lumen) = 0;

            fig = figure;

            imshow(L, 'DisplayRange', [])
            set(fig, 'Position', get(0, 'Screensize'));  % Fullscreen image
            set(fig,'CloseRequestFcn','')                % Prevent pop-up from closing
            set(fig, 'MenuBar', 'none');
            set(fig, 'ToolBar', 'none');
                

%             message = sprintf('Left click on ROI to select.\nRight Click to finish.');
            message = sprintf('Left click on ROI to select.\nPress Return to finish.');
            text( 10, 40, message, 'color', 'r', 'FontSize', 12);

            rgb = label2rgb(L,'jet',[1 1 1]);
        
            
            [xi,yi] = getpts

            pt_num = size(xi,1);
            
            delete(gcf)
            
            area_tag = zeros(size(L,1), size(L,2));
            
            for pt_idx = 1:size(xi,1)
                px_value = impixel([1 size(L,1)], [1 size(L,2)], L, xi(pt_idx), yi(pt_idx));
            
            
%                 delete(gcf)
            
                spacing = dicom_info.PixelSpacing;
                per_pixel_area = spacing(1) * spacing(2);
            
            
                [nRows, nCols] = size(L)
                for R=1:nRows
                    for C=1:nCols
                        if L(R, C) == px_value
                            area_tag(R,C) = 1;
                        end
                    end
                end
            
            end
            
            ROI_area = sum(area_tag * per_pixel_area,'all')

            actual_lumen = img_lumen;
            masked_img_lumen_new = area_tag==1;
            actual_lumen(~masked_img_lumen_new) = 0;
            
            
%             pixel_value = impixel
% 
%             delete(gcf)
% 
%             spacing = dicom_info.PixelSpacing;
%             per_pixel_area = spacing(1) * spacing(2);
%         
% 
%             [nRows, nCols] = size(L)
%             for R=1:nRows
%                 for C=1:nCols
%                     if L(R, C) == pixel_value
%                         area_tag(R,C) = 1;
% 
%                     else
%                         area_tag(R,C) = 0;
%                     end
%                 end
%             end

%             ROI_area = sum(area_tag * per_pixel_area,'all')
%             
%             actual_lumen = img_lumen;
%             masked_img_lumen_new = L==pixel_value(1);
%             actual_lumen(~masked_img_lumen_new) = 0;
        
            masked_img_lumen_bi = imbinarize(actual_lumen);
            geom_features = regionprops('table',masked_img_lumen_bi,'Centroid',...
            'MajorAxisLength','MinorAxisLength');
        
            imshow(masked_img_lumen_bi, [], 'Parent', app.watershed_ax)


            watershed_calcium.lumen = actual_lumen;
            watershed_calcium.area = ROI_area;
            watershed_calcium.centroid = geom_features;
            
            fib_plaque_add = img_lumen;
%             fib_plaque_add = img_fibrous;
            fib_plaque_add(masked_img_lumen_new) = 0;
            
            [nRows, nCols] = size(L);
            
            for pt_idx = 1:size(xi,1)
                px_value = impixel([1 size(L,1)], [1 size(L,2)], L, xi(pt_idx), yi(pt_idx));
                
                for R=1:nRows
                    for C=1:nCols
                        if (L(R, C) == px_value)
                            fib_plaque_add(R,C) = 0;
                        else
                            fib_plaque_add(R,C) = img_lumen(R,C);
                        end
                    end
                end
                
            end
            
            modified_fib_img = imfuse(img_fibrous,fib_plaque_add,'blend','Scaling','none');
            
            watershed_calcium.fib = modified_fib_img;


        end
        
        
        function [dcm_series_images, dcm_number_total] = reorder_dcm_series(app, dcm_series_dir)
            
            if app.flag_imported_centerline == false
                file_list = dir( fullfile( dcm_series_dir, '*.dcm') );
                number_of_dcm_files = length(file_list);
                dcm_number_total = number_of_dcm_files;
            else
                number_of_dcm_files = length(app.full_file_names_series);
                dcm_number_total = number_of_dcm_files;
                
            end
            
            for dcm_index = 1:number_of_dcm_files
                
                dcm_index
                
                if app.flag_imported_centerline == false
        
                    file_name_full = fullfile(file_list(dcm_index).folder, file_list(dcm_index).name );
                else
                    file_name_full = app.full_file_names_series(dcm_index).names
                end
                
                dcm_info = get_dicom_info(app, file_name_full );
                dcm_series_images(dcm_info.InstanceNumber).images = dicomread( file_name_full );
                
            end
            
        
        end
        
        function [lower_level, upper_level] = set_WW_WL(app, WW, WL)
            upper_level = WL + (WW/2);
            lower_level = WL - (WW/2);
        end
        
        
        function propagation_ROI = propagate_ROI(app)
        
            fig = figure;
            fig.WindowState = 'maximized';
            set(fig,'CloseRequestFcn','')                % Prevent pop-up from closing
            set(fig, 'MenuBar', 'none');
            set(fig, 'ToolBar', 'none');
            h1 = subplot(2,2,1);
            
%             previousButtom_first = uicontrol('Parent',fig,"Style","pushbutton","String","Previous Image","Units","normalized","Position",[0.15 0.5 0.1 0.05],"Visible","on", 'Callback', {@previousButtomBallBack,img});
%             nextButtom_first = uicontrol('Parent',fig,"Style","pushbutton","String","Next Image","Units","normalized","Position",[0.31 0.5 0.1 0.05],"Visible","on", 'Callback', {@nextButtomBallBack,img});
%             
%             previousButtom_last = uicontrol('Parent',fig,"Style","pushbutton","String","Previous Image","Units","normalized","Position",[0.65 0.5 0.1 0.05],"Visible","on");
%             nextButtom_last = uicontrol('Parent',fig,"Style","pushbutton","String","Next Image","Units","normalized","Position",[0.81 0.5 0.1 0.05],"Visible","on");
            
            imshow(app.HounsFieldImageSeries(1).images, 'DisplayRange', [app.lower_level app.upper_level]);
%             fig.WindowState = 'maximized';
%             set(h1, 'Position', get(0, 'Screensize'));  % Fullscreen image
%             set(fig,'CloseRequestFcn','')                % Prevent pop-up from closing
%             set(fig, 'MenuBar', 'none');
%             set(fig, 'ToolBar', 'none');
            
            message = sprintf('Step 1: Left click and hold to begin drawing for the FIRST image in the series.\nSimply lift the mouse button to finish.');
            text( 5, 30, message, 'color', 'r', 'FontSize', 12);
            
            uiwait(msgbox(message));
%             hFH1 = imfreehand(h1);
            hFH1 = drawpolygon();

            position_1 = hFH1.Position;
            centroid_1 = mean(position_1);
            
            
            [theta_1 rho_1] = cart2pol(position_1(:,1) - centroid_1(1), position_1(:,2) - centroid_1(2));
            

            theta_q = min(theta_1):(max(theta_1) - min(theta_1))/8:max(theta_1);
            
            new_rho = interp1(theta_1,rho_1, theta_q);
            
            [x1 y1] = pol2cart(theta_q,new_rho);
            
            y_final_1 = y1 + centroid_1(2);
            x_final_1 = x1 + centroid_1(1);
            
            y_centroid_check_1 = mean(y_final_1);
            x_centroid_check_1 = mean(x_final_1);


            h2 = subplot(2,2,2);
            imshow(app.HounsFieldImageSeries(app.dcm_number_total).images, 'DisplayRange', [app.lower_level app.upper_level]);

            
            message = sprintf('Step 2: Left click and hold to begin drawing for the LAST image in the series.\nSimply lift the mouse button to finish.');
            text( 5, 30, message, 'color', 'r', 'FontSize', 12);
            
            uiwait(msgbox(message));

            hFH2 = drawpolygon();

            position_2 = hFH2.Position;
            centroid_2 = mean(position_2);


            [theta_2 rho_2] = cart2pol(position_2(:,1) - centroid_2(1), position_2(:,2) - centroid_2(2));
            
            theta_q = min(theta_2):(max(theta_2) - min(theta_2))/8:max(theta_2);
            
            new_rho = interp1(theta_2,rho_2, theta_q);
            
            [x2 y2] = pol2cart(theta_q,new_rho);
            
            y_final_2 = y2 + centroid_2(2);
            x_final_2 = x2 + centroid_2(1);
            
            y_centroid_check_2 = mean(y_final_2);
            x_centroid_check_2 = mean(x_final_2);


            h3 = subplot(2,2,[3,4]);
            imshow(app.HounsFieldImageSeries(1).images, 'DisplayRange', [app.lower_level app.upper_level]);
            set(fig, 'Position', get(0, 'Screensize'));  % Fullscreen image
            set(fig,'CloseRequestFcn','')                % Prevent pop-up from closing
            set(fig, 'MenuBar', 'none');
            set(fig, 'ToolBar', 'none');
            
            for dcm_idx = 2:(app.dcm_number_total - 1)
                    
                ROI_coord(:,1) = (x_final_2 - x_final_1) .* ((dcm_idx - 1)/(app.dcm_number_total - 1)) + x_final_1;
                ROI_coord(:,2) = (y_final_2 - y_final_1) .* ((dcm_idx - 1)/(app.dcm_number_total - 1)) + y_final_1;
                imshow(app.HounsFieldImageSeries(dcm_idx).images, 'DisplayRange', [app.lower_level app.upper_level]);
                
                propagation_ROI(dcm_idx).ROI = impoly(gca, ROI_coord);
                drawnow;
            end
            
%             delete(gcf);
            
            app.propagation = propagation_ROI;
            
            
        end
        
        function getROIpropagation_mask(app, first_dcm, last_dcm, ROI_propagation_mask, ROI_centroid)
        
            app.first_dcm_mask = first_dcm
            app.last_dcm_mask = last_dcm
            app.ROI_propagation_mask = ROI_propagation_mask;
            app.ROI_centroid = ROI_centroid;
        
        end
        
        function getROIsemiManual(app, first_dcm, last_dcm, seg_dataset, accept_seg)
            app.first_dcm_mask = first_dcm;
            app.last_dcm_mask = last_dcm;
            app.semiManualSeg_dataset = seg_dataset;
            app.IsSegAccepted = accept_seg;
        end
        
        function watershed_sec = watershed_sec_create(app, lumen_img, fib_img, nec_img, ca_img, centroid_info)
            
        
            [y, x] = size(lumen_img);
            min_x = 1;
            max_x = x;
            min_y = 1;
            max_y = y;
            
            
            mean_x = floor(centroid_info(2));
            mean_y = floor(centroid_info(1));
            
            % lumen sectors
            
            watershed_sec.lumen.sector_1 = lumen_img(mean_x:max_x, min_y:mean_y);
            watershed_sec.lumen.sector_2 = lumen_img(mean_x:max_x, (mean_y+1):max_y);
            
            watershed_sec.lumen.sector_3 = lumen_img(min_x:(mean_x-1), min_y:mean_y);
            watershed_sec.lumen.sector_4 = lumen_img(min_x:(mean_x-1), (mean_y+1):max_y);
            
            % fibrous plaque sectors
            
            watershed_sec.fib.sector_1 = fib_img(mean_x:max_x, min_y:mean_y);
            watershed_sec.fib.sector_2 = fib_img(mean_x:max_x, (mean_y+1):max_y);
            
            watershed_sec.fib.sector_3 = fib_img(min_x:(mean_x-1), min_y:mean_y);
            watershed_sec.fib.sector_4 = fib_img(min_x:(mean_x-1), (mean_y+1):max_y);
            
            % necrotic plaque sectors
            
            watershed_sec.nec.sector_1 = nec_img(mean_x:max_x, min_y:mean_y);
            watershed_sec.nec.sector_2 = nec_img(mean_x:max_x, (mean_y+1):max_y);
            
            watershed_sec.nec.sector_3 = nec_img(min_x:(mean_x-1), min_y:mean_y);
            watershed_sec.nec.sector_4 = nec_img(min_x:(mean_x-1), (mean_y+1):max_y);
            
            % calcium plaque sectors
            
            watershed_sec.ca.sector_1 = ca_img(mean_x:max_x, min_y:mean_y);
            watershed_sec.ca.sector_2 = ca_img(mean_x:max_x, (mean_y+1):max_y);
            
            watershed_sec.ca.sector_3 = ca_img(min_x:(mean_x-1), min_y:mean_y);
            watershed_sec.ca.sector_4 = ca_img(min_x:(mean_x-1), (mean_y+1):max_y);
        
        
        end
        
        
        function img = Sigmoid_function(app, Image, a, c)

        % a = 0.1;
        % c = 250;
        % 
        % I_sigmoid_lower = 1./(1 + exp(-a.*(my_pic-c)));
        % subplot(2,3,4)
        % imshow(I_sigmoid_lower, [])
        % title('Sigmoid Function Image, c=250 (lumen)')
        
            if(~exist('a','var')) 
                a = 0.1;        
            end
            
            if(~exist('c','var')) 
                c = 200;            
            end
        
            img_sigmoid = 1./(1 + exp(-a.*(Image - c)));
        %     subplot(2,3,5)
        %     imshow(img_sigmoid, [])
        %     title('Sigmoid Function Image, c=50 (vessel)')
        % 
        %     sgtitle("1A-2012-LCx: Slice#30",'Color','red', 'FontSize', 20)
            
            img = img_sigmoid;
    
        end
        
        
        %
        %
        %
        % ********************************************************************
        % Levelset algorithm functions
        % ********************************************************************
        
        function ROI_mask_levelset = init_levelset(app, Image, seed, seed_bound)

            if(~exist('seed_bound','var')) 
                seed_bound = 5;        
            end
            
            mask_bound_X = (seed(1) - seed_bound):(seed(1) + seed_bound);
            mask_bound_Y = (seed(2) - seed_bound):(seed(2) + seed_bound);
        
            I = double(Image);
            m = zeros(size(I,1),size(I,2));          %-- create initial mask
            m(mask_bound_Y,mask_bound_X) = 1;
        
            I = imresize(I,1);  %-- make image smaller 
            m = imresize(m,1);  %     for fast computation
        
%             figure;
%             subplot(2,2,1); imshow(I, []); title('Input Image');
%             subplot(2,2,2); imshow(m); title('Initialization');
%             subplot(2,2,3); title('Segmentation');
        % 
            seg = region_seg(app, I, m, 400, 0.01, false); %-- Run segmentation
        % 
%             subplot(2,2,4); imshow(seg); title('Global Region-Based Segmentation');
        
            ROI_mask_levelset = seg;
            
        end
        
        function seg = region_seg(app, I,init_mask,max_its,alpha,display)
          
          %-- default value for parameter alpha is .1
          if(~exist('alpha','var')) 
            alpha = .2; 
          end
          %-- default behavior is to display intermediate outputs
          if(~exist('display','var'))
            display = true;
          end
          %-- ensures image is 2D double matrix
%           I = im2graydouble(I);    
          
          %-- Create a signed distance map (SDF) from mask
          phi = mask2phi(app, init_mask);
          
          %--main loop
          for its = 1:max_its   % Note: no automatic convergence test
        
            idx = find(phi <= 1.2 & phi >= -1.2);  %get the curve's narrow band
            
            %-- find interior and exterior mean
            upts = find(phi<=0);                 % interior points
            vpts = find(phi>0);                  % exterior points
            u = sum(I(upts))/(length(upts)+eps); % interior mean
            v = sum(I(vpts))/(length(vpts)+eps); % exterior mean
            
            F = (I(idx)-u).^2-(I(idx)-v).^2;         % force from image information
            curvature = get_curvature(app, phi,idx);  % force from curvature penalty
            
            dphidt = F./max(abs(F)) + alpha*curvature;  % gradient descent to minimize energy
            
            %-- maintain the CFL condition
            dt = .45/(max(dphidt)+eps);
                
            %-- evolve the curve
            phi(idx) = phi(idx) + dt.*dphidt;
        
            %-- Keep SDF smooth
            phi = sussman(app, phi, .5);
        
            %-- intermediate output
            if((display>0)&&(mod(its,20) == 0)) 
              showCurveAndPhi(app, I,phi,its);  
            end
          end
          
          %-- final output
          if(display)
            showCurveAndPhi(app, I,phi,its);
          end
          
          %-- make mask from SDF
          seg = phi<=0; %-- Get mask from levelset
        end
          
        %---------------------------------------------------------------------
        %---------------------------------------------------------------------
        %-- AUXILIARY FUNCTIONS ----------------------------------------------
        %---------------------------------------------------------------------
        %---------------------------------------------------------------------
          
          
        %-- Displays the image with curve superimposed
        function showCurveAndPhi(app, I, phi, i)
        %   imshow(I,'initialmagnification',200,'displayrange',[0 255]); hold on;
          imshow(I,'initialmagnification',200,'displayrange',[]); hold on;
          contour(phi, [0 0], 'g','LineWidth',4);
          contour(phi, [0 0], 'k','LineWidth',2);
          hold off; title([num2str(i) ' Iterations']); drawnow;
        end 
        %-- converts a mask to a SDF
        function phi = mask2phi(app, init_a)
          phi=bwdist(init_a)-bwdist(1-init_a)+im2double(init_a)-.5;
        end
        %-- compute curvature along SDF
        function curvature = get_curvature(app, phi,idx)
            [dimy, dimx] = size(phi);        
            [y x] = ind2sub([dimy,dimx],idx);  % get subscripts
        
            %-- get subscripts of neighbors
            ym1 = y-1; xm1 = x-1; yp1 = y+1; xp1 = x+1;
        
            %-- bounds checking  
            ym1(ym1<1) = 1; xm1(xm1<1) = 1;              
            yp1(yp1>dimy)=dimy; xp1(xp1>dimx) = dimx;    
        
            %-- get indexes for 8 neighbors
            idup = sub2ind(size(phi),yp1,x);    
            iddn = sub2ind(size(phi),ym1,x);
            idlt = sub2ind(size(phi),y,xm1);
            idrt = sub2ind(size(phi),y,xp1);
            idul = sub2ind(size(phi),yp1,xm1);
            idur = sub2ind(size(phi),yp1,xp1);
            iddl = sub2ind(size(phi),ym1,xm1);
            iddr = sub2ind(size(phi),ym1,xp1);
            
            %-- get central derivatives of SDF at x,y
            phi_x  = -phi(idlt)+phi(idrt);
            phi_y  = -phi(iddn)+phi(idup);
            phi_xx = phi(idlt)-2*phi(idx)+phi(idrt);
            phi_yy = phi(iddn)-2*phi(idx)+phi(idup);
            phi_xy = -0.25*phi(iddl)-0.25*phi(idur)...
                     +0.25*phi(iddr)+0.25*phi(idul);
            phi_x2 = phi_x.^2;
            phi_y2 = phi_y.^2;
            
            %-- compute curvature (Kappa)
            curvature = ((phi_x2.*phi_yy + phi_y2.*phi_xx - 2*phi_x.*phi_y.*phi_xy)./...
                      (phi_x2 + phi_y2 +eps).^(3/2)).*(phi_x2 + phi_y2).^(1/2);        
        end
        %-- Converts image to one channel (grayscale) double
        function img = im2graydouble(app, img)    
          [dimy, dimx, c] = size(img);
          if(isfloat(img)) % image is a double
            if(c==3) 
              img = rgb2gray(uint8(img)); 
            end
          else           % image is a int
            if(c==3) 
              img = rgb2gray(img); 
            end
            img = double(img);
          end
        end
        %-- level set re-initialization by the sussman method
        function D = sussman(app, D, dt)
          % forward/backward differences
          a = D - shiftR(app, D); % backward
          b = shiftL(app, D) - D; % forward
          c = D - shiftD(app, D); % backward
          d = shiftU(app, D) - D; % forward
          
          a_p = a;  a_n = a; % a+ and a-
          b_p = b;  b_n = b;
          c_p = c;  c_n = c;
          d_p = d;  d_n = d;
          
          a_p(a < 0) = 0;
          a_n(a > 0) = 0;
          b_p(b < 0) = 0;
          b_n(b > 0) = 0;
          c_p(c < 0) = 0;
          c_n(c > 0) = 0;
          d_p(d < 0) = 0;
          d_n(d > 0) = 0;
          
          dD = zeros(size(D));
          D_neg_ind = find(D < 0);
          D_pos_ind = find(D > 0);
          dD(D_pos_ind) = sqrt(max(a_p(D_pos_ind).^2, b_n(D_pos_ind).^2) ...
                               + max(c_p(D_pos_ind).^2, d_n(D_pos_ind).^2)) - 1;
          dD(D_neg_ind) = sqrt(max(a_n(D_neg_ind).^2, b_p(D_neg_ind).^2) ...
                               + max(c_n(D_neg_ind).^2, d_p(D_neg_ind).^2)) - 1;
          
          D = D - dt .* sussman_sign(app, D) .* dD;
        end
        %-- whole matrix derivatives
        function shift = shiftD(app, M)
          shift = shiftR(app, M')';
        end
        
        function shift = shiftL(app, M)
          shift = [ M(:,2:size(M,2)) M(:,size(M,2)) ];
        end
        
        function shift = shiftR(app, M)
          shift = [ M(:,1) M(:,1:size(M,2)-1) ];
        end
        
        function shift = shiftU(app, M)
          shift = shiftL(app, M')';
        end
        
        function S = sussman_sign(app, D)
          S = D ./ sqrt(D.^2 + 1);  
        end


        function img = Laplacian_filter(app, Image, sigma, alpha)
        
            if(~exist('alpha','var')) 
                alpha = 0.1;            % Characterizes the amplitude of edges
            end
            
            if(~exist('sigma','var')) 
                sigma = 0.3;            % Controls smoothing of details
            end
            
        %     sigma = 0.3;            % Characterizes the amplitude of edges
        %     alpha = 0.1;              % Controls smoothing of details
        
            img_tmp = int8(Image);
        
            img_lap = locallapfilt(img_tmp, sigma, alpha);
        
            img_double = double(img_tmp);
            
            figure;
            subplot(1,3,1)
            imshow(Image, [])
            title('Hounsfield Image')
            subplot(1,3,2)
            imshow(img_lap)
            title('Laplacian Filter')
            subplot(1,3,3)
            imshow(img_double, [])
            title('Hounsfield Image (Double)')
            
            img = img_double;
            
        end
        
        
        function img = Sigmoid_function2(app, Image, a, c)
        
        % a = 0.1;
        % c = 250;
        % 
        % I_sigmoid_lower = 1./(1 + exp(-a.*(my_pic-c)));
        % subplot(2,3,4)
        % imshow(I_sigmoid_lower, [])
        % title('Sigmoid Function Image, c=250 (lumen)')
        
            if(~exist('a','var')) 
                a = 0.1;        
            end
            
            if(~exist('c','var')) 
                c = 20;            
            end
        
            img_sigmoid = 1./(1 + exp(-a.*(Image - c)));
        %     subplot(2,3,5)
        %     imshow(img_sigmoid, [])
        %     title('Sigmoid Function Image, c=50 (vessel)')
        % 
        %     sgtitle("1A-2012-LCx: Slice#30",'Color','red', 'FontSize', 20)
            
            img = img_sigmoid;
        
        end


        function [my_mask centroid] = create_mask_automatic(app, HUImage, lower_level, upper_level)

            HUImage_NOchange = HUImage;
            gmag = imgradient(HUImage);
            gmag_org = gmag;
        
            m = 2; % row
            n = 4; % col
        
%             subplot(m,n,1)
%             imshow(HUImage, [lower_level upper_level])
%             title('Initial Segment')
        
            gmag(gmag>700) = 50;
            gmag(gmag<50) = 50;
        
        
%             subplot(m,n,2)
        %     bw = imbinarize(HUImage, 'adaptive', 'Sensitivity', 0.2);
            bw = imbinarize(HUImage, 0.01);
            
            % Find the biggest blob in the image
            bw_filled = imfill(bw, 'holes');
            biggest_blob = bwareafilt(bw_filled, 1);
            
            my_mask = biggest_blob;
            
            
            count_lumen = 1;
            for x_idx = 1:size(HUImage,2)
                for y_idx = 1:size(HUImage,1)
        
                    if biggest_blob(x_idx, y_idx) ~= 0
                        coords_lumen(count_lumen,:) = [x_idx, y_idx];
                        count_lumen = count_lumen + 1;
                    end
        
                end
            end
        
            centroid = round(mean(coords_lumen));


%           
%             
%             
%             imshow(biggest_blob)
%             title('Binarized Image')
%         
%             subplot(m,n,3)
%             % se = strel('disk',4);
%             se = strel('disk',2);
%             seD = strel('diamond',1);
%             bw1 = imclose(gmag,se);
%             imshow(bw1, [])
%             title('Gradient Mag & imclose')
%         
%             subplot(m,n,4)
%         %     bw2 = imfill(bw1,'holes');
%             bw2 = imfill(gmag,'holes');
%             imshow(bw2, [])
%             title('fill holes')
%         
%             subplot(m,n,5)
%             lumen = bw2-bw1;
%             imshow(lumen, [])
%             title('Lumen: filled - imclose')
%         
%             subplot(m,n,6)
%             lumen(lumen<20) = 0;
%             imshow(lumen, [])
%             title('Only lumen: I<20 = 0')
%         
%         
%             subplot(m,n,7)
%             gmag_2 = imgradient(lumen);
%             % figure; imshow(gmag_2, [])
%             HUImage(~lumen) = -100;
%             imshow(HUImage, [])
%             title('Boundaries')


%             lumen_tmp = HUImage_NOchange;
%             
%             lumen_tmp(~biggest_blob) = -100;
%         
%             lumen_region = lumen_tmp>160 & lumen_tmp<400;
%         
%             lumen_tmp(~lumen_region) = -100;
            
%             subplot(m,n,8)
%             imshow(lumen_tmp, [])
%             title('lumen')
%         
%         
%             intensity = 0;
%             count =  1;
%             count_lumen = 1;
%             for x_idx = 1:size(HUImage,2)
%                 for y_idx = 1:size(HUImage,1)
%         
%                     if lumen_tmp(x_idx, y_idx) ~= -100
%                         intensity(count) = lumen_tmp(x_idx, y_idx);
%                         count = count + 1;
%                     end
%         
%                     if lumen_tmp(x_idx, y_idx) ~= -100
%                         coords_lumen(count_lumen,:) = [x_idx, y_idx];
%                         count_lumen = count_lumen + 1;
%                     end
%         
%                 end
%             end
%         
%             centroid = round(mean(coords_lumen))
%         
%             my_std = std(intensity)
%             my_mean = mean(intensity)
%             my_min = min(intensity)
%             my_max = max(intensity)
        
        end


        function [lower_bound upper_bound] = Calibrate_lumen_threshold(app, HU_Image, lower_level, upper_level)
        
        
            gmag = imgradient(HU_Image);
            
            bw = imbinarize(HU_Image, 0.01);
            bw_filled = imfill(bw, 'holes');
            biggest_blob = bwareafilt(bw_filled, 1);
            
            HU_Image(~biggest_blob) = -1000;
            
            gmag(~biggest_blob) = -1000;
            
            figure;
            artery_handle = subplot(3,2,1);
            imshow(double(HU_Image), []); title('Segmented Image')
            
            gmag_handle = subplot(3,2,2);
            imshow(gmag, [0 300]); title('Gradient Mag Image')
            
            
            [out,LM] = bwferet(biggest_blob,'all');
            x_feret_long = [out.MaxCoordinates{1}(1,1) out.MaxCoordinates{1}(2,1)];
            y_feret_long = [out.MaxCoordinates{1}(1,2) out.MaxCoordinates{1}(2,2)];
            
            x_feret_short = [out.MinCoordinates{1}(1,1) out.MinCoordinates{1}(2,1)];
            y_feret_short = [out.MinCoordinates{1}(1,2) out.MinCoordinates{1}(2,2)];
            
            imdistline(artery_handle,x_feret_long,y_feret_long);
            imdistline(artery_handle,x_feret_short,y_feret_short);
            
            p_long = polyfit(x_feret_long, y_feret_long, 1);
            p_short = polyfit(x_feret_short, y_feret_short, 1);
            
            x_long = linspace(x_feret_long(1),x_feret_long(2),50);
            y_long = polyval(p_long, x_long);
            
            x_short = linspace(x_feret_short(1),x_feret_short(2),50);
            y_short = polyval(p_short, x_short);
            
            [x_HUprofile_long, y_HUprofile_long, HU_profile_long] = improfile(HU_Image,x_feret_long,y_feret_long, 50);
            [x_GMAGprofile_long, y_GMAGprofile_long, gmag_profile_long] = improfile(gmag,x_feret_long,y_feret_long, 50);
            
            [x_HUprofile_short, y_HUprofile_short, HU_profile_short] = improfile(HU_Image,x_feret_short,y_feret_short, 50);
            [x_GMAGprofile_short, y_GMAGprofile_short, gmag_profile_short] = improfile(gmag,x_feret_short,y_feret_short, 50);
            
            subplot(3,2,3)
            plot(HU_profile_long(4:end-4,1),'r'); title('HU along the large diameter'); ylim([0 inf])
            
            h4 = subplot(3,2,4);
            plot(gmag_profile_long(4:end-4,1),'r'); title('GMAG along the large diameter'); ylim([0 inf])
            
            subplot(3,2,5)
            plot(HU_profile_short(4:end-4,1),'r'); title('HU along the small diameter'); ylim([0 inf])
            
            h6 = subplot(3,2,6);
            plot(gmag_profile_short(4:end-4,1),'r'); title('GMAG along the small diameter'); ylim([0 inf])
            
            [x_picked_long GMAG1] = getpts(h4);
            [x_picked_short GMAG2] = getpts(h6);
            
            x_long_coords = x_GMAGprofile_long(round(x_picked_long)');
            x_short_coords = x_GMAGprofile_short(round(x_picked_short)');
            
            y_long_coords = round(y_GMAGprofile_long(round(x_picked_long')));
            y_short_coords = round(y_GMAGprofile_short(round(x_picked_short')));
            
            Calibration_points = [round(x_long_coords') round(x_short_coords'); y_long_coords' y_short_coords'];
            
            for pt_idx = 1:4
                HU(pt_idx) = HU_Image(Calibration_points(2,pt_idx), Calibration_points(1,pt_idx));
            end
            
            HU_min = min(HU)
            HU_max = max(HU_Image, [], 'all')
            
            lower_bound = HU_min;
            upper_bound = HU_max;


        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, image_database, flag_imported_centerline)
        
            app.CallingApp = mainapp;
        
            app.lumen_min = round(app.LumenMinEditField.Value);
            app.lumen_max = round(app.LumenMaxEditField.Value);
            
            app.ca_plaque_min = round(app.CaMinHUEditField.Value);
            app.ca_plaque_max = round(app.CaMaxEditField.Value);
            
            app.fib_plaque_min = round(app.FibminEditField.Value);
            app.fib_plaque_max = round(app.FibmaxEditField.Value);
            
            app.necratic_min = round(app.NecroticMinEditField.Value);
            app.necratic_max = round(app.NecroticMaxEditField.Value);
            
            app.series_mask_success = false;
            
            if flag_imported_centerline == true
                app.flag_imported_centerline = flag_imported_centerline;
                
                
                app.HounsFieldImageSeriesVol = image_database.dcm_series_volume_HU;
                app.HounsFieldImageSeriesVol_scaled = image_database.dcm_series_volume_HU_scaled;
                
                assignin('base', 'V', image_database.slice_2D)
                
                for idx = 1:numel(fieldnames(image_database.slice_2D))
                    app.full_file_names_series(idx).names = image_database.sorted_filenames(idx);
                    app.slice_2D(idx).slice = image_database.slice_2D.(sprintf('idx_%d', idx));
                    app.slice_2D_zoomed(idx).slice = image_database.slice_2D_zoomed.(sprintf('idx_%d', idx));
                    app.HounsFieldImageSeries(idx).images = app.slice_2D_zoomed(idx).slice;
                end
                
                app.centerline = image_database.centerline;
                
                
                assignin('base', 'V', app.full_file_names_series)
                
                [dcm_image_series, dcm_number_total] = reorder_dcm_series(app, "");
                app.dcm_image_series = dcm_image_series;
                app.dcm_number_total = dcm_number_total;
                
                set(app.ScrollthroughImagesSlider, 'Enable', 'on');
                
                set(app.ScrollthroughImagesSlider, 'Limits', [1 app.dcm_number_total]);
                set(app.ScrollthroughImagesSlider, 'MinorTicks', [1:1:app.dcm_number_total])
                
                my_title = strcat('DICOM Image (#', '1',')');
                
                title(app.UImain_image, my_title)
                
                
                WL = round(app.WLEditField.Value);
                WW = round(app.WWEditField.Value);
    
                
                [app.lower_level, app.upper_level] = set_WW_WL(app, WW, WL);
                
                imshow(app.HounsFieldImageSeries(1).images,'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image)
                
                app.DrawROIButton.Enable = false;
                app.PropagateROIButton.Enable = true;
                app.StartButton.Enable = false;
                app.UpdateButton.Enable = false;
                
                
                app.lumen_min_seg(1:app.dcm_number_total) = round(app.LumenMinEditField.Value);
                app.lumen_max_seg(1:app.dcm_number_total) = round(app.LumenMaxEditField.Value);
                
                app.ca_plaque_min_seg(1:app.dcm_number_total) = round(app.CaMinHUEditField.Value);
                app.ca_plaque_max_seg(1:app.dcm_number_total) = round(app.CaMaxEditField.Value);
                
                app.fib_plaque_min_seg(1:app.dcm_number_total) = round(app.FibminEditField.Value);
                app.fib_plaque_max_seg(1:app.dcm_number_total) = round(app.FibmaxEditField.Value);
                
                app.necratic_min_seg(1:app.dcm_number_total) = round(app.NecroticMinEditField.Value);
                app.necratic_max_seg(1:app.dcm_number_total) = round(app.NecroticMaxEditField.Value);


            end
            
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            
            app.single_dcm_flag = true;
            app.series_dcm_flag = false;
            
            [file,path] = uigetfile('*.dcm');

            app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure.Visible = 'off';
            app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure.Visible = 'on';
            
            file_name_full = fullfile( path, file );
            app.full_file_name_single = file_name_full;
            app.dcm_image_single = dicomread( file_name_full );
            
            dicom_info = get_dicom_info(app, file_name_full);
            app.HounsFieldImageSingle = calculate_HU(app, app.dcm_image_single, dicom_info);
            
            set(app.ScrollthroughImagesSlider, 'Enable', 'off');
            
            my_title = strcat('DICOM Image (#', '1',')');
            
            title(app.UImain_image, my_title)


            WL = round(app.WLEditField.Value);
            WW = round(app.WWEditField.Value);

            
            [app.lower_level, app.upper_level] = set_WW_WL(app, WW, WL);
            
            imshow(app.HounsFieldImageSingle,'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image)
            
            
            app.DrawROIButton.Enable = true;
            app.PropagateROIButton.Enable = false;
            app.StartButton.Enable = false;
            app.UpdateButton.Enable = false;
            
            
%             if isequal(file,0)
%                disp('User selected Cancel');
%             else
%                disp(['User selected ', fullfile(path,file)]);
%             end
        end

        % Callback function
        function SingleFileCheckBoxValueChanged(app, event)
            value = app.SingleFileCheckBox.Value;
            if value==1
                set(handles.ImportsingledcmButtonPushed,'Enable','on')
                set(handles.SeriesCheckBoxValueChanged,'Enable','on')
            end
        end

        % Selection changed function: ImportdcmButtonGroup
        function ImportdcmButtonGroupSelectionChanged(app, event)
            selectedButton = app.ImportdcmButtonGroup.SelectedObject;
            if app.ImportsingledcmButton.Value == 1
                set(app.SelectdirectoryButton,'Enable','off')
                set(app.SelectfileButton,'Enable','on')
            end
            
            if app.ImportdcmseriesButton.Value == 1
                set(app.SelectfileButton,'Enable','off')
                set(app.SelectdirectoryButton,'Enable','on')

            end
            
        end

        % Button pushed function: SelectdirectoryButton
        function SelectdirectoryButtonPushed(app, event)
            
            app.single_dcm_flag = false;
            app.series_dcm_flag = true;
            set(app.ScrollthroughImagesSlider, 'Enable', 'on');
            
            dcm_dir = uigetdir;
            
            app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure.Visible = 'off';
            app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure.Visible = 'on';
            
            [dcm_image_series, dcm_number_total] = reorder_dcm_series(app, dcm_dir);
            app.dcm_image_series = dcm_image_series;
            app.dcm_number_total = dcm_number_total
            
            file_list = dir( fullfile( dcm_dir, '*.dcm') );
%             full_file_names_series = zeros(app.dcm_number_total);
            
            for dcm_idx = 1:dcm_number_total
                file_name_full = fullfile(file_list(dcm_idx).folder, file_list(dcm_idx).name );
                app.full_file_names_series(dcm_idx).names = file_name_full;
                dicom_info = get_dicom_info(app, file_name_full);
                app.HounsFieldImageSeries(dcm_idx).images = calculate_HU(app, app.dcm_image_series(dcm_idx).images, dicom_info);
            end
            
            % Initialize the checkbox for manual quant
            for dcm_idx = 1:dcm_number_total
                app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).DCP = 0;
                app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).LAP = 0;
                app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).PR = 0;
                app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).NRS = 0;
                app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).SC = 0;
                app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).SS = 0;
            end
            
            set(app.ScrollthroughImagesSlider, 'Limits', [1 app.dcm_number_total]);
            set(app.ScrollthroughImagesSlider, 'MinorTicks', [1:1:app.dcm_number_total])
            
            my_title = strcat('DICOM Image (#', '1',')');
            
            title(app.UImain_image, my_title)
            
%             set(app.UImain_image, 'Title.String', my_title )

            WL = round(app.WLEditField.Value);
            WW = round(app.WWEditField.Value);

            
            [app.lower_level, app.upper_level] = set_WW_WL(app, WW, WL);
            
            imshow(app.HounsFieldImageSeries(1).images,'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image)
            
            size(app.HounsFieldImageSeries(1).images)
            
            
            app.DrawROIButton.Enable = false;
            app.PropagateROIButton.Enable = true;
            app.StartButton.Enable = false;
            app.UpdateButton.Enable = false;
            
            
            app.lumen_min_seg(1:app.dcm_number_total) = round(app.LumenMinEditField.Value);
            app.lumen_max_seg(1:app.dcm_number_total) = round(app.LumenMaxEditField.Value);
            
            app.ca_plaque_min_seg(1:app.dcm_number_total) = round(app.CaMinHUEditField.Value);
            app.ca_plaque_max_seg(1:app.dcm_number_total) = round(app.CaMaxEditField.Value);
            
            app.fib_plaque_min_seg(1:app.dcm_number_total) = round(app.FibminEditField.Value);
            app.fib_plaque_max_seg(1:app.dcm_number_total) = round(app.FibmaxEditField.Value);
            
            app.necratic_min_seg(1:app.dcm_number_total) = round(app.NecroticMinEditField.Value);
            app.necratic_max_seg(1:app.dcm_number_total) = round(app.NecroticMaxEditField.Value);


            % Creating Tabs for each Segment in Report Panel
%             for dcm_idx = 2:dcm_number_total
%                 report_tab(dcm_idx) = uitab(app.TabGroup, 'Title', strcat('Seg.',num2str(dcm_idx)));
%                 app.UITable_seg(dcm_idx) = copyobj(app.UITable_seg1,report_tab(dcm_idx), 'legacy');
%             end
            
        end

        % Value changed function: ScrollthroughImagesSlider
        function ScrollthroughImagesSliderValueChanged(app, event)
            value = app.ScrollthroughImagesSlider.Value;
            
            % determine which discrete option the current value is closest to.
%             [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
%             % move the slider to that option
%             event.Source.Value = event.Source.MinorTicks(minIdx);
%             % Override the selected value if you plan on using it within this function
%             value = event.Source.MinorTicks(minIdx);
            
            event.Source.Value = round( value );
            
            value = round( value );
            mask_idx = value - app.first_dcm_mask + 1;
            
            if app.series_mask_success == false
            
                imshow(app.HounsFieldImageSeries(value).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image);
            
                title(app.UImain_image, strcat('DICOM Image (#', num2str(value) ,')') );
                
            elseif (app.series_mask_success == true) && (app.first_dcm_mask<= value) && (value <= app.last_dcm_mask )
                
                title(app.UImain_image, strcat('DICOM Image (#', num2str(value) ,')') );
                
                dicom_info = get_dicom_info(app, app.full_file_names_series(value).names);
                
                imshow(app.HounsFieldImageSeries(value).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image);
                
                masked_ROI = bsxfun(@times, app.HounsFieldImageSeries(value).images, cast(app.ROI_propagation_mask(value - app.first_dcm_mask + 1).masks, class(app.HounsFieldImageSeries(value).images)));
                imshow(masked_ROI,'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UIROI);
                
                
%                 masked_img_lumen = threshold_min_max(app, masked_ROI, app.lumen_min_seg(value), app.lumen_max_seg(value));
%             
%                 masked_img_fib_plaque = threshold_min_max(app, masked_ROI, app.fib_plaque_min_seg(value), app.fib_plaque_max_seg(value));
%                 
%                 masked_img_nec_plaque = threshold_min_max(app, masked_ROI, app.necratic_min_seg(value), app.necratic_max_seg(value));
%                 
%                 masked_img_calcium_plaque = threshold_min_max(app, masked_ROI, app.ca_plaque_min_seg(value), app.ca_plaque_max_seg(value));
                
                geom_features = seg_sectors(app, app.masked_img_lumen(mask_idx).mask);
                
%                 lumen_area = calc_ROI_area(app, dicom_info, masked_img_lumen);
%                 fib_area = calc_ROI_area(app, dicom_info, masked_img_fib_plaque);
%                 necrotic_area = calc_ROI_area(app, dicom_info, masked_img_nec_plaque);
%                 calcium_area = calc_ROI_area(app, dicom_info, masked_img_calcium_plaque);
%                 
%                 app.UITable_seg1.Data(1,1) = lumen_area;
%                 app.UITable_seg1.Data(2,1) = fib_area;
%                 app.UITable_seg1.Data(3,1) = necrotic_area;
%                 app.UITable_seg1.Data(4,1) = calcium_area;
                
                patch_ROI(app, app.masked_img_lumen(mask_idx).mask, app.HounsFieldImageSeries(value).images);
                
                multiple_overlay(app, app.masked_img_lumen(mask_idx).mask, app.masked_img_fib_plaque(mask_idx).mask, app.masked_img_nec_plaque(mask_idx).mask, app.masked_img_calcium_plaque(mask_idx).mask, app.HounsFieldImageSeries(value).images);
                
            else
                imshow(app.HounsFieldImageSeries(value).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image);
                imshow([],'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UIROI);
                imshow([],'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UILumen);
                imshow([],'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UIPlaqueQuant);
            end
            
        end

        % Button pushed function: SetWLWWButton
        function SetWLWWButtonPushed(app, event)
        
            WL = round(app.WLEditField.Value);
            WW = round(app.WWEditField.Value);
            
            [app.lower_level, app.upper_level] = set_WW_WL(app, WW, WL);
            
            if app.series_dcm_flag == true
                dcm_idx = app.ScrollthroughImagesSlider.Value;
                dcm_idx = round( dcm_idx );
                imshow(app.HounsFieldImageSeries(dcm_idx).images,'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image)
            elseif app.single_dcm_flag == true
                imshow(app.HounsFieldImageSingle,'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image)
            end
            
        end

        % Value changed function: DrawROIButton
        function DrawROIButtonValueChanged(app, event)
            value = app.DrawROIButton.Value;
            
            dicom_info = get_dicom_info(app, app.full_file_name_single);
            
            masked_ROI = draw_ROI(app, app.HounsFieldImageSingle);
            
            masked_img_lumen = threshold_min_max(app, masked_ROI, app.lumen_min, app.lumen_max);
            
            masked_img_fib_plaque = threshold_min_max(app, masked_ROI, app.fib_plaque_min, app.fib_plaque_max);
            
            masked_img_nec_plaque = threshold_min_max(app, masked_ROI, app.necratic_min, app.necratic_max);
            
            masked_img_calcium_plaque = threshold_min_max(app, masked_ROI, app.ca_plaque_min, app.ca_plaque_max);
            
            geom_features = seg_sectors(app, masked_img_lumen);
            
            lumen_area = calc_ROI_area(app, dicom_info, masked_img_lumen);
            fib_area = calc_ROI_area(app, dicom_info, masked_img_fib_plaque);
            necrotic_area = calc_ROI_area(app, dicom_info, masked_img_nec_plaque);
            calcium_area = calc_ROI_area(app, dicom_info, masked_img_calcium_plaque);


            app.UITable_seg1.Data(1,1) = lumen_area;
            app.UITable_seg1.Data(2,1) = fib_area;
            app.UITable_seg1.Data(3,1) = necrotic_area;
            app.UITable_seg1.Data(4,1) = calcium_area;
            
            patch_ROI(app, masked_img_lumen, app.HounsFieldImageSingle);
            
            multiple_overlay(app, masked_img_lumen, masked_img_fib_plaque, masked_img_nec_plaque, masked_img_calcium_plaque, app.HounsFieldImageSingle);
            
            
            sector_flag = true;
            if sector_flag == true
                segment_sectors = create_sectors(app, masked_ROI, geom_features.Centroid);
                
                lumen_sectors = threshold_min_max(app, segment_sectors, app.lumen_min, app.lumen_max);
                lumen_area_sectors = calc_ROI_area(app, dicom_info, lumen_sectors);
                
                fib_plaque_sectors = threshold_min_max(app, segment_sectors, app.fib_plaque_min, app.fib_plaque_max);
                fib_plaque_area_sectors = calc_ROI_area(app, dicom_info, fib_plaque_sectors);
                
                nec_plaque_sectors = threshold_min_max(app, segment_sectors, app.necratic_min, app.necratic_max);
                nec_plaque_area_sectors = calc_ROI_area(app, dicom_info, nec_plaque_sectors);
                
                calcium_plaque_sectors = threshold_min_max(app, segment_sectors, app.ca_plaque_min, app.ca_plaque_max);
                calcium_plaque_area_sectors = calc_ROI_area(app, dicom_info, calcium_plaque_sectors);
                
                app.UITable_seg1.Data(1,1:5) = lumen_area_sectors;
                app.UITable_seg1.Data(2,1:5) = fib_plaque_area_sectors;
                app.UITable_seg1.Data(3,1:5) = nec_plaque_area_sectors;
                app.UITable_seg1.Data(4,1:5) = calcium_plaque_area_sectors;
                
                
%                 area_error = abs(lumen_area - (lumen_area_sectors.sec1 + lumen_area_sectors.sec2 + lumen_area_sectors.sec3 + lumen_area_sectors.sec4))


            end


        end

        % Button pushed function: SetPQParamButton
        function SetPQParamButtonPushed(app, event)
        
            if app.ApplyonlytocurrentsegmentCheckBox.Value == 0
            
                app.lumen_min = round(app.LumenMinEditField.Value);
                app.lumen_max = round(app.LumenMaxEditField.Value);
                
                app.ca_plaque_min = round(app.CaMinHUEditField.Value);
                app.ca_plaque_max = round(app.CaMaxEditField.Value);
                
                app.fib_plaque_min = round(app.FibminEditField.Value);
                app.fib_plaque_max = round(app.FibmaxEditField.Value);
                
                app.necratic_min = round(app.NecroticMinEditField.Value);
                app.necratic_max = round(app.NecroticMaxEditField.Value);
                
                
                app.lumen_min_seg(1:app.dcm_number_total) = round(app.LumenMinEditField.Value);
                app.lumen_max_seg(1:app.dcm_number_total) = round(app.LumenMaxEditField.Value);
                
                app.ca_plaque_min_seg(1:app.dcm_number_total) = round(app.CaMinHUEditField.Value);
                app.ca_plaque_max_seg(1:app.dcm_number_total) = round(app.CaMaxEditField.Value);
                
                app.fib_plaque_min_seg(1:app.dcm_number_total) = round(app.FibminEditField.Value);
                app.fib_plaque_max_seg(1:app.dcm_number_total) = round(app.FibmaxEditField.Value);
                
                app.necratic_min_seg(1:app.dcm_number_total) = round(app.NecroticMinEditField.Value);
                app.necratic_max_seg(1:app.dcm_number_total) = round(app.NecroticMaxEditField.Value);


            elseif app.ApplyonlytocurrentsegmentCheckBox.Value == 1
                
                app.lumen_min_seg(app.ScrollthroughImagesSlider.Value) = round(app.LumenMinEditField.Value);
                app.lumen_max_seg(app.ScrollthroughImagesSlider.Value) = round(app.LumenMaxEditField.Value);
                
                app.ca_plaque_min_seg(app.ScrollthroughImagesSlider.Value) = round(app.CaMinHUEditField.Value);
                app.ca_plaque_max_seg(app.ScrollthroughImagesSlider.Value) = round(app.CaMaxEditField.Value);
                
                app.fib_plaque_min_seg(app.ScrollthroughImagesSlider.Value) = round(app.FibminEditField.Value);
                app.fib_plaque_max_seg(app.ScrollthroughImagesSlider.Value) = round(app.FibmaxEditField.Value);
                
                app.necratic_min_seg(app.ScrollthroughImagesSlider.Value) = round(app.NecroticMinEditField.Value);
                app.necratic_max_seg(app.ScrollthroughImagesSlider.Value) = round(app.NecroticMaxEditField.Value);


                dcm_idx = app.ScrollthroughImagesSlider.Value;
                mask_idx = dcm_idx - app.first_dcm_mask + 1;
                
                if ( (app.series_mask_success == true) && (app.first_dcm_mask <= dcm_idx) &&  (dcm_idx<= app.last_dcm_mask) )
                                        
                    dicom_info = get_dicom_info(app, app.full_file_names_series(dcm_idx).names);
                    
                    imshow(app.HounsFieldImageSeries(dcm_idx).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image);
                    
                    masked_ROI(mask_idx).mask = bsxfun(@times, app.HounsFieldImageSeries(dcm_idx).images, cast(app.ROI_propagation_mask(mask_idx).masks, class(app.HounsFieldImageSeries(dcm_idx).images)));
                    
                    
                    masked_img_lumen(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, app.lumen_min_seg(dcm_idx), app.lumen_max_seg(dcm_idx));
                
                    masked_img_fib_plaque(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, app.fib_plaque_min_seg(dcm_idx), app.fib_plaque_max_seg(dcm_idx));
                    
                    masked_img_nec_plaque(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, app.necratic_min_seg(dcm_idx), app.necratic_max_seg(dcm_idx));
                    
                    masked_img_calcium_plaque(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, app.ca_plaque_min_seg(dcm_idx), app.ca_plaque_max_seg(dcm_idx));
                    
                    geom_features = seg_sectors(app, masked_img_lumen(mask_idx).mask);
                    
                    lumen_area = calc_ROI_area(app, dicom_info, masked_img_lumen(mask_idx).mask);
                    fib_area = calc_ROI_area(app, dicom_info, masked_img_fib_plaque(mask_idx).mask);
                    necrotic_area = calc_ROI_area(app, dicom_info, masked_img_nec_plaque(mask_idx).mask);
                    calcium_area = calc_ROI_area(app, dicom_info, masked_img_calcium_plaque(mask_idx).mask);


                    app.report_data(1,5) = lumen_area;
                    app.report_data(2,5) = fib_area;
                    app.report_data(3,5) = necrotic_area;
                    app.report_data(4,5) = calcium_area;
                    
                    
                    patch_ROI(app, masked_img_lumen(mask_idx).mask, app.HounsFieldImageSeries(dcm_idx).images);
                    
                    multiple_overlay(app, masked_img_lumen(mask_idx).mask, masked_img_fib_plaque(mask_idx).mask, masked_img_nec_plaque(mask_idx).mask, masked_img_calcium_plaque(mask_idx).mask, app.HounsFieldImageSeries(dcm_idx).images);


                    segment_sectors = create_sectors(app, masked_ROI(mask_idx).mask, geom_features.Centroid);
                    
                    lumen_sectors = threshold_min_max(app, segment_sectors, app.lumen_min, app.lumen_max);
                    lumen_area_sectors = calc_ROI_area(app, dicom_info, lumen_sectors);
                    
                    fib_plaque_sectors = threshold_min_max(app, segment_sectors, app.fib_plaque_min, app.fib_plaque_max);
                    fib_plaque_area_sectors = calc_ROI_area(app, dicom_info, fib_plaque_sectors);
                    
                    nec_plaque_sectors = threshold_min_max(app, segment_sectors, app.necratic_min, app.necratic_max);
                    nec_plaque_area_sectors = calc_ROI_area(app, dicom_info, nec_plaque_sectors);
                    
                    calcium_plaque_sectors = threshold_min_max(app, segment_sectors, app.ca_plaque_min, app.ca_plaque_max);
                    calcium_plaque_area_sectors = calc_ROI_area(app, dicom_info, calcium_plaque_sectors);

                    app.report_data(1,1:5) = lumen_area_sectors;
                    app.report_data(2,1:5) = fib_plaque_area_sectors;
                    app.report_data(3,1:5) = nec_plaque_area_sectors;
                    app.report_data(4,1:5) = calcium_plaque_area_sectors;


                    mask_idx = mask_idx + 1;
                    
                end 
                
%                 report_data
                
                if dcm_idx == 1
                    set(app.UITable_seg1, 'Data' , app.report_data);
                else
                    set(app.UITable_seg(dcm_idx), 'Data' , app.report_data);
                end


            end
                
            
        end

        % Menu selected function: PropagateROIMenu
        function PropagateROIMenuSelected(app, event)
%             propagate_ROI(app);
            
            
        end

        % Button pushed function: QuantifyButton
        function QuantifyButtonPushed(app, event)
%             app.propagationWindow = PropagateROI(app, app.dcm_image_series, app.dcm_number_total, app.HounsFieldImageSeries, app.lower_level, app.upper_level);
            
            app.StatusLamp.Color = [1 0 0];
            app.LampLabel.Text = "Processing the images ...";
            drawnow;
            
            use_sigmoid = 0;
            use_levelset = 0;
            use_semi_manual = 1;
            if app.UseadaptivethresholdCheckBox.Value == 0 % Manual threshold for plq quant


                mask_idx = 1;
                for dcm_idx = 1:app.dcm_number_total
                    
                    
                    app.report_data = zeros(8,5);
                    % Creating Tabs for each Segment in Report Panel
                    if dcm_idx>1
                        app.report_tab(dcm_idx) = uitab(app.TabGroup, 'Title', strcat('Seg. ',num2str(dcm_idx)));
                        app.UITable_seg(dcm_idx) = copyobj(app.UITable_seg1,app.report_tab(dcm_idx), 'legacy');
                    end
                    
                    if ( (app.series_mask_success == true) && (app.first_dcm_mask <= dcm_idx) &&  (dcm_idx<= app.last_dcm_mask) )
                                            
                        dicom_info = get_dicom_info(app, app.full_file_names_series(dcm_idx).names);
                        
                        imshow(app.HounsFieldImageSeries(dcm_idx).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image);
                        
                        masked_ROI(mask_idx).mask = bsxfun(@times, app.HounsFieldImageSeries(dcm_idx).images, cast(app.ROI_propagation_mask(mask_idx).masks, class(app.HounsFieldImageSeries(dcm_idx).images)));
                        
                        
                        app.masked_img_lumen(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, app.lumen_min, app.lumen_max);
                    
                        app.masked_img_fib_plaque(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, app.fib_plaque_min, app.fib_plaque_max);
                        
                        app.masked_img_nec_plaque(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, app.necratic_min, app.necratic_max);
                        
                        app.masked_img_calcium_plaque(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, app.ca_plaque_min, app.ca_plaque_max);
                        
                        geom_features = seg_sectors(app, app.masked_img_lumen(mask_idx).mask);
                        
                        lumen_area = calc_ROI_area(app, dicom_info, app.masked_img_lumen(mask_idx).mask);
                        fib_area = calc_ROI_area(app, dicom_info, app.masked_img_fib_plaque(mask_idx).mask);
                        necrotic_area = calc_ROI_area(app, dicom_info, app.masked_img_nec_plaque(mask_idx).mask);
                        calcium_area = calc_ROI_area(app, dicom_info, app.masked_img_calcium_plaque(mask_idx).mask);


                        app.report_data(1,5) = lumen_area;
                        app.report_data(2,5) = necrotic_area;
                        app.report_data(3,5) = fib_area;
                        app.report_data(4,5) = calcium_area;
                        app.report_data(5,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).PR;
                        app.report_data(6,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).NRS;
                        app.report_data(7,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).SC;
                        app.report_data(8,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).SS;
                        
                        
                        patch_ROI(app, app.masked_img_lumen(mask_idx).mask, app.HounsFieldImageSeries(dcm_idx).images);
                        
                        multiple_overlay(app, app.masked_img_lumen(mask_idx).mask, app.masked_img_fib_plaque(mask_idx).mask, app.masked_img_nec_plaque(mask_idx).mask, app.masked_img_calcium_plaque(mask_idx).mask, app.HounsFieldImageSeries(dcm_idx).images);


                        segment_sectors = create_sectors(app, masked_ROI(mask_idx).mask, geom_features.Centroid);
                        
                        lumen_sectors = threshold_min_max(app, segment_sectors, app.lumen_min, app.lumen_max);
                        lumen_area_sectors = calc_ROI_area(app, dicom_info, lumen_sectors);
                        
                        fib_plaque_sectors = threshold_min_max(app, segment_sectors, app.fib_plaque_min, app.fib_plaque_max);
                        fib_plaque_area_sectors = calc_ROI_area(app, dicom_info, fib_plaque_sectors);
                        
                        nec_plaque_sectors = threshold_min_max(app, segment_sectors, app.necratic_min, app.necratic_max);
                        nec_plaque_area_sectors = calc_ROI_area(app, dicom_info, nec_plaque_sectors);
                        
                        calcium_plaque_sectors = threshold_min_max(app, segment_sectors, app.ca_plaque_min, app.ca_plaque_max);
                        calcium_plaque_area_sectors = calc_ROI_area(app, dicom_info, calcium_plaque_sectors);
    
                        app.report_data(1,1:5) = lumen_area_sectors;
                        app.report_data(2,1:5) = nec_plaque_area_sectors;
                        app.report_data(3,1:5) = fib_plaque_area_sectors;
                        app.report_data(4,1:5) = calcium_plaque_area_sectors;


                        mask_idx = mask_idx + 1;
                        
                    end 
                
%                 report_data
                
                    if dcm_idx == 1
                        set(app.UITable_seg1, 'Data' , app.report_data);
                    else
                        set(app.UITable_seg(dcm_idx), 'Data' , app.report_data);
                        drawnow;
                    end
                    
                end
                
            % ***********************************************************
            % Adaptive threshold 
            % ***********************************************************
                
            elseif app.UseadaptivethresholdCheckBox.Value == 1 % adaptive threshold for plq quant
                
%                 assignin('base','plq_quant_flags',app.plq_quant_flags)
                if use_sigmoid == 1
                    for dcm_idx = 1:app.dcm_number_total
                        
                        % ******************************************************
                        % Apply Sigmoid function on the image
                        sig_img = Sigmoid_function(app, app.HounsFieldImageSeries(dcm_idx).images, 0.2, 180);
                        
                        % ******************************************************
                        % Calculate the mean lumen intensity
                        HUImage = app.HounsFieldImageSeries(dcm_idx).images;
                        count = 1;
                        for x_idx = 1:size(HUImage,2)
                            for y_idx = 1:size(HUImage,1)
                    
                                if sig_img(y_idx, x_idx) == 1
                                    lumen_pix(count) = HUImage(y_idx, x_idx);
                                    count = count + 1;
                                end
                    
                            end
                        end
                    
                        lumen_ave(dcm_idx) = mean(lumen_pix);
                        % ******************************************************
                        
                    end
                end
                
                if use_levelset == 1
                    
                    mask_idx = 1;
                    for dcm_idx = 1:app.dcm_number_total
                        % ******************************************************
                        % Calculate the mean lumen intensity
                        
                        if ( (app.series_mask_success == true) && (app.first_dcm_mask <= dcm_idx) &&  (dcm_idx<= app.last_dcm_mask) )
                        
                            HUImage = app.lumen_levelset(mask_idx).images;
                            count = 1;
                            for x_idx = 1:size(HUImage,2)
                                for y_idx = 1:size(HUImage,1)
                        
                                    if HUImage(y_idx, x_idx) ~= -100
                                        lumen_pix(count) = HUImage(y_idx, x_idx);
                                        count = count + 1;
                                    end
                        
                                end
                            end
                        
                            lumen_ave(mask_idx) = mean(lumen_pix);
                            mask_idx = mask_idx + 1;
                        end
                        % ******************************************************
                    
                    end
                end
                
                if use_semi_manual == 1


                    mask_idx = 1;
                    for dcm_idx = 1:app.dcm_number_total
                        
                        app.report_data = zeros(8,5);
    %                     Creating Tabs for each Segment in Report Panel
                        if dcm_idx>1
                            app.report_tab(dcm_idx) = uitab(app.TabGroup, 'Title', strcat('Seg. ',num2str(dcm_idx)));
                            app.UITable_seg(dcm_idx) = copyobj(app.UITable_seg1,app.report_tab(dcm_idx), 'legacy');
                        end
                        
                        if ( (app.series_mask_success == true) && (app.first_dcm_mask <= dcm_idx) &&  (dcm_idx<= app.last_dcm_mask) && (app.IsSegAccepted(dcm_idx) == 1 ) )
                            
                            dicom_info = get_dicom_info(app, app.full_file_names_series(dcm_idx).names);
                            
                            imshow(app.HounsFieldImageSeries(dcm_idx).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image);
                            
                            masked_ROI(mask_idx).mask = bsxfun(@times, app.HounsFieldImageSeries(dcm_idx).images, cast(app.semiManualSeg_dataset(dcm_idx).ROI_Mask, class(app.HounsFieldImageSeries(dcm_idx).images)));
                            
                            
                            % ******************************************************
                            % Calculate the HU cut-offs
                            calib = 1;
                            if calib == 0
                                %% Nectoric Core
                                NC_upperBound = max(min(polyval(lumen_est_polyfit,mask_idx) - 200, 75), 50)
        %                         if SeverestenosisCheckBoxValueChanged.Value == 1
                                if app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).SS == 1
                                    NC_upperBound = NC_upperBound - 1.25*abs(lumen_ave(mask_idx) - polyval(lumen_est_polyfit,mask_idx))
                                end
                                NC_lowerBound = -1;
                                
                                % Dense Calcium
                                DC_lowerBound = max(polyval(lumen_est_polyfit,mask_idx) +100, app.lumen_bounds_calib(2))
        %                         if DCPCheckBoxValueChanged.Value == 1
                                if app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).DCP == 1
                                    DC_lowerBound = DC_lowerBound + 0.25*abs(lumen_ave(mask_idx) - polyval(lumen_est_polyfit,mask_idx));
                                end
                                DC_upperBound = 1200
                                
                                % Lumen
                                Lumen_upperBound = DC_lowerBound - 1
                                Lumen_lowerBound = max(polyval(lumen_est_polyfit,mask_idx) - (Lumen_upperBound - polyval(lumen_est_polyfit,mask_idx)), app.lumen_bounds_calib(1))
                                
                                
                                % Fibrotic and fibrofatty tissues
                                FI_FF_cutoff = 0.2*(DC_lowerBound - NC_upperBound);
                                
                                fib_lowerBound = NC_upperBound + 1
                                fib_upperBound = Lumen_lowerBound - 1
                            else
                                Lumen_upperBound = app.semiManualSeg_dataset(dcm_idx).Lumen_MAX;
                                Lumen_lowerBound = app.semiManualSeg_dataset(dcm_idx).Lumen_MIN;
                                
                                NC_upperBound = max(min(app.semiManualSeg_dataset(dcm_idx).Lumen_AVE - 200, 75), 50);
                                NC_lowerBound = -1;
                                
                                fib_lowerBound = NC_upperBound + 1;
                                fib_upperBound = Lumen_lowerBound - 1;
                                
                                DC_lowerBound = max(app.semiManualSeg_dataset(dcm_idx).Lumen_AVE +100, app.semiManualSeg_dataset(dcm_idx).Lumen_MAX);
                                DC_upperBound = 1200;


                            end
                            
                            
                            app.masked_img_lumen(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, Lumen_lowerBound, Lumen_upperBound);
                        
                            app.masked_img_fib_plaque(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, fib_lowerBound, fib_upperBound);
                            
                            app.masked_img_nec_plaque(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, NC_lowerBound, NC_upperBound);
                            
                            app.masked_img_calcium_plaque(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, DC_lowerBound, DC_upperBound);
                            
                            geom_features = seg_sectors(app, app.masked_img_lumen(mask_idx).mask);
                            
                            lumen_area = calc_ROI_area(app, dicom_info, app.masked_img_lumen(mask_idx).mask);
                            fib_area = calc_ROI_area(app, dicom_info, app.masked_img_fib_plaque(mask_idx).mask);
                            necrotic_area = calc_ROI_area(app, dicom_info, app.masked_img_nec_plaque(mask_idx).mask);
                            calcium_area = calc_ROI_area(app, dicom_info, app.masked_img_calcium_plaque(mask_idx).mask);


                            app.report_data(1,5) = lumen_area;
                            app.report_data(2,5) = necrotic_area;
                            app.report_data(3,5) = fib_area;
                            app.report_data(4,5) = calcium_area;
                            app.report_data(5,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).PR;
                            app.report_data(6,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).NRS;
                            app.report_data(7,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).SC;
                            app.report_data(8,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).SS;
                            
                            
                            patch_ROI(app, app.masked_img_lumen(mask_idx).mask, app.HounsFieldImageSeries(dcm_idx).images);
                            
                            multiple_overlay(app, app.masked_img_lumen(mask_idx).mask, app.masked_img_fib_plaque(mask_idx).mask, app.masked_img_nec_plaque(mask_idx).mask, app.masked_img_calcium_plaque(mask_idx).mask, app.HounsFieldImageSeries(dcm_idx).images);


                            segment_sectors = create_sectors(app, masked_ROI(mask_idx).mask, geom_features.Centroid);
                            
                            lumen_sectors = threshold_min_max(app, segment_sectors, Lumen_lowerBound, Lumen_upperBound);
                            lumen_area_sectors = calc_ROI_area(app, dicom_info, lumen_sectors);
                            
                            fib_plaque_sectors = threshold_min_max(app, segment_sectors, fib_lowerBound, fib_upperBound);
                            fib_plaque_area_sectors = calc_ROI_area(app, dicom_info, fib_plaque_sectors);
                            
                            nec_plaque_sectors = threshold_min_max(app, segment_sectors, NC_lowerBound, NC_upperBound);
                            nec_plaque_area_sectors = calc_ROI_area(app, dicom_info, nec_plaque_sectors);
                            
                            calcium_plaque_sectors = threshold_min_max(app, segment_sectors, DC_lowerBound, DC_upperBound);
                            calcium_plaque_area_sectors = calc_ROI_area(app, dicom_info, calcium_plaque_sectors);
        
                            app.report_data(1,1:5) = lumen_area_sectors;
                            app.report_data(2,1:5) = nec_plaque_area_sectors;
                            app.report_data(3,1:5) = fib_plaque_area_sectors;
                            app.report_data(4,1:5) = calcium_plaque_area_sectors;
    %                         app.report_data(5,1:4) = 0;
    %                         app.report_data(6,1:4) = 0;
    %                         app.report_data(7,1:4) = 0;
    %                         app.report_data(8,1:4) = 0;
                            drawnow;
    
                            
                            mask_idx = mask_idx + 1;
                            
                        end
                        
                        if dcm_idx == 1
                            set(app.UITable_seg1, 'Data' , app.report_data);
                        else
                            set(app.UITable_seg(dcm_idx), 'Data' , app.report_data);
                            drawnow;
                        end
                        drawnow;
                        
                        
                    end
                    
                    
                end % END OF USE SEMI MANUAL
                
                old_quant = 1;
                if old_quant == 1
                
                    % ******************************************************
                    % Calculate the trendline to the data
                    slice_idx = 1:(mask_idx - 1);
                    lumen_est_polyfit = polyfit(slice_idx, lumen_ave, 1);
                    
                    assignin('base', 'lumen_est_polyfit', lumen_est_polyfit)
                    
                    % ******************************************************
                    % Quantification
                
                
                    mask_idx = 1;
                    for dcm_idx = 1:app.dcm_number_total
                        
                        app.report_data = zeros(8,5);
    %                     Creating Tabs for each Segment in Report Panel
                        if dcm_idx>1
                            app.report_tab(dcm_idx) = uitab(app.TabGroup, 'Title', strcat('Seg. ',num2str(dcm_idx)));
                            app.UITable_seg(dcm_idx) = copyobj(app.UITable_seg1,app.report_tab(dcm_idx), 'legacy');
                        end
                        
                        if ( (app.series_mask_success == true) && (app.first_dcm_mask <= dcm_idx) &&  (dcm_idx<= app.last_dcm_mask) )
                            
                            dicom_info = get_dicom_info(app, app.full_file_names_series(dcm_idx).names);
                            
                            imshow(app.HounsFieldImageSeries(dcm_idx).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image);
                            
                            masked_ROI(mask_idx).mask = bsxfun(@times, app.HounsFieldImageSeries(dcm_idx).images, cast(app.ROI_propagation_mask(mask_idx).masks, class(app.HounsFieldImageSeries(dcm_idx).images)));
                            
                            
                            % ******************************************************
                            % Calculate the HU cut-offs
                            calib = 0;
                            if calib == 0
                                %% Nectoric Core
                                NC_upperBound = max(min(polyval(lumen_est_polyfit,mask_idx) - 200, 75), 50)
        %                         if SeverestenosisCheckBoxValueChanged.Value == 1
                                if app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).SS == 1
                                    NC_upperBound = NC_upperBound - 1.25*abs(lumen_ave(mask_idx) - polyval(lumen_est_polyfit,mask_idx))
                                end
                                NC_lowerBound = -1
                                
                                % Dense Calcium
                                DC_lowerBound = max(polyval(lumen_est_polyfit,mask_idx) +100, app.lumen_bounds_calib(2))
        %                         if DCPCheckBoxValueChanged.Value == 1
                                if app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).DCP == 1
                                    DC_lowerBound = DC_lowerBound + 0.25*abs(lumen_ave(mask_idx) - polyval(lumen_est_polyfit,mask_idx));
                                end
                                DC_upperBound = 1200
                                
                                % Lumen
                                Lumen_upperBound = DC_lowerBound - 1
                                Lumen_lowerBound = max(polyval(lumen_est_polyfit,mask_idx) - (Lumen_upperBound - polyval(lumen_est_polyfit,mask_idx)), app.lumen_bounds_calib(1))
                                
                                
                                % Fibrotic and fibrofatty tissues
                                FI_FF_cutoff = 0.2*(DC_lowerBound - NC_upperBound);
                                
                                fib_lowerBound = NC_upperBound + 1
                                fib_upperBound = Lumen_lowerBound - 1
                            else
                                Lumen_upperBound = app.semiManualSeg_dataset(mask_idx).Lumen_MAX;
                                Lumen_lowerBound = app.semiManualSeg_dataset(mask_idx).Lumen_MIN;
                                
                            end
                            
                            
                            app.masked_img_lumen(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, Lumen_lowerBound, Lumen_upperBound);
                        
                            app.masked_img_fib_plaque(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, fib_lowerBound, fib_upperBound);
                            
                            app.masked_img_nec_plaque(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, NC_lowerBound, NC_upperBound);
                            
                            app.masked_img_calcium_plaque(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, DC_lowerBound, DC_upperBound);
                            
                            geom_features = seg_sectors(app, app.masked_img_lumen(mask_idx).mask);
                            
                            lumen_area = calc_ROI_area(app, dicom_info, app.masked_img_lumen(mask_idx).mask);
                            fib_area = calc_ROI_area(app, dicom_info, app.masked_img_fib_plaque(mask_idx).mask);
                            necrotic_area = calc_ROI_area(app, dicom_info, app.masked_img_nec_plaque(mask_idx).mask);
                            calcium_area = calc_ROI_area(app, dicom_info, app.masked_img_calcium_plaque(mask_idx).mask);


                            app.report_data(1,5) = lumen_area;
                            app.report_data(2,5) = necrotic_area;
                            app.report_data(3,5) = fib_area;
                            app.report_data(4,5) = calcium_area;
                            app.report_data(5,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).PR;
                            app.report_data(6,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).NRS;
                            app.report_data(7,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).SC;
                            app.report_data(8,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).SS;
                            
                            
                            patch_ROI(app, app.masked_img_lumen(mask_idx).mask, app.HounsFieldImageSeries(dcm_idx).images);
                            
                            multiple_overlay(app, app.masked_img_lumen(mask_idx).mask, app.masked_img_fib_plaque(mask_idx).mask, app.masked_img_nec_plaque(mask_idx).mask, app.masked_img_calcium_plaque(mask_idx).mask, app.HounsFieldImageSeries(dcm_idx).images);


                            segment_sectors = create_sectors(app, masked_ROI(mask_idx).mask, geom_features.Centroid);
                            
                            lumen_sectors = threshold_min_max(app, segment_sectors, Lumen_lowerBound, Lumen_upperBound);
                            lumen_area_sectors = calc_ROI_area(app, dicom_info, lumen_sectors);
                            
                            fib_plaque_sectors = threshold_min_max(app, segment_sectors, fib_lowerBound, fib_upperBound);
                            fib_plaque_area_sectors = calc_ROI_area(app, dicom_info, fib_plaque_sectors);
                            
                            nec_plaque_sectors = threshold_min_max(app, segment_sectors, NC_lowerBound, NC_upperBound);
                            nec_plaque_area_sectors = calc_ROI_area(app, dicom_info, nec_plaque_sectors);
                            
                            calcium_plaque_sectors = threshold_min_max(app, segment_sectors, DC_lowerBound, DC_upperBound);
                            calcium_plaque_area_sectors = calc_ROI_area(app, dicom_info, calcium_plaque_sectors);
        
                            app.report_data(1,1:5) = lumen_area_sectors;
                            app.report_data(2,1:5) = nec_plaque_area_sectors;
                            app.report_data(3,1:5) = fib_plaque_area_sectors;
                            app.report_data(4,1:5) = calcium_plaque_area_sectors;
    %                         app.report_data(5,1:4) = 0;
    %                         app.report_data(6,1:4) = 0;
    %                         app.report_data(7,1:4) = 0;
    %                         app.report_data(8,1:4) = 0;
                            drawnow;
    
                            
                            mask_idx = mask_idx + 1;
                            
                        end
                        
                        if dcm_idx == 1
                            set(app.UITable_seg1, 'Data' , app.report_data);
                        else
                            set(app.UITable_seg(dcm_idx), 'Data' , app.report_data);
                            drawnow;
                        end
                        drawnow;
                        
                        
                    end
                    
                end
                
                
            end
            
            app.StartButton.Enable = true;
            app.StatusLamp.Color = [0 1 0];
            app.LampLabel.Text = "Quantification process done!";
            drawnow;
            app.ExportButton.Enable = true;
            drawnow;

        end

        % Value changed function: PropagateROIButton
        function PropagateROIButtonValueChanged(app, event)
            value = app.PropagateROIButton.Value;
            
            semi_auto_segmentation_flag = 0;
            
            if semi_auto_segmentation_flag == 1
                app.propagationWindow = semi_auto_segmentation(app, app.dcm_image_series, app.dcm_number_total, app.HounsFieldImageSeries, app.lower_level, app.upper_level);
            else


                if app.AutoSegmentationCheckBox.Value == 0
                
                    if app.UseGradientofImagesCheckBox.Value == 1
                        
                        for idx = 1:app.dcm_number_total
                            gmag(idx).images = imgradient(app.HounsFieldImageSeries(idx).images);
                        end
                        
                        app.propagationWindow = PropagateROI(app, app.dcm_image_series, app.dcm_number_total, gmag, app.lower_level, app.upper_level);
                        
                    else
                        app.propagationWindow = PropagateROI(app, app.dcm_image_series, app.dcm_number_total, app.HounsFieldImageSeries, app.lower_level, app.upper_level);
                        
        %                 uiwait;
        %                 % find lumen using Levelset algorithm
        %                 
        %                 assignin('base', 'centroids', app.ROI_centroid)
        %                 
        %                 mask_idx = 1;
        %                 for dcm_idx = 1:app.dcm_number_total
        %                     if ( (app.first_dcm_mask <= dcm_idx) &&  (dcm_idx<= app.last_dcm_mask) )
        %                         gmag = imgradient(app.HounsFieldImageSeries(dcm_idx).images);
        %                         binaryImage = imclearborder(gmag);
        %                         se = strel('disk',3);
        %                         closeBW = imclose(binaryImage,se);
        %                         seg = init_levelset(app, gmag, app.ROI_centroid(mask_idx).centeroid, 2);
        %                         se = strel('disk',8);
        %                         closeBW2 = imclose(seg,se);
        %                         lumen_mask = double(closeBW2) - double(seg);
        %                         
        %                         img_tmp = app.HounsFieldImageSeries(dcm_idx).images;
        %                         img_tmp(~BW2) = -100;
        %                         app.lumen_levelset(mask_idx).images = img_tmp;
        %                         
        %                         mask_idx = mask_idx + 1;
        %                         
        %                     end
        %                 end
                        
                    end
                    
                else
                    
                    start_idx = round(app.FromEditField.Value);
                    end_idx = round(app.ToEditField.Value);
                    
                    count = 1;
                    for idx = start_idx:end_idx
                        [app.ROI_propagation_mask(count).masks app.ROI_centroid(count).centeroid] = create_mask_automatic(app, app.HounsFieldImageSeries(idx).images, app.lower_level, app.upper_level);
                        count = count + 1;
                    end
                    
                    
                end


                app.first_dcm_mask = start_idx;
                app.last_dcm_mask = end_idx;
                
            end
            
%             app.PropagateROIButton.Enable = false;
            app.series_mask_success = true;
            app.LumenButton.Enable = true;
          
            
        end

        % Value changed function: ApplyonlytocurrentsegmentCheckBox
        function ApplyonlytocurrentsegmentCheckBoxValueChanged(app, event)
            value = app.ApplyonlytocurrentsegmentCheckBox.Value;
            
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            
            
            app.watershed_ax = axes('Parent', app.VisualizationPanel, 'Position', [0.7 0.1 0.25 0.3])
            
            imshow(app.HounsFieldImageSeries(1).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.watershed_ax);
            
            dcm_idx = app.ScrollthroughImagesSlider.Value;
            mask_idx = dcm_idx - app.first_dcm_mask + 1;
            
            app.report_data = zeros(8,5);
            
            dicom_info = get_dicom_info(app, app.full_file_names_series(dcm_idx).names);
                                
%             masked_ROI(mask_idx).mask = bsxfun(@times, app.HounsFieldImageSeries(dcm_idx).images, cast(app.ROI_propagation_mask(mask_idx).masks, class(app.HounsFieldImageSeries(dcm_idx).images)));
%                         
%             masked_img_lumen(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, app.lumen_min_seg(dcm_idx), app.lumen_max_seg(dcm_idx));
%             
%             masked_img_fib_plaque(mask_idx).mask = threshold_min_max(app, masked_ROI(mask_idx).mask, app.fib_plaque_min_seg(dcm_idx), app.fib_plaque_max_seg(dcm_idx));
                    
            watershed_calcium = watershed_separation(app, app.masked_img_lumen(mask_idx).mask, app.masked_img_fib_plaque(mask_idx).mask, app.HounsFieldImageSeries(dcm_idx).images, dicom_info);
            
            app.masked_img_lumen(mask_idx).mask = watershed_calcium.lumen;
            app.masked_img_fib_plaque(mask_idx).mask = watershed_calcium.fib;
            
            
            geom_features = seg_sectors(app, app.masked_img_lumen(mask_idx).mask);
                    
            lumen_area = calc_ROI_area(app, dicom_info, app.masked_img_lumen(mask_idx).mask);
            fib_area = calc_ROI_area(app, dicom_info, app.masked_img_fib_plaque(mask_idx).mask);
            necrotic_area = calc_ROI_area(app, dicom_info, app.masked_img_nec_plaque(mask_idx).mask);
            calcium_area = calc_ROI_area(app, dicom_info, app.masked_img_calcium_plaque(mask_idx).mask);


            app.report_data(1,5) = lumen_area;
            app.report_data(2,5) = necrotic_area;
            app.report_data(3,5) = fib_area;
            app.report_data(4,5) = calcium_area;
            app.report_data(5,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).PR;
            app.report_data(6,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).NRS;
            app.report_data(7,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).SC;
            app.report_data(8,5) = app.plq_quant_flags.(sprintf('center_point_%d', dcm_idx)).SS;
            
            
            patch_ROI(app, app.masked_img_lumen(mask_idx).mask, app.HounsFieldImageSeries(dcm_idx).images);
            
            multiple_overlay(app, app.masked_img_lumen(mask_idx).mask, app.masked_img_fib_plaque(mask_idx).mask, app.masked_img_nec_plaque(mask_idx).mask, app.masked_img_calcium_plaque(mask_idx).mask, app.HounsFieldImageSeries(dcm_idx).images);


%             masked_ROI(mask_idx).mask = bsxfun(@times, app.HounsFieldImageSeries(dcm_idx).images, cast(app.ROI_propagation_mask(mask_idx).masks, class(app.HounsFieldImageSeries(dcm_idx).images)));
%             segment_sectors = create_sectors(app, masked_ROI(mask_idx).mask, geom_features.Centroid);

            watershed_sec = watershed_sec_create(app, app.masked_img_lumen(mask_idx).mask, app.masked_img_fib_plaque(mask_idx).mask, app.masked_img_nec_plaque(mask_idx).mask, app.masked_img_calcium_plaque(mask_idx).mask, watershed_calcium.centroid.Centroid)
            
%             figure;
%             subplot(2,2,1)
%             imshow(segment_sectors.sec1, [])
%             subplot(2,2,2)
%             imshow(segment_sectors.sec2, [])
%             subplot(2,2,3)
%             imshow(segment_sectors.sec3, [])
%             subplot(2,2,4)
%             imshow(segment_sectors.sec4, [])
            
%             lumen_sectors = threshold_min_max(app, segment_sectors, app.lumen_min_seg, app.lumen_max_seg);
            lumen_sectors = watershed_sec.lumen;
            lumen_area_sectors = calc_ROI_area(app, dicom_info, lumen_sectors);
            
%             fib_plaque_sectors = threshold_min_max(app, segment_sectors, app.fib_plaque_min_seg, app.fib_plaque_max_seg);
            fib_plaque_sectors = watershed_sec.fib;
            fib_plaque_area_sectors = calc_ROI_area(app, dicom_info, fib_plaque_sectors);
            
%             nec_plaque_sectors = threshold_min_max(app, segment_sectors, app.necratic_min_seg, app.necratic_max_seg);
            nec_plaque_sectors = watershed_sec.nec;
            nec_plaque_area_sectors = calc_ROI_area(app, dicom_info, nec_plaque_sectors);
            
%             calcium_plaque_sectors = threshold_min_max(app, segment_sectors, app.ca_plaque_min_seg, app.ca_plaque_max_seg);
            calcium_plaque_sectors = watershed_sec.ca;
            calcium_plaque_area_sectors = calc_ROI_area(app, dicom_info, calcium_plaque_sectors);

            app.report_data(1,1:5) = lumen_area_sectors;
            app.report_data(2,1:5) = nec_plaque_area_sectors;
            app.report_data(3,1:5) = fib_plaque_area_sectors;
            app.report_data(4,1:5) = calcium_plaque_area_sectors;
            
            if dcm_idx == 1
                set(app.UITable_seg1, 'Data' , app.report_data);
            else
                set(app.UITable_seg(dcm_idx), 'Data' , app.report_data);
            end
            drawnow;
            
            
        end

        % Button pushed function: ResetquantificationButton
        function ResetquantificationButtonPushed(app, event)
            for tab_idx = 2:app.dcm_number_total
                
                delete(app.UITable_seg(tab_idx));
                delete(app.report_tab(tab_idx));
                
            end
        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)
            app.ScrollthroughImagesSlider.Value = app.ScrollthroughImagesSlider.Value + 1;
            value = app.ScrollthroughImagesSlider.Value;
            
            mask_idx = value - app.first_dcm_mask + 1;
            
            if app.series_mask_success == false
            
                imshow(app.HounsFieldImageSeries(value).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image);
            
                title(app.UImain_image, strcat('DICOM Image (#', num2str(value) ,')') );
                
            elseif (app.series_mask_success == true) && (app.first_dcm_mask<= value) && (value <= app.last_dcm_mask )
                
                % Changing the checkbox based on the slice id
                app.DCPCheckBox.Value = app.plq_quant_flags.(sprintf('center_point_%d', value)).DCP;
                app.LAPCheckBox.Value = app.plq_quant_flags.(sprintf('center_point_%d', value)).LAP;
                app.PositiveremodelingCheckBox.Value = app.plq_quant_flags.(sprintf('center_point_%d', value)).PR;
                app.NRSCheckBox.Value = app.plq_quant_flags.(sprintf('center_point_%d', value)).NRS;
                app.SpottyCalcificationCheckBox.Value = app.plq_quant_flags.(sprintf('center_point_%d', value)).SC;
                app.SeverestenosisCheckBox.Value = app.plq_quant_flags.(sprintf('center_point_%d', value)).SS;
                
                title(app.UImain_image, strcat('DICOM Image (#', num2str(value) ,')') );
                
                dicom_info = get_dicom_info(app, app.full_file_names_series(value).names);
                
                imshow(app.HounsFieldImageSeries(value).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image);
                
                masked_ROI = bsxfun(@times, app.HounsFieldImageSeries(value).images, cast(app.ROI_propagation_mask(value - app.first_dcm_mask + 1).masks, class(app.HounsFieldImageSeries(value).images)));
                imshow(masked_ROI,'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UIROI);
                
                
%                 masked_img_lumen = threshold_min_max(app, masked_ROI, app.lumen_min_seg(value), app.lumen_max_seg(value));
%             
%                 masked_img_fib_plaque = threshold_min_max(app, masked_ROI, app.fib_plaque_min_seg(value), app.fib_plaque_max_seg(value));
%                 
%                 masked_img_nec_plaque = threshold_min_max(app, masked_ROI, app.necratic_min_seg(value), app.necratic_max_seg(value));
%                 
%                 masked_img_calcium_plaque = threshold_min_max(app, masked_ROI, app.ca_plaque_min_seg(value), app.ca_plaque_max_seg(value));
                
                geom_features = seg_sectors(app, app.masked_img_lumen(mask_idx).mask);
                
%                 lumen_area = calc_ROI_area(app, dicom_info, masked_img_lumen);
%                 fib_area = calc_ROI_area(app, dicom_info, masked_img_fib_plaque);
%                 necrotic_area = calc_ROI_area(app, dicom_info, masked_img_nec_plaque);
%                 calcium_area = calc_ROI_area(app, dicom_info, masked_img_calcium_plaque);
%                 
%                 app.UITable_seg1.Data(1,1) = lumen_area;
%                 app.UITable_seg1.Data(2,1) = fib_area;
%                 app.UITable_seg1.Data(3,1) = necrotic_area;
%                 app.UITable_seg1.Data(4,1) = calcium_area;
                
                patch_ROI(app, app.masked_img_lumen(mask_idx).mask, app.HounsFieldImageSeries(value).images);
                
                multiple_overlay(app, app.masked_img_lumen(mask_idx).mask, app.masked_img_fib_plaque(mask_idx).mask, app.masked_img_nec_plaque(mask_idx).mask, app.masked_img_calcium_plaque(mask_idx).mask, app.HounsFieldImageSeries(value).images);
                
            else
                imshow(app.HounsFieldImageSeries(value).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image);
                imshow([],'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UIROI);
                imshow([],'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UILumen);
                imshow([],'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UIPlaqueQuant);
            end
        end

        % Button pushed function: PreviousImageButton
        function PreviousImageButtonPushed(app, event)
            app.ScrollthroughImagesSlider.Value = app.ScrollthroughImagesSlider.Value - 1;
            value = app.ScrollthroughImagesSlider.Value;
            
            mask_idx = value - app.first_dcm_mask + 1;
            
            if app.series_mask_success == false
            
                imshow(app.HounsFieldImageSeries(value).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image);
            
                title(app.UImain_image, strcat('DICOM Image (#', num2str(value) ,')') );
                
            elseif (app.series_mask_success == true) && (app.first_dcm_mask<= value) && (value <= app.last_dcm_mask )
                
                % Changing the checkbox based on the slice id
                app.DCPCheckBox.Value = app.plq_quant_flags.(sprintf('center_point_%d', value)).DCP;
                app.LAPCheckBox.Value = app.plq_quant_flags.(sprintf('center_point_%d', value)).LAP;
                app.PositiveremodelingCheckBox.Value = app.plq_quant_flags.(sprintf('center_point_%d', value)).PR;
                app.NRSCheckBox.Value = app.plq_quant_flags.(sprintf('center_point_%d', value)).NRS;
                app.SpottyCalcificationCheckBox.Value = app.plq_quant_flags.(sprintf('center_point_%d', value)).SC;
                app.SeverestenosisCheckBox.Value = app.plq_quant_flags.(sprintf('center_point_%d', value)).SS;
                
                title(app.UImain_image, strcat('DICOM Image (#', num2str(value) ,')') );
                
                dicom_info = get_dicom_info(app, app.full_file_names_series(value).names);
                
                imshow(app.HounsFieldImageSeries(value).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image);
                
                masked_ROI = bsxfun(@times, app.HounsFieldImageSeries(value).images, cast(app.ROI_propagation_mask(value - app.first_dcm_mask + 1).masks, class(app.HounsFieldImageSeries(value).images)));
                imshow(masked_ROI,'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UIROI);
                
                
%                 masked_img_lumen = threshold_min_max(app, masked_ROI, app.lumen_min_seg(value), app.lumen_max_seg(value));
%             
%                 masked_img_fib_plaque = threshold_min_max(app, masked_ROI, app.fib_plaque_min_seg(value), app.fib_plaque_max_seg(value));
%                 
%                 masked_img_nec_plaque = threshold_min_max(app, masked_ROI, app.necratic_min_seg(value), app.necratic_max_seg(value));
%                 
%                 masked_img_calcium_plaque = threshold_min_max(app, masked_ROI, app.ca_plaque_min_seg(value), app.ca_plaque_max_seg(value));
                
                geom_features = seg_sectors(app, app.masked_img_lumen(mask_idx).mask);
                
%                 lumen_area = calc_ROI_area(app, dicom_info, masked_img_lumen);
%                 fib_area = calc_ROI_area(app, dicom_info, masked_img_fib_plaque);
%                 necrotic_area = calc_ROI_area(app, dicom_info, masked_img_nec_plaque);
%                 calcium_area = calc_ROI_area(app, dicom_info, masked_img_calcium_plaque);
%                 
%                 app.UITable_seg1.Data(1,1) = lumen_area;
%                 app.UITable_seg1.Data(2,1) = fib_area;
%                 app.UITable_seg1.Data(3,1) = necrotic_area;
%                 app.UITable_seg1.Data(4,1) = calcium_area;
                
                patch_ROI(app, app.masked_img_lumen(mask_idx).mask, app.HounsFieldImageSeries(value).images);
                
                multiple_overlay(app, app.masked_img_lumen(mask_idx).mask, app.masked_img_fib_plaque(mask_idx).mask, app.masked_img_nec_plaque(mask_idx).mask, app.masked_img_calcium_plaque(mask_idx).mask, app.HounsFieldImageSeries(value).images);
                
            else
                imshow(app.HounsFieldImageSeries(value).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UImain_image);
                imshow([],'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UIROI);
                imshow([],'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UILumen);
                imshow([],'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.UIPlaqueQuant);
            end
        end

        % Value changed function: ExportButton
        function ExportButtonValueChanged(app, event)
            value = app.ExportButton.Value;
            [file, path] = uiputfile("*.xlsx");
            file_name = fullfile(path,file);
            
            row_names = {'lumen', 'nec', 'fib', 'ca', 'PR', 'NRS', 'SC', 'SS'};
            column_names = {'fist_sec', 'second_sec', 'third_sec', 'fourth_sec', 'total'};
            
            for dcm_idx = 1:app.dcm_number_total
                if dcm_idx == 1
                    dataset_export.(sprintf("slice_idx%d", dcm_idx)) = get(app.UITable_seg1, 'Data');
                else
%                 dataset_export.(sprintf("slice_idx%d", dcm_idx)) = app.UITable_seg(dcm_idx).Data;
                    dataset_export.(sprintf("slice_idx%d", dcm_idx)) = get(app.UITable_seg(dcm_idx), 'Data');
                end
                
                my_table = array2table(dataset_export.(sprintf("slice_idx%d", dcm_idx)), 'VariableNames',column_names, 'RowNames', row_names);
%                 writecell(dataset_export.(sprintf("slice_idx%d", dcm_idx)), file_name, 'Sheet', strcat('slice_idx',num2str(dcm_idx)))
                writetable(my_table, file_name, 'WriteRowNames',true, 'Sheet', strcat('slice_idx',num2str(dcm_idx)))
            end
            
%             assignin('base','my_dataset_tra',dataset_export)
            
        end

        % Value changed function: UseadaptivethresholdCheckBox
        function UseadaptivethresholdCheckBoxValueChanged(app, event)
            value = app.UseadaptivethresholdCheckBox.Value;
            
        end

        % Value changed function: SeverestenosisCheckBox
        function SeverestenosisCheckBoxValueChanged(app, event)
            value = app.SeverestenosisCheckBox.Value;
            
        end

        % Value changed function: DCPCheckBox
        function DCPCheckBoxValueChanged(app, event)
            value = app.DCPCheckBox.Value;
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            slice_idx = app.ScrollthroughImagesSlider.Value;
            
            app.plq_quant_flags.(sprintf('center_point_%d', slice_idx)).DCP = double(app.DCPCheckBox.Value);
            app.plq_quant_flags.(sprintf('center_point_%d', slice_idx)).LAP = double(app.LAPCheckBox.Value);
            app.plq_quant_flags.(sprintf('center_point_%d', slice_idx)).PR = double(app.PositiveremodelingCheckBox.Value);
            app.plq_quant_flags.(sprintf('center_point_%d', slice_idx)).NRS = double(app.NRSCheckBox.Value);
            app.plq_quant_flags.(sprintf('center_point_%d', slice_idx)).SC = double(app.SpottyCalcificationCheckBox.Value);
            app.plq_quant_flags.(sprintf('center_point_%d', slice_idx)).SS = double(app.SeverestenosisCheckBox.Value);
            
        end

        % Button pushed function: LumenButton
        function LumenButtonPushed(app, event)
            
        
            app.StatusLamp.Color = [1 0 0];
            app.LampLabel.Text = "Calculating the lumen ...";
            drawnow;
            
            levelset_lumen = 0;
            
            assignin('base', 'centroids', app.ROI_centroid)
            
            if levelset_lumen == 1
            
                mask_idx = 1;
                for dcm_idx = 1:app.dcm_number_total
                    if ( (app.first_dcm_mask <= dcm_idx) &&  (dcm_idx<= app.last_dcm_mask) )
                        gmag = imgradient(app.HounsFieldImageSeries(dcm_idx).images);
                        binaryImage = imclearborder(gmag);
                        se = strel('disk',3);
                        closeBW = imclose(binaryImage,se);
                        seg = init_levelset(app, gmag, app.ROI_centroid(mask_idx).centeroid, 2);
                        se = strel('disk',8);
                        closeBW2 = imclose(seg,se);
                        lumen_mask = double(closeBW2) - double(seg);
                        
                        BW2 = bwareafilt(logical(lumen_mask),1);
                        
                        img_tmp = app.HounsFieldImageSeries(dcm_idx).images;
                        img_tmp(~BW2) = -100;
                        app.lumen_levelset(mask_idx).images = img_tmp;
                        
                        mask_idx = mask_idx + 1;
                        
                    end
                end
                
            else
                
                mask_idx = 1;
                for dcm_idx = 1:app.dcm_number_total
                    
                    if ( (app.first_dcm_mask <= dcm_idx) &&  (dcm_idx<= app.last_dcm_mask) )
                        
                        lumen_tmp = app.HounsFieldImageSeries(dcm_idx).images;
                        
                        lumen_tmp(~app.ROI_propagation_mask(mask_idx).masks) = -100;
                    
                        lumen_region = lumen_tmp>=app.lumen_bounds_calib(1) & lumen_tmp<=app.lumen_bounds_calib(2);
                    
                        lumen_tmp(~lumen_region) = -100;
                        
                        app.lumen_levelset(mask_idx).images = lumen_tmp;
                        mask_idx = mask_idx + 1;
                        
                    end
                    
                end
                
            end
                
            app.StatusLamp.Color = [0 1 0];
            app.LampLabel.Text = "Lumen created successfully!";
            drawnow;


        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            
        
            first_slice = round(app.FirstImageEditField.Value);
            last_slice = round(app.LastImageEditField.Value);
            
            HU_Image1 = app.HounsFieldImageSeries(first_slice).images;
            HU_Image2 = app.HounsFieldImageSeries(last_slice).images;
            
            [lower_bound_calib(1), upper_bound_calib(1)] = Calibrate_lumen_threshold(app, HU_Image1, app.lower_level, app.upper_level);
            [lower_bound_calib(2), upper_bound_calib(2)] = Calibrate_lumen_threshold(app, HU_Image2, app.lower_level, app.upper_level);
            
            app.lumen_bounds_calib = [max(min(lower_bound_calib), 150) max(upper_bound_calib)];
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure = uifigure('Visible', 'off');
            app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure.Name = 'Automated Interactive Plaque Quantification AIPQ';
            app.ExportMenu = uimenu(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure);
            app.ExportMenu.Text = 'Export';
            app.Menu_2 = uimenu(app.ExportMenu);
            app.Menu_2.Text = 'Menu';
            app.ToolsMenu = uimenu(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.PropagateROIMenu = uimenu(app.ToolsMenu);
            app.PropagateROIMenu.MenuSelectedFcn = createCallbackFcn(app, @PropagateROIMenuSelected, true);
            app.PropagateROIMenu.Text = 'Propagate ROI';
            app.VisualizationPanel = uipanel(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.UIROI = uiaxes(app.VisualizationPanel);
            title(app.UIROI, 'ROI')
            xlabel(app.UIROI, '')
            ylabel(app.UIROI, '')
            app.UIPlaqueQuant = uiaxes(app.VisualizationPanel);
            title(app.UIPlaqueQuant, 'Plaque Quant.')
            xlabel(app.UIPlaqueQuant, '')
            ylabel(app.UIPlaqueQuant, '')
            app.UILumen = uiaxes(app.VisualizationPanel);
            title(app.UILumen, 'Lumen')
            xlabel(app.UILumen, '')
            ylabel(app.UILumen, '')
            app.UImain_image = uiaxes(app.VisualizationPanel);
            title(app.UImain_image, 'DICOM Image')
            xlabel(app.UImain_image, '')
            ylabel(app.UImain_image, '')
            app.UImain_image.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ScrollthroughImagesSliderLabel = uilabel(app.VisualizationPanel);
            app.ScrollthroughImagesSliderLabel.Text = 'Scroll through Images';
            app.ScrollthroughImagesSlider = uislider(app.VisualizationPanel);
            app.ScrollthroughImagesSlider.ValueChangedFcn = createCallbackFcn(app, @ScrollthroughImagesSliderValueChanged, true);
            app.ScrollthroughImagesSlider.MinorTicks = [];
            app.NextImageButton = uibutton(app.VisualizationPanel, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Text = '>';
            app.PreviousImageButton = uibutton(app.VisualizationPanel, 'push');
            app.PreviousImageButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousImageButtonPushed, true);
            app.PreviousImageButton.Text = '<';
            app.ImportdcmButtonGroup = uibuttongroup(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure);
            app.ImportdcmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImportdcmButtonGroupSelectionChanged, true);
            app.ImportdcmButtonGroup.Title = 'Import .dcm';
            app.ImportsingledcmButton = uiradiobutton(app.ImportdcmButtonGroup);
            app.ImportsingledcmButton.Text = 'Import single .dcm';
            app.ImportsingledcmButton.Value = true;
            app.ImportdcmseriesButton = uiradiobutton(app.ImportdcmButtonGroup);
            app.ImportdcmseriesButton.Text = 'Import .dcm series';
            app.SelectfileButton = uibutton(app.ImportdcmButtonGroup, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.SelectdirectoryButton = uibutton(app.ImportdcmButtonGroup, 'push');
            app.SelectdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdirectoryButtonPushed, true);
            app.SelectdirectoryButton.Enable = 'off';
            app.SelectdirectoryButton.Text = 'Select directory';
            app.PlaqueQuantificationParametersPanel = uipanel(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure);
            app.PlaqueQuantificationParametersPanel.Title = 'Plaque Quantification Parameters';
            app.LumenHULabel = uilabel(app.PlaqueQuantificationParametersPanel);
            app.LumenHULabel.Text = 'Lumen HU:';
            app.LumenMinEditField = uieditfield(app.PlaqueQuantificationParametersPanel, 'numeric');
            app.LumenMinEditField.Value = 250;
            app.NecroticMaxEditField = uieditfield(app.PlaqueQuantificationParametersPanel, 'numeric');
            app.NecroticMaxEditField.Value = 105;
            app.toEditField_2Label = uilabel(app.PlaqueQuantificationParametersPanel);
            app.toEditField_2Label.Text = 'to';
            app.CaPLQHULabel = uilabel(app.PlaqueQuantificationParametersPanel);
            app.CaPLQHULabel.Text = 'Ca PLQ HU:';
            app.CaMinHUEditField = uieditfield(app.PlaqueQuantificationParametersPanel, 'numeric');
            app.CaMinHUEditField.Value = 500;
            app.CaMaxEditField = uieditfield(app.PlaqueQuantificationParametersPanel, 'numeric');
            app.CaMaxEditField.Value = 900;
            app.toEditField_3Label = uilabel(app.PlaqueQuantificationParametersPanel);
            app.toEditField_3Label.Text = 'to';
            app.LumenMaxEditField = uieditfield(app.PlaqueQuantificationParametersPanel, 'numeric');
            app.LumenMaxEditField.Value = 460;
            app.toEditField_4Label = uilabel(app.PlaqueQuantificationParametersPanel);
            app.toEditField_4Label.Text = 'to';
            app.NecrCoreHULabel = uilabel(app.PlaqueQuantificationParametersPanel);
            app.NecrCoreHULabel.Text = 'Necr. Core HU:';
            app.NecroticMinEditField = uieditfield(app.PlaqueQuantificationParametersPanel, 'numeric');
            app.NecroticMinEditField.Value = 10;
            app.SetPQParamButton = uibutton(app.PlaqueQuantificationParametersPanel, 'push');
            app.SetPQParamButton.ButtonPushedFcn = createCallbackFcn(app, @SetPQParamButtonPushed, true);
            app.SetPQParamButton.Text = 'Set';
            app.FIBPLQHULabel = uilabel(app.PlaqueQuantificationParametersPanel);
            app.FIBPLQHULabel.Text = 'FIB PLQ HU:';
            app.FibminEditField = uieditfield(app.PlaqueQuantificationParametersPanel, 'numeric');
            app.FibminEditField.Value = 110;
            app.FibmaxEditField = uieditfield(app.PlaqueQuantificationParametersPanel, 'numeric');
            app.FibmaxEditField.Value = 245;
            app.toLabel = uilabel(app.PlaqueQuantificationParametersPanel);
            app.toLabel.Text = 'to';
            app.ApplyonlytocurrentsegmentCheckBox = uicheckbox(app.PlaqueQuantificationParametersPanel);
            app.ApplyonlytocurrentsegmentCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyonlytocurrentsegmentCheckBoxValueChanged, true);
            app.ApplyonlytocurrentsegmentCheckBox.Text = 'Apply only to current segment';
            app.UseadaptivethresholdCheckBox = uicheckbox(app.PlaqueQuantificationParametersPanel);
            app.UseadaptivethresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseadaptivethresholdCheckBoxValueChanged, true);
            app.UseadaptivethresholdCheckBox.Text = 'Use adaptive threshold';
            app.AutoSegmentationCheckBox = uicheckbox(app.PlaqueQuantificationParametersPanel);
            app.AutoSegmentationCheckBox.Text = 'Auto Segmentation';
            app.FromLabel = uilabel(app.PlaqueQuantificationParametersPanel);
            app.FromLabel.Text = 'From';
            app.FromEditField = uieditfield(app.PlaqueQuantificationParametersPanel, 'numeric');
            app.ToEditFieldLabel = uilabel(app.PlaqueQuantificationParametersPanel);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.PlaqueQuantificationParametersPanel, 'numeric');
            app.TabGroup = uitabgroup(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure);
            app.Seg1Tab = uitab(app.TabGroup);
            app.Seg1Tab.Title = 'Seg. 1';
            app.UITable_seg1 = uitable(app.Seg1Tab);
            app.UITable_seg1.ColumnName = {'1st Sector'; '2nd Sector'; '3rd Sector'; '4th Sector'; 'Total Area'};
            app.UITable_seg1.RowName = {'Lumen area'; 'Necrotic core area'; 'Fibrous area'; 'Ca area'; 'Positive Remodeling'; 'NRS'; 'Spotty calcification'; 'Severe stenosis'};
            app.WLWWPanel = uipanel(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure);
            app.WLWWPanel.Title = 'WL/WW';
            app.SetWLWWButton = uibutton(app.WLWWPanel, 'push');
            app.SetWLWWButton.ButtonPushedFcn = createCallbackFcn(app, @SetWLWWButtonPushed, true);
            app.SetWLWWButton.Text = 'Set WL/WW';
            app.WWEditFieldLabel = uilabel(app.WLWWPanel);
            app.WWEditFieldLabel.Text = 'WW:';
            app.WWEditField = uieditfield(app.WLWWPanel, 'numeric');
            app.WWEditField.Value = 730;
            app.WLEditFieldLabel = uilabel(app.WLWWPanel);
            app.WLEditFieldLabel.Text = 'WL:';
            app.WLEditField = uieditfield(app.WLWWPanel, 'numeric');
            app.WLEditField.Value = 250;
            app.ROIManagerPanel = uipanel(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure);
            app.ROIManagerPanel.Title = 'ROI Manager';
            app.DrawROIButton = uibutton(app.ROIManagerPanel, 'state');
            app.DrawROIButton.ValueChangedFcn = createCallbackFcn(app, @DrawROIButtonValueChanged, true);
            app.DrawROIButton.Enable = 'off';
            app.DrawROIButton.Text = 'Draw ROI';
            app.DrawROIforasingleimageLabel = uilabel(app.ROIManagerPanel);
            app.DrawROIforasingleimageLabel.Text = 'Draw ROI for a single image:';
            app.PropagateROIButton = uibutton(app.ROIManagerPanel, 'state');
            app.PropagateROIButton.ValueChangedFcn = createCallbackFcn(app, @PropagateROIButtonValueChanged, true);
            app.PropagateROIButton.Enable = 'off';
            app.PropagateROIButton.Text = 'Propagate ROI';
            app.PropagateROIthroughaseriesofimagesLabel = uilabel(app.ROIManagerPanel);
            app.PropagateROIthroughaseriesofimagesLabel.Text = {'Propagate ROI through a series'; 'of images'};
            app.StartButton = uibutton(app.ROIManagerPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.WatershedLabel = uilabel(app.ROIManagerPanel);
            app.WatershedLabel.Text = 'Watershed';
            app.UpdateButton = uibutton(app.ROIManagerPanel, 'state');
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.UpdatethetableLabel = uilabel(app.ROIManagerPanel);
            app.UpdatethetableLabel.Text = 'Update the table:';
            app.QuantifyButton = uibutton(app.ROIManagerPanel, 'push');
            app.QuantifyButton.ButtonPushedFcn = createCallbackFcn(app, @QuantifyButtonPushed, true);
            app.QuantifyButton.Text = 'Quantify';
            app.PropagateROIthroughaseriesofimagesLabel_2 = uilabel(app.ROIManagerPanel);
            app.PropagateROIthroughaseriesofimagesLabel_2.Text = {'Propagate ROI through a series'; 'of images'};
            app.ExportButton = uibutton(app.ROIManagerPanel, 'state');
            app.ExportButton.ValueChangedFcn = createCallbackFcn(app, @ExportButtonValueChanged, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.ExportthetableLabel = uilabel(app.ROIManagerPanel);
            app.ExportthetableLabel.Text = 'Export the table:';
            app.CalculatethelumenalareaLabel = uilabel(app.ROIManagerPanel);
            app.CalculatethelumenalareaLabel.Text = 'Calculate the lumenal area';
            app.LumenButton = uibutton(app.ROIManagerPanel, 'push');
            app.LumenButton.ButtonPushedFcn = createCallbackFcn(app, @LumenButtonPushed, true);
            app.LumenButton.Enable = 'off';
            app.LumenButton.Text = 'Lumen';
            app.StatusLampLabel = uilabel(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure);
            app.ResetquantificationButton = uibutton(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure, 'push');
            app.ResetquantificationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetquantificationButtonPushed, true);
            app.ResetquantificationButton.Text = 'Reset quantification';
            app.LampLabel = uilabel(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure);
            app.LampLabel.Text = '';
            app.UseGradientofImagesCheckBox = uicheckbox(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure);
            app.UseGradientofImagesCheckBox.Text = 'Use Gradient of Images';
            app.QuantoptionsPanel = uipanel(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure);
            app.QuantoptionsPanel.Title = 'Quant. options';
            app.DCPCheckBox = uicheckbox(app.QuantoptionsPanel);
            app.DCPCheckBox.ValueChangedFcn = createCallbackFcn(app, @DCPCheckBoxValueChanged, true);
            app.DCPCheckBox.Text = 'DCP';
            app.LAPCheckBox = uicheckbox(app.QuantoptionsPanel);
            app.LAPCheckBox.Text = 'LAP';
            app.PositiveremodelingCheckBox = uicheckbox(app.QuantoptionsPanel);
            app.PositiveremodelingCheckBox.Text = 'Positive remodeling';
            app.NRSCheckBox = uicheckbox(app.QuantoptionsPanel);
            app.NRSCheckBox.Text = 'NRS';
            app.SpottyCalcificationCheckBox = uicheckbox(app.QuantoptionsPanel);
            app.SpottyCalcificationCheckBox.Text = 'Spotty Calcification';
            app.SelectbiomarkersLabel = uilabel(app.QuantoptionsPanel);
            app.SelectbiomarkersLabel.Text = 'Select biomarkers:';
            app.SeverestenosisCheckBox = uicheckbox(app.QuantoptionsPanel);
            app.SeverestenosisCheckBox.ValueChangedFcn = createCallbackFcn(app, @SeverestenosisCheckBoxValueChanged, true);
            app.SeverestenosisCheckBox.Text = 'Severe stenosis';
            app.ApplyButton = uibutton(app.QuantoptionsPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CalibrationPanel = uipanel(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure);
            app.CalibrationPanel.Title = 'Calibration';
            app.FirstImageEditFieldLabel = uilabel(app.CalibrationPanel);
            app.FirstImageEditFieldLabel.Text = 'First Image';
            app.FirstImageEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.LastImageEditFieldLabel = uilabel(app.CalibrationPanel);
            app.LastImageEditFieldLabel.Text = 'Last Image';
            app.LastImageEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.CalibrateButton = uibutton(app.CalibrationPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            % Show the figure after all components are created
            app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = my_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutomatedInteractivePlaqueQuantificationAIPQUIFigure)
        end
    end
end