classdef semi_auto_segmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Semi_auto_segmentationUIFigure  matlab.ui.Figure
        ROIpropagationPanel             matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        SliceSlider                     matlab.ui.control.Slider
        AcceptROIButton                 matlab.ui.control.Button
        HUTrendsPanel                   matlab.ui.container.Panel
        AcceptLumenButton               matlab.ui.control.Button
        HUValuesLable                   matlab.ui.control.Label
        AcceptallSegmentsButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        dcm_image_series;
        dcm_number_total;
        HounsFieldImageSeries;
        propagation;
        CallingApp;
        lower_level;
        upper_level;
        % Axes
        Img_ax;
        Masked_ax;
        GMAG_ax;
        Img_seg_ax;
        seg_lines_int_ax;
        seg_lines_gmag_ax
        intensity_curve1_ax
        gmag_curve1_ax
        intensity_curve2_ax
        gmag_curve2_ax
        histogram_ax
        lumen_ax
        
        ROI_propagation_mask;
        ROI_centroid                    % Structure (.centeroid) centeroid for all the ROIs
        ROI_accept_flag                 % Binary: 1 if ROI is accepted, 0 if not accepted
        SEG_accept_flag                 % Binary: 1 if segment is accepted, 0 if not accepted
        ROI_database                    % Structure: .Position(N); .mask(N)
        ROI_mask_tmp                    % Temporary variable storing the changed ROI mask
        ROI_position_tmp                % Temporary variable storing the changed ROI position
        MAX_INT_SAMPLE_CIRCLE           % Maximum intensity value inside the sample circle (upper band for lumen)
        MIN_INT_SAMPLE_LINES            % Minimum intensity value of lumen based on calibration lines (lower band for lumen)
        LONG_POINTS_X_COORDS            % Array [x1 x2]: X coordinates of vertical guide lines on long intensity profile
        SHORT_POINTS_X_COORDS           % Array [x1 x2]: X coordinates of vertical guide lines on short intensity profile
        calibration_points              % Array [1:4]: Stores the intensity values for calibration lines
        sample_circle                   % Structure (.center; .radius): Stores the temporary sample circle
        Stored_data                     % Structure: Stores all the required variables for reconstruction of segmentation
        
        seg_dataset                     % Structure: Stores the final parameters to return to the main app.
                                        % .ROI_Position: coordinates of each ROI
                                        % .ROI_Mask: ROI mask
                                        % .ROI_Centroid: centroid of each ROI
                                        % .Lumen_MIN: lower bound for lumen
                                        % .Lumen_MAX: upper bound for lumen
                                        % .Lumen_AVE: average intensity of lumen
                                       
        
        Axes matlab.graphics.axis.Axes
    end
    
    methods (Access = public)
        
        function output_roi = Create_ROI(app, dcm_idx, ROI_flag)
        
            HUImage = app.HounsFieldImageSeries(dcm_idx).images;
            HUImage_int16 = int16(HUImage);
            HUImage_NOchange = HUImage;
            gmag = imgradient(HUImage);
            gmag_org = gmag;
            
            gmag(gmag>700) = 50;
            gmag(gmag<50) = 50;                
            gmag_int16 = int16(gmag);
            
            bw = imbinarize(HUImage);
            img = gmag_int16;
            
            
            if ROI_flag == 0
                
                
                segmentedLabels = imsegkmeans(img,2);
                boneMask = segmentedLabels==1;
                
                boneMask = bwareafilt(boneMask, 2);
                
                bw_filled = imfill(boneMask, 'holes');
                biggest_blob = bwareafilt(bw_filled, 1);
                
                blocations = bwboundaries(biggest_blob,'noholes');
                
                for ind = 1:numel(blocations)
                    % Convert to x,y order.
                    pos = blocations{ind};
                    pos = fliplr(pos);
                    N = 20;
                    q = curvspace(app, pos, N);
                    % Create a freehand ROI.
                %     drawfreehand('Position', q);
                %     drawassisted('Position', q)
                    roi_1 = drawpolygon(app.Img_ax,'Position', q, 'Tag', 'ROI_IMG');
                end
                
                imshowpair(img, biggest_blob, 'Parent', app.Masked_ax);
                roi_2 = drawpolygon(app.Masked_ax,'Position', q, 'Tag', 'ROI_MASKED');
                
                imshow(gmag, 'DisplayRange', [], 'Parent', app.GMAG_ax)
                roi_3 = drawpolygon(app.GMAG_ax,'Position', q, 'Tag', 'ROI_GMAG');
                
                hlink = linkprop([roi_1, roi_2, roi_3], 'Position');
                
                addlistener(roi_1,'MovingROI',@(src,evt) allevents(app, src, evt, roi_1, roi_2, roi_3));
                addlistener(roi_2,'MovingROI',@(src,evt) allevents(app, src, evt, roi_1, roi_2, roi_3));
                addlistener(roi_3,'MovingROI',@(src,evt) allevents(app, src, evt, roi_1, roi_2, roi_3));

%                 handle = subplot(1,4,4);
                
                l1 = addlistener(roi_1,'MovingROI',@(src, evt)clickCallback(app, src, evt, HUImage, roi_1, app.Img_seg_ax)); %this listener I have added to listen whenever we move ROI
                l2 = addlistener(roi_2,'MovingROI',@(src, evt)clickCallback(app, src, evt, HUImage, roi_2, app.Img_seg_ax)); %this listener I have added to listen whenever we move ROI
                l3 = addlistener(roi_3,'MovingROI',@(src, evt)clickCallback(app, src, evt, HUImage, roi_3, app.Img_seg_ax)); %this listener I have added to listen whenever we move ROI

                
                output_roi = app.ROI_position_tmp;
                
%                 app.ROI_position_tmp = [];
                
            else
                
                output_roi = app.ROI_database(dcm_idx).Position
                
                roi_1 = drawpolygon(app.Img_ax,'Position', output_roi, 'Tag', 'ROI_IMG');
                
                my_mask = createMask(roi_1);
                imshowpair(img, my_mask, 'Parent', app.Masked_ax);
                roi_2 = drawpolygon(app.Masked_ax,'Position', output_roi, 'Tag', 'ROI_MASKED');
                
                imshow(gmag, 'DisplayRange', [], 'Parent', app.GMAG_ax)
                roi_3 = drawpolygon(app.GMAG_ax,'Position', output_roi, 'Tag', 'ROI_GMAG');
                
                HUImage(~my_mask) = -100;
                imshow( HUImage, 'Parent', app.Img_seg_ax, 'DisplayRange', [app.lower_level app.upper_level])
                
            end


        end
        
        function clickCallback(app, src, evt, img, roi, handle)
            
        %     h_text = text(20,20,num2str(0));
        %     delete(hText);
        %     currentPosition = roi.CurrentPosition;
            currentPosition = roi.Position;
        %     roi_new = drawpolygon('Position', currentPosition);
        %     my_mask = createMask(roi_new);
            my_mask = createMask(roi);
            img(~my_mask) = -100;
%             BW = imbinarize(img);
%             area = bwarea(BW);
%             disp(['ROI Area: ' num2str(area)]);
            imshow( img, 'Parent', handle, 'DisplayRange', [])
            
            app.ROI_position_tmp = currentPosition;
            app.ROI_mask_tmp = my_mask;
            
        %     h_text = text(20,20,num2str(area))
        end
        
        
        function allevents(app, src, evt, roi1, roi2, roi3)
            new_position = evt.CurrentPosition;
            roi1.Position = new_position;
            roi2.Position = new_position;
            roi3.Position = new_position;
            app.ROI_position_tmp = new_position;
            
            my_mask = createMask(roi1);
            app.ROI_mask_tmp = my_mask;
            
        end


        function q = curvspace(app, p, N)
            % CURVSPACE Evenly spaced points along an existing curve in 2D or 3D.
            %   CURVSPACE(P,N) generates N points that interpolates a curve
            %   (represented by a set of points) with an equal spacing. Each
            %   row of P defines a point, which means that P should be a n x 2
            %   (2D) or a n x 3 (3D) matrix.
            %
            %   (Example)
            %   x = -2*pi:0.5:2*pi;
            %   y = 10*sin(x);
            %   z = linspace(0,10,length(x));
            %   N = 50;
            %   p = [x',y',z'];
            %   q = curvspace(p,N);
            %   figure;
            %   plot3(p(:,1),p(:,2),p(:,3),'*b',q(:,1),q(:,2),q(:,3),'.r');
            %   axis equal;
            %   legend('Original Points','Interpolated Points');
            %
            %   See also LINSPACE.
            %
            %   22 Mar 2005, Yo Fukushima
            %% initial settings %%
            currentpt = p(1,:); % current point
            indfirst = 2; % index of the most closest point in p from curpt
            len = size(p,1); % length of p
            q = currentpt; % output point
            k = 0;
            %% distance between points in p %%
            for k0 = 1:len-1
               dist_bet_pts(k0) = distance(app, p(k0,:),p(k0+1,:));
            end
            totaldist = sum(dist_bet_pts);
            %% interval %%
            intv = totaldist./(N-1);
            %% iteration %%
            for k = 1:N-1
        
               newpt = []; distsum = 0;
               ptnow = currentpt;
               kk = 0;
               pttarget = p(indfirst,:);
               remainder = intv; % remainder of distance that should be accumulated
               while isempty(newpt)
                  % calculate the distance from active point to the most
                  % closest point in p
                  disttmp = distance(app, ptnow,pttarget);
                  distsum = distsum + disttmp;
                  % if distance is enough, generate newpt. else, accumulate
                  % distance
                  if distsum >= intv
                     newpt = interpintv(app, ptnow,pttarget,remainder);
                  else
                     remainder = remainder - disttmp;
                     ptnow = pttarget;
                     kk = kk + 1;
                     if indfirst+kk > len
                        newpt = p(len,:);
                     else
                        pttarget = p(indfirst+kk,:);
                     end
                  end
               end
        
               % add to the output points
               q = [q; newpt];
        
               % update currentpt and indfirst
               currentpt = newpt;
               indfirst = indfirst + kk;
        
            end
        
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%
        %%    SUBFUNCTIONS     %%
        %%%%%%%%%%%%%%%%%%%%%%%%%
        function l = distance(app, x, y)
            % DISTANCE Calculate the distance.
            %   DISTANCE(X,Y) calculates the distance between two
            %   points X and Y. X should be a 1 x 2 (2D) or a 1 x 3 (3D)
            %   vector. Y should be n x 2 matrix (for 2D), or n x 3 matrix
            %   (for 3D), where n is the number of points. When n > 1,
            %   distance between X and all the points in Y are returned.
            %
            %   (Example)
            %   x = [1 1 1];
            %   y = [1+sqrt(3) 2 1];
            %   l = distance(x,y)
            %
            % 11 Mar 2005, Yo Fukushima
            %% calculate distance %%
            if size(x,2) == 2
               l = sqrt((x(1)-y(:,1)).^2+(x(2)-y(:,2)).^2);
            elseif size(x,2) == 3
               l = sqrt((x(1)-y(:,1)).^2+(x(2)-y(:,2)).^2+(x(3)-y(:,3)).^2);
            else
               error('Number of dimensions should be 2 or 3.');
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function newpt = interpintv(app, pt1, pt2, intv)
            % Generate a point between pt1 and pt2 in such a way that
            % the distance between pt1 and new point is intv.
            % pt1 and pt2 should be 1x3 or 1x2 vector.
            dirvec = pt2 - pt1;
            dirvec = dirvec./norm(dirvec);
            l = dirvec(1); m = dirvec(2);
            newpt = [intv*l+pt1(1),intv*m+pt1(2)];
            if length(pt1) == 3
               n = dirvec(3);
               newpt = [newpt,intv*n+pt1(3)];
            end
        end


        function [long_profile short_profile] = plot_int_gmag_curves(app, HU_Image, gmag, mask)


%             figure;
%             artery_handle = subplot(3,2,1);
%             imshow(double(HU_Image), []); title('Segmented Image')
%             
%             gmag_handle = subplot(3,2,2);
%             imshow(gmag, [0 300]); title('Gradient Mag Image')
            
            
            [out,LM] = bwferet(mask,'all');
            x_feret_long = [out.MaxCoordinates{1}(1,1) out.MaxCoordinates{1}(2,1)];
            y_feret_long = [out.MaxCoordinates{1}(1,2) out.MaxCoordinates{1}(2,2)];
            long_feret_position = [out.MaxCoordinates{1}(1,1) out.MaxCoordinates{1}(1,2); out.MaxCoordinates{1}(2,1) out.MaxCoordinates{1}(2,2)];
            
            x_feret_short = [out.MinCoordinates{1}(1,1) out.MinCoordinates{1}(2,1)];
            y_feret_short = [out.MinCoordinates{1}(1,2) out.MinCoordinates{1}(2,2)];
            short_feret_position = [out.MinCoordinates{1}(1,1) out.MinCoordinates{1}(1,2); out.MinCoordinates{1}(2,1) out.MinCoordinates{1}(2,2)];


%             imdistline(app.seg_lines_int_ax,x_feret_long,y_feret_long);
%             imdistline(app.seg_lines_int_ax,x_feret_short,y_feret_short);
            
            drawline('Position', long_feret_position, "Color", 'g', "InteractionsAllowed","none", "Parent", app.seg_lines_int_ax);
            drawline('Position', short_feret_position, "Color", 'b', "InteractionsAllowed","none", "Parent", app.seg_lines_int_ax);
            
            drawline('Position', long_feret_position, "Color", 'g', "InteractionsAllowed","none", "Parent", app.seg_lines_gmag_ax);
            drawline('Position', short_feret_position, "Color", 'b', "InteractionsAllowed","none", "Parent", app.seg_lines_gmag_ax);
            
            p_long = polyfit(x_feret_long, y_feret_long, 1);
            p_short = polyfit(x_feret_short, y_feret_short, 1);
            
            x_long = linspace(x_feret_long(1),x_feret_long(2),50);
            y_long = polyval(p_long, x_long);
            
            x_short = linspace(x_feret_short(1),x_feret_short(2),50);
            y_short = polyval(p_short, x_short);
            
            [x_HUprofile_long, y_HUprofile_long, HU_profile_long] = improfile(HU_Image,x_feret_long,y_feret_long, 50);
            [x_GMAGprofile_long, y_GMAGprofile_long, gmag_profile_long] = improfile(gmag,x_feret_long,y_feret_long, 50);
            
            [x_HUprofile_short, y_HUprofile_short, HU_profile_short] = improfile(HU_Image,x_feret_short,y_feret_short, 50);
            [x_GMAGprofile_short, y_GMAGprofile_short, gmag_profile_short] = improfile(gmag,x_feret_short,y_feret_short, 50);
            
%             subplot(3,2,3)
            plot(app.intensity_curve1_ax, HU_profile_long(:,1),'g'); title(app.intensity_curve1_ax,'HU along the large diameter'); ylim(app.intensity_curve1_ax, [0 inf]); xlim(app.intensity_curve1_ax, [0 50]); grid(app.intensity_curve1_ax, "on");
            
%             h4 = subplot(3,2,4);
            plot(app.gmag_curve1_ax, gmag_profile_long(:,1),'g'); title(app.gmag_curve1_ax, 'GMAG along the large diameter'); ylim(app.gmag_curve1_ax, [0 inf]); xlim(app.gmag_curve1_ax, [0 50]); grid(app.gmag_curve1_ax, 'on');
            
%             subplot(3,2,5)
            plot(app.intensity_curve2_ax, HU_profile_short(:,1),'b'); title(app.intensity_curve2_ax, 'HU along the small diameter'); ylim(app.intensity_curve2_ax, [0 inf]); xlim(app.intensity_curve2_ax, [0 50]); grid(app.intensity_curve2_ax, 'on');
            
%             h6 = subplot(3,2,6);
            plot(app.gmag_curve2_ax, gmag_profile_short(:,1),'b'); title(app.gmag_curve2_ax, 'GMAG along the small diameter'); ylim(app.gmag_curve2_ax, [0 inf]); xlim(app.gmag_curve2_ax, [0 50]); grid(app.gmag_curve2_ax, 'on');
            
%             [x_picked_long GMAG1] = getpts(h4);
%             [x_picked_short GMAG2] = getpts(h6);
%             
%             x_long_coords = x_GMAGprofile_long(round(x_picked_long)');
%             x_short_coords = x_GMAGprofile_short(round(x_picked_short)');
%             
%             y_long_coords = round(y_GMAGprofile_long(round(x_picked_long')));
%             y_short_coords = round(y_GMAGprofile_short(round(x_picked_short')));
%             
%             Calibration_points = [round(x_long_coords') round(x_short_coords'); y_long_coords' y_short_coords'];
%             
%             for pt_idx = 1:4
%                 HU(pt_idx) = HU_Image(Calibration_points(2,pt_idx), Calibration_points(1,pt_idx));
%             end
%             
%             HU_min = min(HU)
%             HU_max = max(HU_Image, [], 'all')
%             
%             lower_bound = HU_min;
%             upper_bound = HU_max;
            
            long_profile = [x_HUprofile_long, y_HUprofile_long, HU_profile_long];
            short_profile = [x_HUprofile_short, y_HUprofile_short, HU_profile_short];
        
        
        end
        
        
        function Create_Calibration_lines(app, long_profile, short_profile, HU_image, HU_image_masked, seg_accept_flag)
            
            % ***************************************************************
            % Create vertical lines for LONG INTENSITY CURVE (app.intensity_curve1_ax)
            % ***************************************************************
            yl = get(app.intensity_curve1_ax,'YTick');
            param_long_int.YLim = yl([1,end]);
            
            xl = get(app.intensity_curve1_ax,'XTick');
            param_long_int.XLim = xl([1,end]);
            
            v1_long_int_position = [(param_long_int.XLim(2) - param_long_int.XLim(1))/3 -1000; (param_long_int.XLim(2) - param_long_int.XLim(1))/3 1000];
            v1_long_int_handle = drawline("Position", v1_long_int_position, "Color", 'r', "InteractionsAllowed","translate", "Parent", app.intensity_curve1_ax, 'Tag', 'V1_long_int');
            
            v2_long_int_position = [2*(param_long_int.XLim(2) - param_long_int.XLim(1))/3 -1000; 2*(param_long_int.XLim(2) - param_long_int.XLim(1))/3 1000];
            v2_long_int_handle = drawline("Position", v2_long_int_position, "Color", 'r', "InteractionsAllowed","translate", "Parent", app.intensity_curve1_ax, 'Tag', 'V2_long_int');
            
            % Draw the points for long diameters
            point_idx = round((param_long_int.XLim(2) - param_long_int.XLim(1))/3);
            if seg_accept_flag == 1
                point_idx = app.LONG_POINTS_X_COORDS(1);
            end
            x_p = long_profile(point_idx, 1);
            y_p = long_profile(point_idx, 2);
            p1_long_int_handle = drawpoint("Position", [x_p y_p], "Color", 'r', "InteractionsAllowed","all", "Parent", app.seg_lines_int_ax, "LineWidth", 20);
            p1_long_gmag_handle = drawpoint("Position", [x_p y_p], "Color", 'r', "InteractionsAllowed","all", "Parent", app.seg_lines_gmag_ax, "LineWidth", 20);
            
            point_idx = round(2*(param_long_int.XLim(2) - param_long_int.XLim(1))/3);
            if seg_accept_flag == 1
                point_idx = app.LONG_POINTS_X_COORDS(2);
            end
            x_p = long_profile(point_idx, 1);
            y_p = long_profile(point_idx, 2);
            p2_long_int_handle = drawpoint("Position", [x_p y_p], "Color", 'r', "InteractionsAllowed","all", "Parent", app.seg_lines_int_ax, "LineWidth", 20);
            p2_long_gmag_handle = drawpoint("Position", [x_p y_p], "Color", 'r', "InteractionsAllowed","all", "Parent", app.seg_lines_gmag_ax, "LineWidth", 20);
            
            
            % ***************************************************************
            % Create vertical lines for SHORT INTENSITY CURVE (app.intensity_curve2_ax)
            % ***************************************************************
            yl = get(app.intensity_curve2_ax,'YTick');
            param_short_int.YLim = yl([1,end]);
            
            xl = get(app.intensity_curve2_ax,'XTick');
            param_short_int.XLim = xl([1,end]);
            
            v1_short_int_position = [(param_short_int.XLim(2) - param_short_int.XLim(1))/3 -1000; (param_short_int.XLim(2) - param_short_int.XLim(1))/3 1000];
            v1_short_int_handle = drawline("Position", v1_short_int_position, "Color", 'r', "InteractionsAllowed","translate", "Parent", app.intensity_curve2_ax, 'Tag', 'V1_shor_int');
            
            v2_short_int_position = [2*(param_short_int.XLim(2) - param_short_int.XLim(1))/3 -1000; 2*(param_short_int.XLim(2) - param_short_int.XLim(1))/3 1000];
            v2_short_int_handle = drawline("Position", v2_short_int_position, "Color", 'r', "InteractionsAllowed","translate", "Parent", app.intensity_curve2_ax, 'Tag', 'V2_shor_int');
            
            % Draw the points for short diameters
            point_idx = round((param_short_int.XLim(2) - param_short_int.XLim(1))/3);
            if seg_accept_flag == 1
                point_idx = app.SHORT_POINTS_X_COORDS(1);
            end
            x_p = short_profile(point_idx, 1);
            y_p = short_profile(point_idx, 2);
            p1_short_int_handle = drawpoint("Position", [x_p y_p], "Color", 'r', "InteractionsAllowed","all", "Parent", app.seg_lines_int_ax, "LineWidth", 20);
            p1_short_gmag_handle = drawpoint("Position", [x_p y_p], "Color", 'r', "InteractionsAllowed","all", "Parent", app.seg_lines_gmag_ax, "LineWidth", 20);
            
            point_idx = round(2*(param_short_int.XLim(2) - param_short_int.XLim(1))/3);
            if seg_accept_flag == 1
                point_idx = app.SHORT_POINTS_X_COORDS(2);
            end
            x_p = short_profile(point_idx, 1);
            y_p = short_profile(point_idx, 2);
            p2_short_int_handle = drawpoint("Position", [x_p y_p], "Color", 'r', "InteractionsAllowed","all", "Parent", app.seg_lines_int_ax, "LineWidth", 20);
            p2_short_gmag_handle = drawpoint("Position", [x_p y_p], "Color", 'r', "InteractionsAllowed","all", "Parent", app.seg_lines_gmag_ax, "LineWidth", 20);
            
            
            % ***************************************************************
            % Create vertical lines for LONG GMAG CURVE (app.gmag_curve1_ax)
            % ***************************************************************
            yl = get(app.gmag_curve1_ax,'YTick');
            param_long_gmag.YLim = yl([1,end]);
            
            xl = get(app.gmag_curve1_ax,'XTick');
            param_long_gmag.XLim = xl([1,end]);
            
            v1_long_gmag_position = [(param_long_gmag.XLim(2) - param_long_gmag.XLim(1))/3 -1000; (param_long_gmag.XLim(2) - param_long_gmag.XLim(1))/3 1000];
            v1_long_gmag_handle = drawline("Position", v1_long_gmag_position, "Color", 'r', "InteractionsAllowed","translate", "Parent", app.gmag_curve1_ax, 'Tag', 'V1_long_gmag');
            
            
            v2_long_gmag_position = [2*(param_long_gmag.XLim(2) - param_long_gmag.XLim(1))/3 -1000; 2*(param_long_gmag.XLim(2) - param_long_gmag.XLim(1))/3 1000];
            v2_long_gmag_handle = drawline("Position", v2_long_gmag_position, "Color", 'r', "InteractionsAllowed","translate", "Parent", app.gmag_curve1_ax, 'Tag', 'V2_long_gmag');
            
            % ***************************************************************
            % Create vertical lines for SHORT GMAG CURVE (app.gmag_curve2_ax)
            % ***************************************************************
            yl = get(app.gmag_curve2_ax,'YTick');
            param_short_gmag.YLim = yl([1,end]);
            
            xl = get(app.gmag_curve2_ax,'XTick');
            param_short_gmag.XLim = xl([1,end]);
            
            v1_short_gmag_position = [(param_short_gmag.XLim(2) - param_short_gmag.XLim(1))/3 -1000; (param_short_gmag.XLim(2) - param_short_gmag.XLim(1))/3 1000];
            v1_short_gmag_handle = drawline("Position", v1_short_gmag_position, "Color", 'r', "InteractionsAllowed","translate", "Parent", app.gmag_curve2_ax, 'Tag', 'V1_short_gmag');
            
            v2_short_gmag_position = [2*(param_short_gmag.XLim(2) - param_short_gmag.XLim(1))/3 -1000; 2*(param_short_gmag.XLim(2) - param_short_gmag.XLim(1))/3 1000];
            v2_short_gmag_handle = drawline("Position", v2_short_gmag_position, "Color", 'r', "InteractionsAllowed","translate", "Parent", app.gmag_curve2_ax, 'Tag', 'V2_short_gmag');
            
            center_xy = long_profile(round(size(long_profile,1)),1:2);
            radius = 5;
            if seg_accept_flag == 1
                center_xy = app.sample_circle.center;
                radius = app.sample_circle.radius;
            end
            circle_int_handle = drawcircle("Center", center_xy, "Radius", radius, "Color", 'y', "Parent",app.seg_lines_int_ax);
            circle_gmag_handle = drawcircle("Center", center_xy, "Radius", radius, "Color", 'y', "Parent",app.seg_lines_gmag_ax);
            
            % ***************************************************************
            % Link all the vertical lines
            % ***************************************************************
            addlistener(v1_long_int_handle,'MovingROI',@(src,evt) link_all_ver_lines(app, src, evt, v1_long_int_handle, v1_long_gmag_handle));
            addlistener(v1_long_gmag_handle,'MovingROI',@(src,evt) link_all_ver_lines(app, src, evt, v1_long_int_handle, v1_long_gmag_handle));
            
            addlistener(v2_long_int_handle,'MovingROI',@(src,evt) link_all_ver_lines(app, src, evt, v2_long_int_handle, v2_long_gmag_handle));
            addlistener(v2_long_gmag_handle,'MovingROI',@(src,evt) link_all_ver_lines(app, src, evt, v2_long_int_handle, v2_long_gmag_handle));
            
            addlistener(v1_short_int_handle,'MovingROI',@(src,evt) link_all_ver_lines(app, src, evt, v1_short_int_handle, v1_short_gmag_handle));
            addlistener(v1_short_gmag_handle,'MovingROI',@(src,evt) link_all_ver_lines(app, src, evt, v1_short_int_handle, v1_short_gmag_handle));
            
            addlistener(v2_short_int_handle,'MovingROI',@(src,evt) link_all_ver_lines(app, src, evt, v2_short_int_handle, v2_short_gmag_handle));
            addlistener(v2_short_gmag_handle,'MovingROI',@(src,evt) link_all_ver_lines(app, src, evt, v2_short_int_handle, v2_short_gmag_handle));
            
            % ***************************************************************
            % Link all the vertical lines and points
            % ***************************************************************
            addlistener(v1_long_int_handle,'MovingROI',@(src,evt) link_all_lines_points(app, src, evt, v1_long_int_handle, p1_long_int_handle, p1_long_gmag_handle, long_profile));
            addlistener(v1_long_gmag_handle,'MovingROI',@(src,evt) link_all_lines_points(app, src, evt, v1_long_gmag_handle, p1_long_int_handle, p1_long_gmag_handle, long_profile));
            
            addlistener(v2_long_int_handle,'MovingROI',@(src,evt) link_all_lines_points(app, src, evt, v2_long_int_handle, p2_long_int_handle, p2_long_gmag_handle, long_profile));
            addlistener(v2_long_gmag_handle,'MovingROI',@(src,evt) link_all_lines_points(app, src, evt, v2_long_gmag_handle, p2_long_int_handle, p2_long_gmag_handle, long_profile));
            
            addlistener(v1_short_int_handle,'MovingROI',@(src,evt) link_all_lines_points(app, src, evt, v1_short_int_handle, p1_short_int_handle, p1_short_gmag_handle, short_profile));
            addlistener(v1_short_gmag_handle,'MovingROI',@(src,evt) link_all_lines_points(app, src, evt, v1_short_gmag_handle, p1_short_int_handle, p1_short_gmag_handle, short_profile));
            
            addlistener(v2_short_int_handle,'MovingROI',@(src,evt) link_all_lines_points(app, src, evt, v2_short_int_handle, p2_short_int_handle, p2_short_gmag_handle, short_profile));
            addlistener(v2_short_gmag_handle,'MovingROI',@(src,evt) link_all_lines_points(app, src, evt, v2_short_gmag_handle, p2_short_int_handle, p2_short_gmag_handle, short_profile));
            
            % ***************************************************************
            % Link sample circles
            % ***************************************************************
            addlistener(circle_int_handle,'MovingROI',@(src,evt) link_sample_circles(app, src, evt, circle_int_handle, circle_gmag_handle, HU_image));
            addlistener(circle_gmag_handle,'MovingROI',@(src,evt) link_sample_circles(app, src, evt, circle_int_handle, circle_gmag_handle, HU_image));
            
            % ***************************************************************
            % Get MIN intensity from the calibration lines
            % ***************************************************************
            addlistener(v1_long_int_handle,'MovingROI',@(src,evt) get_min_int(app, src, evt, v1_long_int_handle, v2_long_int_handle, v1_short_int_handle, v2_short_int_handle, long_profile, short_profile, HU_image_masked));
            addlistener(v2_long_int_handle,'MovingROI',@(src,evt) get_min_int(app, src, evt, v1_long_int_handle, v2_long_int_handle, v1_short_int_handle, v2_short_int_handle, long_profile, short_profile, HU_image_masked));
            
            addlistener(v1_short_int_handle,'MovingROI',@(src,evt) get_min_int(app, src, evt, v1_long_int_handle, v2_long_int_handle, v1_short_int_handle, v2_short_int_handle, long_profile, short_profile, HU_image_masked));
            addlistener(v2_short_int_handle,'MovingROI',@(src,evt) get_min_int(app, src, evt, v1_long_int_handle, v2_long_int_handle, v1_short_int_handle, v2_short_int_handle, long_profile, short_profile, HU_image_masked));


        end
        
        
        function link_all_ver_lines(app, src, evt, roi1, roi2)
            new_position = evt.CurrentPosition;
%             x = new_position(1,1)
            roi1.Position = new_position;
            roi2.Position = new_position;
%             app.ROI_position_tmp = new_position;
%             
%             my_mask = createMask(roi1);
%             app.ROI_mask_tmp = my_mask;
            
        end
        
        
        function link_all_lines_points(app, src, evt, ver_line, int_point, gmag_point, line_profile)
            new_position = evt.CurrentPosition;
            ver_line.Position = new_position;
            p_idx = round(new_position(1,1));
            
            x_p = line_profile(p_idx, 1);
            y_p = line_profile(p_idx, 2);
            int_point.Position = [x_p y_p];
            gmag_point.Position = [x_p y_p];

            
%             roi1.Position = new_position;
%             roi2.Position = new_position;
%             app.ROI_position_tmp = new_position;
%             
%             my_mask = createMask(roi1);
%             app.ROI_mask_tmp = my_mask;
            
        end
        
        function link_sample_circles(app, src, evt, roi1, roi2, image)
            new_center = evt.CurrentCenter;
            new_radius = evt.CurrentRadius;
%             x = new_position(1,1)
            roi1.Center = new_center;
            roi1.Radius = new_radius;
            roi2.Center = new_center;
            roi2.Radius = new_radius;
            
            my_mask = createMask(roi1);
            image(~my_mask) = -1000;
            app.MAX_INT_SAMPLE_CIRCLE = max(image, [], 'all');
            app.sample_circle.center = new_center;
            app.sample_circle.radius = new_radius;
            
%             app.ROI_position_tmp = new_position;
%             
%             my_mask = createMask(roi1);
%             app.ROI_mask_tmp = my_mask;
            
        end
        
        
        function get_min_int(app, src, evt, roi_long1, roi_long2, roi_short1, roi_short2, long_profile, short_profile, img)
            
            pos_long1 = roi_long1.Position;
            pos_long2 = roi_long2.Position;
            pos_short1 = roi_short1.Position;
            pos_short2 = roi_short2.Position;
            
            app.LONG_POINTS_X_COORDS = [pos_long1(1) pos_long2(1)];
            app.SHORT_POINTS_X_COORDS = [pos_short1(1) pos_short2(1)];
            
            
            if (src.Tag == 'V1_long_int')
                app.calibration_points(1) = interp1(1:50, long_profile(:,3), pos_long1(1));
            elseif src.Tag == 'V2_long_int'
                app.calibration_points(2) = interp1(1:50, long_profile(:,3), pos_long2(1));
            elseif src.Tag == 'V1_shor_int'
                app.calibration_points(3) = interp1(1:50, short_profile(:,3), pos_short1(1));
            elseif src.Tag == 'V2_shor_int'
                app.calibration_points(4) = interp1(1:50, short_profile(:,3), pos_short2(1));
            end
            
            app.MIN_INT_SAMPLE_LINES = min(app.calibration_points);
            
            histogram(app.histogram_ax, img, 'BinLimits', [-10 max(img, [], 'all')], 'BinWidth', 10);
            hold(app.histogram_ax,'on')
            histogram(app.histogram_ax, img, 'BinLimits', [app.MIN_INT_SAMPLE_LINES app.MAX_INT_SAMPLE_CIRCLE], 'BinWidth', 10);
            hold(app.histogram_ax,'off')
            
            lumen = threshold_min_max(app, img, app.MIN_INT_SAMPLE_LINES, app.MAX_INT_SAMPLE_CIRCLE);
 
            imshow(img, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.lumen_ax)
            
            green = cat(3, zeros(size(img)), ones(size(img)), zeros(size(img)));
            hold(app.lumen_ax,'on')
            h = imshow(green, 'Parent', app.lumen_ax);
            hold(app.lumen_ax,"off")
            h.AlphaData = 0.3.*lumen;
            
            int = 0;
            count = 1;
            for x_idx = 1:size(lumen, 1)
                for y_idx = 1:size(lumen, 2)
                    if lumen(x_idx, y_idx) == 1
                        int = int + img(x_idx, y_idx);
                        count = count + 1;
                    end
                end
            end
            
            lumen_ave = int/count;
            
            label_text = strcat('Lumen: Min HU= ', num2str(app.MIN_INT_SAMPLE_LINES), ', Max HU= ', num2str(app.MAX_INT_SAMPLE_CIRCLE), ', Mean HU= ', num2str(lumen_ave));
            app.HUValuesLable.Text = label_text;
            drawnow;
            
            
        end
        
        
        function  lumen = threshold_min_max(app, img, min_thrshold, max_thrshold)
        
            lumen = img;
            threshold_region = img >= min_thrshold & img <= max_thrshold;
            lumen(~threshold_region) = 0;
            lumen(threshold_region) = 1;
            

        end 


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, dcm_image_series, dcm_number_total, HounsFieldImageSeries, lower_level, upper_level)
            app.CallingApp = mainapp;
            app.dcm_image_series = dcm_image_series;
            app.dcm_number_total = dcm_number_total;
            app.HounsFieldImageSeries = HounsFieldImageSeries;
            app.lower_level = lower_level;
            app.upper_level = upper_level;
            
            app.ROI_accept_flag = zeros(1, app.dcm_number_total);
            app.SEG_accept_flag = zeros(1, app.dcm_number_total);
            for idx = 1:app.dcm_number_total
                app.ROI_database(idx).Position = [];
                app.ROI_database(idx).mask = [];
                app.seg_dataset(idx).ROI_Position = [];
                app.seg_dataset(idx).ROI_Mask = [];
                app.seg_dataset(idx).ROI_Centroid = [];
                app.seg_dataset(idx).Lumen_MIN = [];
                app.seg_dataset(idx).Lumen_MAX = [];
                app.seg_dataset(idx).Lumen_AVE = [];
                app.Stored_data(idx).Long_profile_pts = [];
                app.Stored_data(idx).Short_profile_pts = [];
                app.Stored_data(idx).sample_circle_center = [];
                app.Stored_data(idx).sample_circle_radius = [];
                app.Stored_data(idx).calibration_points = [];


            end
            
            
            % Propagation Panel
            app.Img_ax = axes('Parent',app.ROIpropagationPanel, 'Position',[0.03 0.15 0.2125 0.8]);
            title(app.Img_ax,'Original Image')
            app.Masked_ax = axes('Parent',app.ROIpropagationPanel, 'Position',[0.2725 0.15 0.2125 0.8]);
            title(app.Masked_ax,'Masked Image')
            app.GMAG_ax = axes('Parent',app.ROIpropagationPanel, 'Position',[0.515 0.15 0.2125 0.8]);
            title(app.GMAG_ax,'GMAG Image')
            app.Img_seg_ax = axes('Parent',app.ROIpropagationPanel, 'Position',[0.7575 0.15 0.2125 0.8]);
            title(app.Img_seg_ax,'Segmented Image')
            
            % HU Trends Panel
            app.seg_lines_gmag_ax = axes('Parent',app.HUTrendsPanel, 'Position',[0.03 0.575 0.1086 0.375]);
            title(app.seg_lines_gmag_ax,'Segmented Image (GMAG)')
            app.seg_lines_int_ax = axes('Parent',app.HUTrendsPanel, 'Position',[0.03 0.10 0.1086 0.375]);
            title(app.seg_lines_int_ax,'Segmented Image (intensity)')
            
            app.gmag_curve1_ax = axes('Parent',app.HUTrendsPanel, 'Position',[0.1686 0.575 0.1086 0.375]);
            title(app.gmag_curve1_ax,'Gmag Values (long)')
            app.intensity_curve1_ax = axes('Parent',app.HUTrendsPanel, 'Position',[0.1686 0.10 0.1086 0.375]);
            title(app.intensity_curve1_ax,'HU Values (long)')
            
            app.gmag_curve2_ax = axes('Parent',app.HUTrendsPanel, 'Position',[0.3072 0.575 0.1086 0.375]);
            title(app.gmag_curve2_ax,'Gmag Values (short)')
            app.intensity_curve2_ax = axes('Parent',app.HUTrendsPanel, 'Position',[0.3072 0.10 0.1086 0.375]);
            title(app.intensity_curve2_ax,'HU Values (short)')
            
            app.histogram_ax = axes('Parent',app.HUTrendsPanel, 'Position',[0.4458 0.10 0.2472 0.85]);
            title(app.histogram_ax,'Histogram')
            app.lumen_ax = axes('Parent',app.HUTrendsPanel, 'Position',[0.723 0.10 0.2472 0.85]);
            title(app.lumen_ax,'Lumen')
            
            
            imshow(app.HounsFieldImageSeries(1).images, 'DisplayRange', [app.lower_level app.upper_level],'Parent', app.Img_ax);
            mask_tmp = Create_ROI(app, 1, app.ROI_accept_flag(1));
            
            set(app.SliceSlider, 'Limits', [1 app.dcm_number_total]);
            set(app.SliceSlider, 'MinorTicks', [1:1:app.dcm_number_total]);


        end

        % Button pushed function: AcceptLumenButton
        function AcceptLumenButtonPushed(app, event)
            
            img_idx = round(app.SliceSlider.Value);
            app.ROI_accept_flag(img_idx) = 1;
            
            app.seg_dataset(img_idx).ROI_Position = app.ROI_database(img_idx).Position;
            app.seg_dataset(img_idx).ROI_Mask = app.ROI_database(img_idx).mask;
            
            s = regionprops(app.ROI_database(img_idx).mask,'centroid');
            app.seg_dataset(img_idx).ROI_Centroid = s.Centroid;
            
            label_text = app.HUValuesLable.Text;
            extracted_nums = regexp(label_text,'\d+\.?\d*','match');
            app.seg_dataset(img_idx).Lumen_MIN = str2double(extracted_nums{1});
            app.seg_dataset(img_idx).Lumen_MAX = str2double(extracted_nums{2});
            app.seg_dataset(img_idx).Lumen_AVE = str2double(extracted_nums{3});
            
            app.Stored_data(img_idx).Long_profile_pts = app.LONG_POINTS_X_COORDS;
            app.Stored_data(img_idx).Short_profile_pts = app.SHORT_POINTS_X_COORDS;
            app.Stored_data(img_idx).sample_circle_center = app.sample_circle.center;
            app.Stored_data(img_idx).sample_circle_radius = app.sample_circle.radius;
            app.Stored_data(img_idx).calibration_points = app.calibration_points;
            
            app.SEG_accept_flag(img_idx) = 1;


        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            value = app.SliceSlider.Value;
            
            event.Source.Value = round( value );
            
            value = round( value );
            
%             if value>= app.SliceSlider.Value
%                 value = app.LastROISlider.Value - 1;
%             end
            
            event.Source.Value = round( value );
            
            imshow(app.HounsFieldImageSeries(value).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.Img_ax)
            
            title(app.Img_ax, strcat('Original Image (#', num2str(value) ,')') )
            
            
            app.ROI_database(value).Position = Create_ROI(app, value, app.ROI_accept_flag(value));
            
            if app.SEG_accept_flag(value) == 1
                
                % Get the Mask
                my_mask = app.ROI_database(value).mask;
                % Masked Image
                img = app.HounsFieldImageSeries(value).images;
                img(~my_mask) = -1000;
                imshow( img, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.seg_lines_int_ax)
                % GMAG
                gmag = imgradient(app.HounsFieldImageSeries(dcm_idx).images);
                gmag(gmag>700) = 50;
                gmag(gmag<50) = 50;  
                imshow( gmag, 'DisplayRange', [], 'Parent', app.seg_lines_gmag_ax)
                % Histogram
                histogram(app.histogram_ax, img, 'BinLimits', [-10 max(img, [], 'all')], 'BinWidth', 10);
                % Plot Lumen
    %             fused_image = imfuse(img, gmag);
                imshow(app.HounsFieldImageSeries(value).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.lumen_ax)


                app.MAX_INT_SAMPLE_CIRCLE = app.seg_dataset(value).Lumen_MAX;
                app.MIN_INT_SAMPLE_LINES = app.seg_dataset(value).Lumen_MIN;
                app.seg_dataset(value).Lumen_AVE = str2double(extracted_nums{3});
                
                app.LONG_POINTS_X_COORDS = app.Stored_data(value).Long_profile_pts;
                app.SHORT_POINTS_X_COORDS = app.Stored_data(value).Short_profile_pts;
                app.sample_circle.center = app.Stored_data(value).sample_circle_center;
                app.sample_circle.radius = app.Stored_data(value).sample_circle_radius;
                app.calibration_points = app.Stored_data(value).calibration_points;
                
                % Plot Sample Lines + Intensity + GMAG graphs
                [long_profile short_profile] = plot_int_gmag_curves(app, app.HounsFieldImageSeries(value).images, gmag, my_mask);
                
                
                Create_Calibration_lines(app, long_profile, short_profile, app.HounsFieldImageSeries(value).images, img, app.SEG_accept_flag(value));


            end


        end

        % Button pushed function: AcceptROIButton
        function AcceptROIButtonPushed(app, event)
            
            dcm_idx = round(app.SliceSlider.Value);
            app.ROI_accept_flag(dcm_idx) = 1;
            app.ROI_database(dcm_idx).Position = app.ROI_position_tmp;
            app.ROI_database(dcm_idx).mask = app.ROI_mask_tmp;
            
            % Get the Mask
            my_mask = app.ROI_database(dcm_idx).mask;
            % Masked Image
            img = app.HounsFieldImageSeries(dcm_idx).images;
            img(~my_mask) = -1000;
            imshow( img, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.seg_lines_int_ax)
            % GMAG
            gmag = imgradient(app.HounsFieldImageSeries(dcm_idx).images);
            gmag(gmag>700) = 50;
            gmag(gmag<50) = 50;  
            imshow( gmag, 'DisplayRange', [], 'Parent', app.seg_lines_gmag_ax)
            % Histogram
            histogram(app.histogram_ax, img, 'BinLimits', [-10 max(img, [], 'all')], 'BinWidth', 10);
            % Plot Lumen
%             fused_image = imfuse(img, gmag);
            imshow(app.HounsFieldImageSeries(dcm_idx).images, 'DisplayRange', [app.lower_level app.upper_level], 'Parent', app.lumen_ax)
            % Plot Sample Lines + Intensity + GMAG graphs
            [long_profile short_profile] = plot_int_gmag_curves(app, app.HounsFieldImageSeries(dcm_idx).images, gmag, my_mask);
            
            app.calibration_points = zeros(1,4);
            
            Create_Calibration_lines(app, long_profile, short_profile, app.HounsFieldImageSeries(dcm_idx).images, img, 0);


        end

        % Button pushed function: AcceptallSegmentsButton
        function AcceptallSegmentsButtonPushed(app, event)
            % getROIpropagation_mask(app.CallingApp, app.FirstROISlider.Value, app.LastROISlider.Value, app.ROI_propagation_mask, app.ROI_centroid);
            mycount = 1;
            for idx = 1:app.dcm_number_total
                if app.SEG_accept_flag(idx)==1
                    myseg(mycount) = idx;
                    mycount = mycount + 1;
                end
            end
            first_dcm = min(myseg);
            last_dcm = max(myseg);

            getROIsemiManual(app.CallingApp, first_dcm, last_dcm, app.seg_dataset, app.SEG_accept_flag);
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Semi_auto_segmentationUIFigure = uifigure('Visible', 'off');
            app.Semi_auto_segmentationUIFigure.Name = 'Semi_auto_segmentation';
            app.ROIpropagationPanel = uipanel(app.Semi_auto_segmentationUIFigure);
            app.ROIpropagationPanel.Title = 'ROI propagation';
            app.Label = uilabel(app.ROIpropagationPanel);
            app.Label.Text = '';
            app.SliceSlider = uislider(app.ROIpropagationPanel);
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.AcceptROIButton = uibutton(app.ROIpropagationPanel, 'push');
            app.AcceptROIButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptROIButtonPushed, true);
            app.AcceptROIButton.Text = 'Accept ROI';
            app.HUTrendsPanel = uipanel(app.Semi_auto_segmentationUIFigure);
            app.HUTrendsPanel.Title = 'HU Trends';
            app.AcceptLumenButton = uibutton(app.Semi_auto_segmentationUIFigure, 'push');
            app.AcceptLumenButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptLumenButtonPushed, true);
            app.AcceptLumenButton.Text = 'Accept Lumen';
            app.HUValuesLable = uilabel(app.Semi_auto_segmentationUIFigure);
            app.HUValuesLable.Text = '';
            app.AcceptallSegmentsButton = uibutton(app.Semi_auto_segmentationUIFigure, 'push');
            app.AcceptallSegmentsButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptallSegmentsButtonPushed, true);
            app.AcceptallSegmentsButton.Text = 'Accept all Segments';
            % Show the figure after all components are created
            app.Semi_auto_segmentationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = semi_auto_segmentation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Semi_auto_segmentationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Semi_auto_segmentationUIFigure)
        end
    end
end